classdef camGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        PositionLabel     matlab.ui.control.Label
        RowSigmaLabel     matlab.ui.control.Label
        ColumnSigmaLabel  matlab.ui.control.Label
        SnapImageButton   matlab.ui.control.Button
        UIAxes2           matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        camera
        frame
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.camera = videoinput('gige', 1, 'Mono8');
            %set(app.camera, 'FramesPerTrigger', 1);
            app.frame = Tiff('C:\Users\James Tutt\Desktop\tREXS_dalsa_genie\images\2021_06_14\trexs_z_scan_newzstage_x43p5_yNA_zm0p5E6_yaw_c171_f44p5.tif', 'r');
            app.frame = read(app.frame);
        end

        % Button pushed function: SnapImageButton
        function SnapImageButtonPushed(app, event)
            %frame = getsnapshot(app.camera);
            xarray = sum(app.frame);
            yarray = sum(app.frame,2);

%% find maximum position
            max_x = max(xarray); 
            max_y = max(yarray);

            xposition = find(xarray==max_x);
            yposition = find(yarray==max_y);

%% find test position
            newxarray = xarray(xposition-100:xposition+100);
            newyarray = yarray(yposition-100:yposition+100);

            newyarray = newyarray - min(newyarray);
            newxarray = newxarray - min(newxarray);

%% setting parameters for fit
            xpos = xposition - 100:xposition +100;
            ypos = yposition - 100:yposition +100;
            h = 0.8;

%% creating fit
            [sigmax, mux, Ax] = mygaussfit(xpos, newxarray, h);
            [sigmay, muy, Ay] = mygaussfit(ypos, newyarray, h);
            
            xfit = gaussFcn(xpos, mux, Ax, sigmax);
            yfit = gaussFcn(ypos, muy, Ay, sigmay);
            
            xaxes = xposition - 100:xposition +100;
            yaxes = yposition - 100:yposition +100;
            
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, xaxes, xfit);
            plot(app.UIAxes, xaxes, newxarray);
            
            hold(app.UIAxes2, 'on');
            plot(app.UIAxes2, yaxes, yfit);
            plot(app.UIAxes2, yaxes, newyarray);
            
            app.ColumnSigmaLabel.Text = sprintf("Column Sigma: %0.2f",sigmax);
            app.RowSigmaLabel.Text = sprintf("Row Sigma: %.2f",sigmay);
            app.PositionLabel.Text = sprintf("Position: (%.2f, %0.2f)", mux, muy);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.camera);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Columns')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Rows')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.SnapImageButton = uibutton(app.UIFigure, 'push');
            app.SnapImageButton.ButtonPushedFcn = createCallbackFcn(app, @SnapImageButtonPushed, true);
            app.SnapImageButton.Text = 'Snap Image';
            app.ColumnSigmaLabel = uilabel(app.UIFigure);
            app.ColumnSigmaLabel.Text = 'Column Sigma:';
            app.RowSigmaLabel = uilabel(app.UIFigure);
            app.RowSigmaLabel.Text = 'Row Sigma:';
            app.PositionLabel = uilabel(app.UIFigure);
            app.PositionLabel.Text = 'Position:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = camGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end