classdef focuscamGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        PositionLabel     matlab.ui.control.Label
        RowSigmaLabel     matlab.ui.control.Label
        ColumnSigmaLabel  matlab.ui.control.Label
        SnapImageButton   matlab.ui.control.Button
        UIAxes3           matlab.ui.control.UIAxes
        UIAxes2           matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        camera
        frame
        sincfit % Description
        gaussfit % Description
    end
    
    methods (Access = private)
        
        function result = sincfcn(app,x,x_0,D,I_0)
            result = I_0*(sinc((((x-x_0)*(pi*375*5.86))/(.4065*D)))).^2;
        end
        
        function result = gaussfcn(app,y,y_0,sigma,amp)
            result = amp*exp(-(((y-y_0).^2)/(2*(sigma^2))));
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             app.camera = videoinput('gige', 1, 'Mono8');
%             set(app.camera, 'FramesPerTrigger', 1);
            app.frame = Tiff('/Users/vincentsmedile/Documents/OneDrive - The Pennsylvania State University/McEntaffer Group Stuff/Geni/testimgtREXs.tif', 'r');
            app.frame = read(app.frame);
            py.importlib.import_module('pickle');
            py.importlib.import_module('numpy');
            py.importlib.import_module('scipy');
            py.importlib.import_module('arraysmaker');
            py.importlib.import_module('xarraymaker');
            py.importlib.import_module('matplotlib');
            py.importlib.import_module('pdfsaver');
        end

        % Button pushed function: SnapImageButton
        function SnapImageButtonPushed(app, event)
            %frame = getsnapshot(app.camera);
%             reloadpy;

% Create the fit for y
            py.arraysmaker.yfitmaker()
            paramsy = readtable('variables.txt', 'ReadVariableNames', false);
            paramsy = table2array(paramsy);
           
            yarray = readtable('yarrayfile.txt','ReadVariableNames',false);
            yarray = table2array(yarray);
            
            yaxis = readtable('yaxisfile.txt','ReadVariableNames',false);
            yaxis = table2array(yaxis);
            
            y_0 = paramsy(1);
            sigmay = paramsy(2);
            amp = paramsy(3);
            
            display(y_0);
            display(sigmay);
            display(amp);
            
% Create the fit for x
            py.xarraymaker.xfitmaker();
            paramsx = readtable('xvariables.txt', 'ReadVariableNames', false);
            paramsx = table2array(paramsx);
           
            xarray = readtable('xarrayfile.txt','ReadVariableNames',false);
            xarray = table2array(xarray);
            
            xaxis = readtable('xaxisfile.txt','ReadVariableNames',false);
            xaxis = table2array(xaxis);
            
            x_0 = paramsx(1);
            D = paramsx(2);
            I_0 = paramsx(3);


% plot the data  
            ycurve = gaussfcn(app,yaxis,y_0,sigmay,amp);
            xcurve = sincfcn(app,xaxis,x_0,D,I_0);
            
            
            hold(app.UIAxes,'on');
            plot(app.UIAxes,yaxis,yarray);
            plot(app.UIAxes,yaxis,ycurve);
            
            hold(app.UIAxes2,'on');
            plot(app.UIAxes2,xaxis,xarray);
            plot(app.UIAxes2,xaxis,xcurve);
            
            imagesc(app.UIAxes3, app.frame);
            
%             py.pdfsaver.graph2pdf(xaxis,xarray);
%             py.pdfsaver.graph2pdf(yaxis,yarray);
%             py.pdfsaver.image2pdf(app.frame);
%             
%             display(class(xarray));
%             display(class(yarray));
%             display(class(app.frame));
            
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.camera);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Rows')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Columns')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.SnapImageButton = uibutton(app.UIFigure, 'push');
            app.SnapImageButton.ButtonPushedFcn = createCallbackFcn(app, @SnapImageButtonPushed, true);
            app.SnapImageButton.Text = 'Snap Image';
            app.ColumnSigmaLabel = uilabel(app.UIFigure);
            app.ColumnSigmaLabel.Text = 'Column Sigma:';
            app.RowSigmaLabel = uilabel(app.UIFigure);
            app.RowSigmaLabel.Text = 'Row Sigma:';
            app.PositionLabel = uilabel(app.UIFigure);
            app.PositionLabel.Text = 'Position:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = focuscamGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end