classdef focuscamGUIfinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PDFNameEditField_2              matlab.ui.control.EditField
        PDFNameEditField_2Label         matlab.ui.control.Label
        SaveFiguresButton               matlab.ui.control.Button
        ExposeCamerasButton             matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        FitTab                          matlab.ui.container.Tab
        XLabel_5                        matlab.ui.control.Label
        Label9                          matlab.ui.control.Label
        YLabel_5                        matlab.ui.control.Label
        Label10                         matlab.ui.control.Label
        XLabel_4                        matlab.ui.control.Label
        Label5_2                        matlab.ui.control.Label
        YLabel_4                        matlab.ui.control.Label
        Label6_2                        matlab.ui.control.Label
        Label8                          matlab.ui.control.Label
        Label7                          matlab.ui.control.Label
        YLabel_3                        matlab.ui.control.Label
        XLabel_3                        matlab.ui.control.Label
        Label4                          matlab.ui.control.Label
        Label3                          matlab.ui.control.Label
        Label2                          matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        YLabel_2                        matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        XLabel_2                        matlab.ui.control.Label
        XLabel                          matlab.ui.control.Label
        Camera0Label                    matlab.ui.control.Label
        Camera2Label                    matlab.ui.control.Label
        Camera3Label                    matlab.ui.control.Label
        Camera1Label                    matlab.ui.control.Label
        Camera4Label                    matlab.ui.control.Label
        IntensityTab                    matlab.ui.container.Tab
        ClearAllPlotsButton_2           matlab.ui.control.Button
        ClearAllMostRecentButton        matlab.ui.control.Button
        ClearAllButton_5                matlab.ui.control.Button
        ClearMostRecentButton_5         matlab.ui.control.Button
        ClearAllButton_4                matlab.ui.control.Button
        ClearMostRecentButton_4         matlab.ui.control.Button
        ClearAllButton                  matlab.ui.control.Button
        ClearMostRecentButton_3         matlab.ui.control.Button
        ClearAllButton_2                matlab.ui.control.Button
        ClearMostRecentButton_2         matlab.ui.control.Button
        ClearAllButton_3                matlab.ui.control.Button
        ClearMostRecentButton           matlab.ui.control.Button
        ClearAllCamerasButton           matlab.ui.control.Button
        UIAxes16_5                      matlab.ui.control.UIAxes
        UIAxes16_4                      matlab.ui.control.UIAxes
        UIAxes16_3                      matlab.ui.control.UIAxes
        UIAxes16_2                      matlab.ui.control.UIAxes
        UIAxes16                        matlab.ui.control.UIAxes
        CentroidTab                     matlab.ui.container.Tab
        ClearAllPlotsButton             matlab.ui.control.Button
        Camera0ButtonGroup              matlab.ui.container.ButtonGroup
        YButton_5                       matlab.ui.control.RadioButton
        XButton_5                       matlab.ui.control.RadioButton
        Camera3ButtonGroup              matlab.ui.container.ButtonGroup
        YButton_4                       matlab.ui.control.RadioButton
        XButton_4                       matlab.ui.control.RadioButton
        Camera2ButtonGroup              matlab.ui.container.ButtonGroup
        YButton_3                       matlab.ui.control.RadioButton
        XButton_3                       matlab.ui.control.RadioButton
        Camera1ButtonGroup              matlab.ui.container.ButtonGroup
        YButton_2                       matlab.ui.control.RadioButton
        XButton_2                       matlab.ui.control.RadioButton
        Camera4ButtonGroup              matlab.ui.container.ButtonGroup
        YButton                         matlab.ui.control.RadioButton
        XButton                         matlab.ui.control.RadioButton
        ClearAllMostRecentButton_2      matlab.ui.control.Button
        ClearAllButton_10               matlab.ui.control.Button
        ClearMostRecentButton_10        matlab.ui.control.Button
        ClearAllButton_9                matlab.ui.control.Button
        ClearMostRecentButton_9         matlab.ui.control.Button
        ClearAllButton_8                matlab.ui.control.Button
        ClearMostRecentButton_8         matlab.ui.control.Button
        ClearAllButton_7                matlab.ui.control.Button
        ClearMostRecentButton_7         matlab.ui.control.Button
        ClearAllButton_6                matlab.ui.control.Button
        ClearMostRecentButton_6         matlab.ui.control.Button
        ClearAllCamerasButton_2         matlab.ui.control.Button
        UIAxes16_10                     matlab.ui.control.UIAxes
        UIAxes16_9                      matlab.ui.control.UIAxes
        UIAxes16_8                      matlab.ui.control.UIAxes
        UIAxes16_7                      matlab.ui.control.UIAxes
        UIAxes16_6                      matlab.ui.control.UIAxes
        Tab                             matlab.ui.container.Tab
        Label16                         matlab.ui.control.Label
        AmplitudeLabel_4                matlab.ui.control.Label
        Label15                         matlab.ui.control.Label
        AmplitudeLabel_3                matlab.ui.control.Label
        SigmaLabel_2                    matlab.ui.control.Label
        CentroidLabel_2                 matlab.ui.control.Label
        Label12_2                       matlab.ui.control.Label
        Label11_2                       matlab.ui.control.Label
        SigmaLabel                      matlab.ui.control.Label
        CentroidLabel                   matlab.ui.control.Label
        Label12                         matlab.ui.control.Label
        Label11                         matlab.ui.control.Label
        PDFNameEditField                matlab.ui.control.EditField
        PDFNameEditFieldLabel           matlab.ui.control.Label
        SaveExposureTimeButton          matlab.ui.control.Button
        ExposureTimeusEditField_5       matlab.ui.control.NumericEditField
        ExposureTimeusEditField_5Label  matlab.ui.control.Label
        DisplayDropDown_3               matlab.ui.control.DropDown
        DisplayDropDown_3Label          matlab.ui.control.Label
        SaveCamera0FiguresButton        matlab.ui.control.Button
        ExposeCamera0Button             matlab.ui.control.Button
        UIAxes11                        matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        Tab_2                           matlab.ui.container.Tab
        AmplitudeLabel_2                matlab.ui.control.Label
        Label14                         matlab.ui.control.Label
        Label13                         matlab.ui.control.Label
        AmplitudeLabel                  matlab.ui.control.Label
        SigmaLabel_4                    matlab.ui.control.Label
        CentroidLabel_4                 matlab.ui.control.Label
        Label12_4                       matlab.ui.control.Label
        Label11_4                       matlab.ui.control.Label
        SigmaLabel_3                    matlab.ui.control.Label
        CentroidLabel_3                 matlab.ui.control.Label
        Label12_3                       matlab.ui.control.Label
        Label11_3                       matlab.ui.control.Label
        PDFNameEditField_3              matlab.ui.control.EditField
        PDFNameEditField_3Label         matlab.ui.control.Label
        ExposeCamera1Button             matlab.ui.control.Button
        SaveExposureTimeButton_2        matlab.ui.control.Button
        ExposureTimeusEditField_4       matlab.ui.control.NumericEditField
        ExposureTimeusEditField_4Label  matlab.ui.control.Label
        DisplayDropDown_2               matlab.ui.control.DropDown
        DisplayDropDown_2Label          matlab.ui.control.Label
        SaveCamera1FiguresButton_2      matlab.ui.control.Button
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes12                        matlab.ui.control.UIAxes
        Tab_3                           matlab.ui.container.Tab
        Label18                         matlab.ui.control.Label
        AmplitudeLabel_6                matlab.ui.control.Label
        Label17                         matlab.ui.control.Label
        AmplitudeLabel_5                matlab.ui.control.Label
        SigmaLabel_6                    matlab.ui.control.Label
        CentroidLabel_6                 matlab.ui.control.Label
        Label12_6                       matlab.ui.control.Label
        Label11_6                       matlab.ui.control.Label
        SigmaLabel_5                    matlab.ui.control.Label
        CentroidLabel_5                 matlab.ui.control.Label
        Label12_5                       matlab.ui.control.Label
        Label11_5                       matlab.ui.control.Label
        PDFNameEditField_4              matlab.ui.control.EditField
        PDFNameEditField_4Label         matlab.ui.control.Label
        SaveExposureTimeButton_3        matlab.ui.control.Button
        ExposureTimeusEditField_3       matlab.ui.control.NumericEditField
        ExposureTimeusEditField_3Label  matlab.ui.control.Label
        DisplayDropDown                 matlab.ui.control.DropDown
        DisplayDropDownLabel            matlab.ui.control.Label
        SaveCamera2FiguresButton_2      matlab.ui.control.Button
        ExposeCamera2Button             matlab.ui.control.Button
        UIAxes13                        matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        Tab_5                           matlab.ui.container.Tab
        Label20                         matlab.ui.control.Label
        AmplitudeLabel_8                matlab.ui.control.Label
        Label19                         matlab.ui.control.Label
        AmplitudeLabel_7                matlab.ui.control.Label
        SigmaLabel_8                    matlab.ui.control.Label
        CentroidLabel_8                 matlab.ui.control.Label
        Label12_8                       matlab.ui.control.Label
        Label11_8                       matlab.ui.control.Label
        SigmaLabel_7                    matlab.ui.control.Label
        CentroidLabel_7                 matlab.ui.control.Label
        Label12_7                       matlab.ui.control.Label
        Label11_7                       matlab.ui.control.Label
        PDFNameEditField_5              matlab.ui.control.EditField
        PDFNameEditField_5Label         matlab.ui.control.Label
        SaveExposureTimeButton_4        matlab.ui.control.Button
        ExposureTimeusEditField_2       matlab.ui.control.NumericEditField
        ExposureTimeusEditField_2Label  matlab.ui.control.Label
        DisplayDropDown_4               matlab.ui.control.DropDown
        DisplayDropDown_4Label          matlab.ui.control.Label
        SaveCamera3FiguresButton_2      matlab.ui.control.Button
        ExposeCamera3Button             matlab.ui.control.Button
        UIAxes14                        matlab.ui.control.UIAxes
        UIAxes8                         matlab.ui.control.UIAxes
        UIAxes7                         matlab.ui.control.UIAxes
        Tab_4                           matlab.ui.container.Tab
        Label22                         matlab.ui.control.Label
        AmplitudeLabel_10               matlab.ui.control.Label
        Label21                         matlab.ui.control.Label
        AmplitudeLabel_9                matlab.ui.control.Label
        SigmaLabel_10                   matlab.ui.control.Label
        CentroidLabel_10                matlab.ui.control.Label
        Label12_10                      matlab.ui.control.Label
        Label11_10                      matlab.ui.control.Label
        SigmaLabel_9                    matlab.ui.control.Label
        CentroidLabel_9                 matlab.ui.control.Label
        Label12_9                       matlab.ui.control.Label
        Label11_9                       matlab.ui.control.Label
        PDFNameEditField_6              matlab.ui.control.EditField
        PDFNameEditField_6Label         matlab.ui.control.Label
        SaveExposureTimeButton_5        matlab.ui.control.Button
        ExposureTimeusEditField         matlab.ui.control.NumericEditField
        ExposureTimeusEditFieldLabel    matlab.ui.control.Label
        DisplayDropDown_5               matlab.ui.control.DropDown
        DisplayDropDown_5Label          matlab.ui.control.Label
        SaveCamera4FiguresButton_2      matlab.ui.control.Button
        ExposeCamera4Button             matlab.ui.control.Button
        UIAxes15                        matlab.ui.control.UIAxes
        UIAxes10                        matlab.ui.control.UIAxes
        UIAxes9                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        camera1
        camera2
        camera3
        camera4
        camera5
        
        frame1
        frame2
        frame3
        frame4
        frame5
        
        src
        src2
        src3
        src4
        src5
        
        sincfit 
        gaussfit 
        
        xc1
        x1_0
        D_x1
        D_x1start
        Amplitudex1
        Amplitudex1start
        yc1
        y1_0
        Amplitudey1
        Amplitudey1start
        sigmay1
        sigmay1start
        
        xc2
        x2_0
        D_x2
        D_x2start
        Amplitudex2
        Amplitudex2start
        yc2
        y2_0
        Amplitudey2
        Amplitudey2start
        sigmay2
        sigmay2start
        
        xc3
        x3_0
        D_x3
        D_x3start
        Amplitudex3
        Amplitudex3start
        yc3
        y3_0
        Amplitudey3
        Amplitudey3start
        sigmay3
        sigmay3start
        
        xc4
        x4_0
        D_x4
        D_x4start
        Amplitudex4
        Amplitudex4start
        yc4
        y4_0
        Amplitudey4
        Amplitudey4start
        sigmay4
        sigmay4start
        
        xc5
        x5_0
        D_x5
        D_x5start
        Amplitudex5
        Amplitudex5start
        yc5
        y5_0
        Amplitudey5
        Amplitudey5start
        sigmay5
        sigmay5start
        
        paramsx1
        paramsy1
        xarray1
        yarray1
        xaxis1
        yaxis1
        
        paramsx2
        paramsy2
        xarray2
        yarray2
        xaxis2
        yaxis2
        
        paramsx3
        paramsy3
        xarray3
        yarray3 
        xaxis3
        yaxis3        
        
        paramsx4
        paramsy4
        xarray4
        yarray4
        xaxis4
        yaxis4        
        
        paramsx5
        paramsy5
        xarray5
        yarray5
        xaxis5
        yaxis5       
        
        intensity0
        intensity1
        intensity2
        intensity3
        intensity4
        
        x0arr
        x1arr
        x2arr
        x3arr
        x4arr
        y0arr
        y1arr
        y2arr
        y3arr
        y4arr
        
        exposure0
        exposure1
        exposure2
        exposure3
        exposure4
        tout
        camera
    end
    
    methods (Access = private)
        
        function result = sincfcn(app,x,x_0,D,I_0)
            result = I_0.*(sinc((((x-x_0).*(pi*375*5.86))/(0.4065*D)))).^2;
        end
        
        function result = gaussfcn(app,y,y_0,sigma,amp)
            result = amp.*exp(-(((y-y_0).^2)./(2*(sigma^2))));
            
        end
        
        function reloadpy(app) %reloads the python function in the event of updating parameters
            warning('off','MATLAB:ClassInstanceExists')
            clear classes
            mod = py.importlib.import_module('arraysmaker');
            py.importlib.reload(mod);
        end
        
        function mat2np(app,m, file, dtype)
            %MAT2PKL saves a MATLAB array into a pickled numpy array
            %
            %  mat2pkl(m, file, dtype) saves a 1-d or 2-d array and into a pickled 
            %    numpy array named 'file' in specified dtype. 
            %  
            %  Currently, only dypte 'int8', 'int16', 'int32' and 'float64' are 
            %  supported.
            %
            % Copyright, Chao-Yuan Yeh, 2016
            %
            fout = fopen(file, 'wb');
            % *consult pickle.py source file for file structure.
            % File structure : headbytes + shapebytes + byte_after_shape + dtypebytes +
            % byte_after_dtype + dsizebyte + data + tailbyte
            headbytes = [128 3 99 110 117 109 112 121 46 99 111 114 101 46 109 117 ...
               108 116 105 97 114 114 97 121 10 95 114 101 99 111 110 115 116 114 117 ...
               99 116 10 113 0 99 110 117 109 112 121 10 110 100 97 114 114 97 121 10 ...
               113 1 75 0 133 113 2 67 1 98 113 3 135 113 4 82 113 5 40];
            fwrite(fout, headbytes, 'uint8');
            % b75 is the beginning byte of shape records. for shape (1,) this
            % is 75, 1, 75, 1. for shape (2,1), this is 75, 1, 75, 2, 75, 1. For
            % dimension (2,2), this is 75, 1, 75, 2, 75, 2
            fwrite(fout, [75 1], 'uint8');
            if size(m, 2) == 1
                if size(m, 1) < 2^8
                    fwrite(fout, [75, size(m,1) 133], 'uint8');
                elseif size(m,1) < 2^16
                    fwrite(fout, 77, 'uint8');
                    fwrite(fout, size(m, 1), 'uint16', 'l');
                    fwrite(fout, 134, 'uint8');
                end
            else
                if size(m, 1) < 2^8
                    fwrite(fout, [75, size(m,1)], 'uint8');
                elseif size(m,1) < 2^16
                    fwrite(fout, 77, 'uint8');
                    fwrite(fout, size(m, 1), 'uint16', 'l');
                end
                
                if size(m, 2) < 2^8
                    fwrite(fout, [75, size(m, 2) 134], 'uint8');
                elseif size(m, 2) < 2^16
                    fwrite(fout, 77, 'uint8');
                    fwrite(fout, size(m, 2), 'uint16', 'l');
                    fwrite(fout, 134, 'uint8');
                end
            end
            byte_after_shape = [113 6 99 110 117 109 112 121 10 100 116 121 112 101 ...
                 10 113 7 88 2 0 0 0];
            fwrite(fout, byte_after_shape, 'uint8');
            dtype_type = regexpi(dtype, '\D*', 'match');
            dtype_type = dtype_type{1};
            dtype_size = str2double(regexp(dtype, '\d*', 'match'))/8;
            if strfind(dtype_type, 'i')
                if strfind(dtype_type, 'u')
                    dtype_type = 'u' + 0; % cast it into integer type
                else
                    dtype_type = 'i' + 0;
                end
            elseif strfind(dtype_type, 'f')
                dtype_type = 'f' + 0;
            end
            bytes_after_dtype = [113 8 75 0 75 1 135 113 9 82 113 10 40 75 3 88 1 0 0 ...
                0 60 113 11 78 78 78 74 255 255 255 255 74 255 255 255 255 75 0 116 ...
                113 12 98 137];
            fwrite(fout, [dtype_type, num2str(dtype_size) + 0, ...
                bytes_after_dtype], 'uint8');
            dsizebytes = size(m, 1) * size(m, 2) * dtype_size ;
            if dsizebytes < 2^8
                fwrite(fout, [67 dsizebytes], 'uint8');
            else
                fwrite(fout, 66, 'uint8')
                if dsizebytes < 2^16
                    fwrite(fout, dsizebytes, 'uint16', 'l');
                elseif dsizebytes < 2^32
                    fwrite(fout, dsizebytes, 'uint32', 'l');
                end
            end
            % data is stored by rows. [[1,2],[3,4]) is stored as 1,2,3,4
            % data is stored imeediately before tailbytes 
            data = m';
            switch dtype
                case {'int8', 'uint8', 'int16', 'uint16', 'int32', 'uint32'}
                    fwrite(fout, data(:), dtype, 'l');
                case 'float64'
                    fwrite(fout, data(:), 'float64', 'a');
            end
            tailbytes = [113 13 116 113 14 98 46];
            fwrite(fout, tailbytes, 'uint8');
            fclose(fout);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
% For loading camera snapshots
            app.camera = -1;
            app.exposure0 = 120000; % Camera 0 initial exposure time in microseconds
            app.exposure1 = 120000; % Camera 0 initial exposure time in microseconds
            app.exposure2 = 120000; % Camera 0 initial exposure time in microseconds
            app.exposure3 = 90000; % Camera 0 initial exposure time in microseconds
            app.exposure4 = 120000; % Camera 0 initial exposure time in microseconds

            app.tout = 0.5; % Camera Timeout in seconds
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
%             app.camera1 = videoinput('gige', 1, 'Mono8');
%             set(app.camera1, 'FramesPerTrigger', 1);
%             triggerconfig(app.camera1,'manual');            
%             app.src = getselectedsource(app.camera1);
%             app.camera1 = gigecam('S1098217');
%             app.camera1.Timeout=app.tout;
%             app.camera1.ExposureTime = app.baseexposure;
% %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%             app.src.ExposureTime = baseexposure;
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
%             app.camera2 = videoinput('gige', 2, 'Mono8');
%             set(app.camera2, 'FramesPerTrigger', 1);
%             triggerconfig(app.camera2,'manual');            
%             app.src2 = getselectedsource(app.camera2);
%             app.camera2 = gigecam('S1100936');
%             app.camera2.Timeout=app.tout;
%             app.camera2.ExposureTime = app.baseexposure;
%             display(app.camera2.Timeout);
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%             app.src2.ExposureTime = baseexposure;
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%             
%             app.camera3 = videoinput('gige', 3, 'Mono8');           
%             set(app.camera3, 'FramesPerTrigger', 1);
%             triggerconfig(app.camera3,'manual');
%             app.src3 = getselectedsource(app.camera3);
%             app.camera3 = gigecam('S1254378');
%             app.camera3.Timeout=app.tout;
%             app.camera3.ExposureTime = app.baseexposure;
            
% %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% 
%             app.camera4 = videoinput('gige', 4, 'Mono8');
%             set(app.camera4, 'FramesPerTrigger', 1);
%             triggerconfig(app.camera4,'manual');   
%             app.src4 = getselectedsource(app.camera4);
%             app.camera4 = gigecam('S1255183');
%             app.camera4.Timeout=app.tout;
%             app.camera4.ExposureTime = app.baseexposure;
% %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%             app.src4.ExposureTime = baseexposure;
% %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%             
%             app.camera5 = videoinput('gige', 5, 'Mono8');
%             set(app.camera5, 'FramesPerTrigger', 1);
%             triggerconfig(app.camera5,'manual'); 
%             app.src5 = getselectedsource(app.camera5);
%             app.camera5 = gigecam('S1255193');
%             app.camera5.Timeout=app.tout; 
%             app.camera5.ExposureTime = app.baseexposure;
% %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%             app.src5.ExposureTime = baseexposure;
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%          
            
% For testing or using an image file
%             app.frame1 = Tiff('C:\Users\James_admin\Desktop\testimgtREXs.tif', 'r');
%             app.frame1 = read(app.frame1);
%             
%             app.frame2 = Tiff('C:\Users\James_admin\Desktop\testimgtREXs.tif', 'r');
%             app.frame2 = read(app.frame2);
% 
%             app.frame3 = Tiff('C:\Users\James_admin\Desktop\testimgtREXs.tif', 'r');
%             app.frame3 = read(app.frame3);
%             
%             app.frame4 = Tiff('C:\Users\James_admin\Desktop\testimgtREXs.tif', 'r');
%             app.frame4 = read(app.frame4);
%             
%             app.frame5 = Tiff('C:\Users\James_admin\Desktop\testimgtREXs.tif', 'r');
%             app.frame5 = read(app.frame5);   
            
            py.importlib.import_module('pickle');
            py.importlib.import_module('numpy');
            py.importlib.import_module('scipy');
            py.importlib.import_module('arraysmaker');
            py.importlib.import_module('xarraymaker');
            
            % creating empty intensity arrays for plotting
            app.intensity0 = [];
            app.intensity1 = [];
            app.intensity2 = [];
            app.intensity3 = [];
            app.intensity4 = [];
            
            % creating empty x/y position arrays for plotting
            app.x0arr = [];
            app.x1arr = [];
            app.x2arr = [];
            app.x3arr = [];
            app.x4arr = [];
            
            app.y0arr = [];
            app.y1arr = [];
            app.y2arr = [];
            app.y3arr = [];
            app.y4arr = [];
       
            
        end

        % Button pushed function: ExposeCamera0Button
        function ExposeCamera0ButtonPushed(app, event)
%             reloadpy;
            if app.camera == -1
                app.camera = gigecam('S1098217');
                app.camera.Timeout=app.tout;
%                 app.camera.ExposureTime = app.exposure0;
            end
            if strcmp(app.camera.SerialNumber, 'S1098217') == 0
                delete(app.camera)
                clear app.camera
                app.camera = gigecam('S1098217');
                app.camera.Timeout=app.tout;
%                 app.camera.ExposureTime = app.exposure0;
            end
            
            app.camera.ExposureTime = app.exposure0;
            app.frame1 = snapshot(app.camera);
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes11);
            cla(app.UIAxes16);
            cla(app.UIAxes16_6);

%             app.Columns1DisplayOptionsDropDown.Value = 'Both';
%             app.Rows1DisplayOptionsDropDown.Value = 'Both';
            app.DisplayDropDown_3.Value = 'Full';
            
            mat2np(app,app.frame1, 'a.pkl', 'uint8');
            py.arraysmaker.yfitmaker();
            
            % Create the fit for y
            app.paramsy1 = readtable('variables.txt', 'ReadVariableNames', false);
            app.paramsy1 = table2array(app.paramsy1);
            
            app.yarray1 = readtable('yarrayfile.txt','ReadVariableNames',false);
            app.yarray1 = table2array(app.yarray1);
            
            app.yaxis1 = readtable('yaxisfile.txt','ReadVariableNames',false);
            app.yaxis1 = table2array(app.yaxis1);
            
            app.y1_0 = app.paramsy1(1);
            app.yc1 = app.paramsy1(1);                    
            app.sigmay1 = app.paramsy1(2);
            app.sigmay1start = app.paramsy1(2);                    
            app.Amplitudey1 = app.paramsy1(3);
            app.Amplitudey1start = app.paramsy1(3);

            % Create the fit for x
            py.xarraymaker.xfitmaker();
            app.paramsx1 = readtable('xvariables.txt', 'ReadVariableNames', false);
            app.paramsx1 = table2array(app.paramsx1);
            
            app.xarray1 = readtable('xarrayfile.txt','ReadVariableNames',false);
            app.xarray1 = table2array(app.xarray1);
            
            app.xaxis1 = readtable('xaxisfile.txt','ReadVariableNames',false);
            app.xaxis1 = table2array(app.xaxis1);
            
            app.x1_0 = app.paramsx1(1);
            app.xc1 = app.paramsx1(1);
            app.D_x1 = app.paramsx1(2);
            app.D_x1start = app.paramsx1(2);                    
            app.Amplitudex1 = app.paramsx1(3);
            app.Amplitudex1start = app.paramsx1(3);
            inten = app.paramsx1(3);
            
            % plot the data
            hold(app.UIAxes11,"on");
            imagesc(app.UIAxes11,app.frame1); 
            sz = size(app.frame1);
            app.UIAxes11.XLim = [0, sz(2)];
            app.UIAxes11.YLim = [0, sz(1)];
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            scatter(app.UIAxes11, round(app.x1_0), round(app.y1_0), 'x','red');
            colorbar(app.UIAxes11);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            hold(app.UIAxes, 'on');
            xcurve = gaussfcn(app,app.xaxis1,app.x1_0,app.D_x1,app.Amplitudex1);
            plot(app.UIAxes,app.xaxis1,xcurve);
            plot(app.UIAxes,app.xaxis1,app.xarray1);
            xline(app.UIAxes,app.xc1,'--r');            
            
            ycurve = gaussfcn(app,app.yaxis1,app.y1_0,app.sigmay1,app.Amplitudey1);
            hold(app.UIAxes2,'on');
            plot(app.UIAxes2,app.yaxis1,ycurve);
            plot(app.UIAxes2,app.yaxis1,app.yarray1);
            xline(app.UIAxes2,app.yc1,'--r');
            
            % append intensity to array
            app.intensity0 = [app.intensity0, inten];
            
            % append x/y centroids to arrays for plotting
            app.x0arr = [app.x0arr, app.xc1];
            app.y0arr = [app.y0arr, app.yc1];
            
            % plot intensity
            hold(app.UIAxes16, "on")
            plot(app.UIAxes16, app.intensity0, '--o', 'Color', 'k')
            
            % plot centroid
            hold(app.UIAxes16_6, "on")
            plot(app.UIAxes16_6, app.x0arr, '--o', 'Color', 'b')
            
            % Display the Parameters
%             app.Amplitudex1EditField.Value = sprintf('%.2f',app.Amplitudex1);
%             app.x1_0EditField_2.Value = sprintf('%.2f',app.x1_0);
%             app.D_x1EditField.Value = sprintf('%.2f',app.D_x1);     
%             
%             app.Amplitudey1EditField.Value = sprintf('%.2f',app.Amplitudey1);
%             app.y1_0EditField.Value = sprintf('%.2f',app.y1_0);
%             app.Sigmay1EditField.Value = sprintf('%.2f',app.sigmay1);
            app.Label11.Text = sprintf('%.2f', app.x1_0);
            app.Label12.Text = sprintf('%.2f', app.D_x1);
            app.Label11_2.Text = sprintf('%.2f', app.y1_0);
            app.Label12_2.Text = sprintf('%.2f', app.sigmay1);
            app.Label15.Text = sprintf('%.3E', app.Amplitudex1);
            app.Label16.Text = sprintf('%.3E', app.Amplitudey1);
            
            app.Label.Text = sprintf('%.2f',app.x1_0);
            app.Label2.Text = sprintf('%.2f',app.y1_0);   
            
            % reseting camera
            delete(app.camera1)
            clear app.camera1
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.camera1);
            delete(app.camera2);
            delete(app.camera3);
            delete(app.camera4);
            delete(app.camera5);
            delete(app);
        end

        % Button pushed function: ExposeCamera1Button
        function ExposeCamera1ButtonPushed(app, event)
            if app.camera == -1
                app.camera = gigecam('S1100936');
                app.camera.Timeout=app.tout;
                app.camera.ExposureTime = app.exposure1;
            end
            if strcmp(app.camera.SerialNumber, 'S1100936') == 0
                delete(app.camera)
                clear app.camera
                app.camera = gigecam('S1100936');
                app.camera.Timeout=app.tout;
                app.camera.ExposureTime = app.exposure1;
            end
            
            app.frame2 = snapshot(app.camera);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            cla(app.UIAxes12);
            cla(app.UIAxes16_2);
            cla(app.UIAxes16_7);
            
%             app.Columns2DisplayOptionsDropDown.Value = 'Both';
%             app.Rows2DisplayOptionsDropDown.Value = 'Both';
            app.DisplayDropDown_2.Value = 'Full';
            
            mat2np(app,app.frame2, 'a2.pkl', 'uint8');
            py.arraysmaker.yfitmaker2();
            
            % Create the fit for y
            app.paramsy2 = readtable('variables.txt', 'ReadVariableNames', false);
            app.paramsy2 = table2array(app.paramsy2);
            
            app.yarray2 = readtable('yarrayfile.txt','ReadVariableNames',false);
            app.yarray2 = table2array(app.yarray2);
            
            app.yaxis2 = readtable('yaxisfile.txt','ReadVariableNames',false);
            app.yaxis2 = table2array(app.yaxis2);
            
            app.y2_0 = app.paramsy2(1);
            app.yc2 = app.paramsy2(1);                    
            app.sigmay2 = app.paramsy2(2);
            app.sigmay2start = app.paramsy2(2);                    
            app.Amplitudey2 = app.paramsy2(3);
            app.Amplitudey2start = app.paramsy2(3);
            
            % Create the fit for x
            py.xarraymaker.xfitmaker2();
            app.paramsx2 = readtable('xvariables.txt', 'ReadVariableNames', false);
            app.paramsx2 = table2array(app.paramsx2);
            
            app.xarray2 = readtable('xarrayfile.txt','ReadVariableNames',false);
            app.xarray2 = table2array(app.xarray2);
            
            app.xaxis2 = readtable('xaxisfile.txt','ReadVariableNames',false);
            app.xaxis2 = table2array(app.xaxis2);
            
            app.x2_0 = app.paramsx2(1);
            app.xc2 = app.paramsx2(1);                    
            app.D_x2 = app.paramsx2(2);
            app.D_x2start = app.paramsx2(2);                    
            app.Amplitudex2 = app.paramsx2(3);
            app.Amplitudex2start = app.paramsx2(3);
            inten = app.paramsx2(3);
            
            % plot the data
            hold(app.UIAxes12,"on");
            imagesc(app.UIAxes12,app.frame2); 
            sz = size(app.frame2);
            app.UIAxes12.XLim = [0, sz(2)];
            app.UIAxes12.YLim = [0, sz(1)];
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            scatter(app.UIAxes12, round(app.x2_0), round(app.y2_0), 'x','red');
            colorbar(app.UIAxes12);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            hold(app.UIAxes3, 'on');
            xcurve = gaussfcn(app,app.xaxis2,app.x2_0,app.D_x2,app.Amplitudex2);
            plot(app.UIAxes3,app.xaxis2,xcurve);
            plot(app.UIAxes3,app.xaxis2,app.xarray2);
            xline(app.UIAxes3,app.xc2,'--r');

            ycurve = gaussfcn(app,app.yaxis2,app.y2_0,app.sigmay2,app.Amplitudey2);
            hold(app.UIAxes4,'on');
            plot(app.UIAxes4,app.yaxis2,ycurve);
            plot(app.UIAxes4,app.yaxis2,app.yarray2);
            xline(app.UIAxes4,app.yc2,'--r');
            
            % append intensity to array
            app.intensity1 = [app.intensity1, inten];
            
            % append x/y centroids to arrays for plotting
            app.x1arr = [app.x1arr, app.xc2];
            app.y1arr = [app.y1arr, app.yc2];
            
            % plot intensity
            hold(app.UIAxes16_2, "on")
            plot(app.UIAxes16_2, app.intensity1, '--o', 'Color', 'b')
            
            % plot centroid
            hold(app.UIAxes16_7, "on")
            plot(app.UIAxes16_7, app.x1arr, '--o', 'Color', 'b')
            
            % Display the parameters
%             app.Amplitudex2EditField.Value = sprintf('%.2f',app.Amplitudex2);
%             app.x2_0EditField_2.Value = sprintf('%.2f',app.x2_0);
%             app.D_x2EditField.Value = sprintf('%.2f',app.D_x2);     
%             
%             app.Amplitudey2EditField.Value = sprintf('%.2f',app.Amplitudey2);
%             app.y2_0EditField.Value = sprintf('%.2f',app.y2_0);
%             app.Sigmay2EditField.Value = sprintf('%.2f',app.sigmay2);
            app.Label11_3.Text = sprintf('%.2f', app.x2_0);
            app.Label12_3.Text = sprintf('%.2f', app.D_x2);
            app.Label11_4.Text = sprintf('%.2f', app.y2_0);
            app.Label12_4.Text = sprintf('%.2f', app.sigmay2);
            
            app.Label3.Text = sprintf('%.2f',app.x2_0);
            app.Label4.Text = sprintf('%.2f',app.y2_0); 
            app.Label13.Text = sprintf('%.3E', app.Amplitudex2);
            app.Label14.Text = sprintf('%.3E', app.Amplitudey2);
            
            % clear camera 1
            delete(app.camera2)
            clear app.camera2
        end

        % Button pushed function: ExposeCamera2Button
        function ExposeCamera2ButtonPushed(app, event)
            if app.camera == -1
                app.camera = gigecam('S1254378');
                app.camera.Timeout=app.tout;
                app.camera.ExposureTime = app.exposure2;
            end

            if strcmp(app.camera.SerialNumber, 'S1254378') == 0
                delete(app.camera)
                clear app.camera
                app.camera = gigecam('S1254378');
                app.camera.Timeout=app.tout;
                app.camera.ExposureTime = app.exposure2;
            end

            
            app.frame3 = snapshot(app.camera);

            cla(app.UIAxes5);
            cla(app.UIAxes6);
            cla(app.UIAxes13);
            cla(app.UIAxes16_3);
            cla(app.UIAxes16_8);

%             app.Columns3DisplayOptionsDropDown.Value = 'Both';
%             app.Rows3DisplayOptionsDropDown.Value = 'Both';
            app.DisplayDropDown.Value = 'Full';

            mat2np(app,app.frame3, 'a3.pkl', 'uint8');
            py.arraysmaker.yfitmaker3();
            
            % Create the fit for y
            % Create the fit for y
            app.paramsy3 = readtable('variables.txt', 'ReadVariableNames', false);
            app.paramsy3 = table2array(app.paramsy3);
            
            app.yarray3 = readtable('yarrayfile.txt','ReadVariableNames',false);
            app.yarray3 = table2array(app.yarray3);
            
            app.yaxis3 = readtable('yaxisfile.txt','ReadVariableNames',false);
            app.yaxis3 = table2array(app.yaxis3);
            
            app.y3_0 = app.paramsy3(1);
            app.yc3 = app.paramsy3(1);                    
            app.sigmay3 = app.paramsy3(2);
            app.sigmay3start = app.paramsy3(2);                    
            app.Amplitudey3 = app.paramsy3(3);
            app.Amplitudey3start = app.paramsy3(3);
            
            % Create the fit for x
            py.xarraymaker.xfitmaker3();
            app.paramsx3 = readtable('xvariables.txt', 'ReadVariableNames', false);
            app.paramsx3 = table2array(app.paramsx3);
            
            app.xarray3 = readtable('xarrayfile.txt','ReadVariableNames',false);
            app.xarray3 = table2array(app.xarray3);
            
            app.xaxis3 = readtable('xaxisfile.txt','ReadVariableNames',false);
            app.xaxis3 = table2array(app.xaxis3);
            
            app.x3_0 = app.paramsx3(1);
            app.xc3 = app.paramsx3(1);                    
            app.D_x3 = app.paramsx3(2);
            app.D_x3start = app.paramsx3(2);                    
            app.Amplitudex3 = app.paramsx3(3);
            app.Amplitudex3start = app.paramsx3(3); 
            inten = app.paramsy3(3);
    
            % plot the data
            
            hold(app.UIAxes13,"on");
            imagesc(app.UIAxes13,app.frame3); 
            sz = size(app.frame3);
            app.UIAxes13.XLim = [0, sz(2)];
            app.UIAxes13.YLim = [0, sz(1)];
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            scatter(app.UIAxes13, round(app.x3_0), round(app.y3_0), 'x','red');
            colorbar(app.UIAxes13);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            hold(app.UIAxes5, 'on');
            xcurve = gaussfcn(app,app.xaxis3,app.x3_0,app.D_x3,app.Amplitudex3);
            plot(app.UIAxes5,app.xaxis3,xcurve);
            plot(app.UIAxes5,app.xaxis3,app.xarray3);
            xline(app.UIAxes5,app.xc3,'--r');            
            
            ycurve = gaussfcn(app,app.yaxis3,app.y3_0,app.sigmay3,app.Amplitudey3);
            hold(app.UIAxes6,'on');
            plot(app.UIAxes6,app.yaxis3,ycurve);
            plot(app.UIAxes6,app.yaxis3,app.yarray3);
            xline(app.UIAxes6,app.yc3,'--r');
            
            % append intensity to array
            app.intensity2 = [app.intensity2, inten];
            
            % append x/y centroids to arrays for plotting
            app.x2arr = [app.x2arr, app.xc3];
            app.y2arr = [app.y2arr, app.yc3];
            
            % plot intensity
            hold(app.UIAxes16_3, "on")
            plot(app.UIAxes16_3, app.intensity2, '--o', 'Color', 'r')
            
            % plot centroid
            hold(app.UIAxes16_8, "on")
            plot(app.UIAxes16_8, app.y2arr, '--o', 'Color', 'r')
            
            % Display the Parameters
%             app.Amplitudex3EditField.Value = sprintf('%.2f',app.Amplitudex3);
%             app.x3_0EditField_2.Value = sprintf('%.2f',app.x3_0);
%             app.D_x3EditField.Value = sprintf('%.2f',app.D_x3);     
%             
%             app.Amplitudey3EditField.Value = sprintf('%.2f',app.Amplitudey3);
%             app.y3_0EditField.Value = sprintf('%.2f',app.y3_0);
%             app.Sigmay3EditField.Value = sprintf('%.2f',app.sigmay3);
            app.Label11_5.Text = sprintf('%.2f', app.x3_0);
            app.Label12_5.Text = sprintf('%.2f', app.D_x3);
            app.Label11_6.Text = sprintf('%.2f', app.y3_0);
            app.Label12_6.Text = sprintf('%.2f', app.sigmay3);
            
            app.Label5_2.Text = sprintf('%.2f',app.x3_0);
            app.Label6_2.Text = sprintf('%.2f',app.y3_0);   
            app.Label17.Text = sprintf('%.3E', app.Amplitudex3);
            app.Label18.Text = sprintf('%.3E', app.Amplitudey3);
            
        end

        % Button pushed function: ExposeCamera3Button
        function ExposeCamera3ButtonPushed(app, event)
            if app.camera == -1
                app.camera = gigecam('S1255183');
                app.camera.Timeout=app.tout;
                app.camera.ExposureTime = app.exposure3;
            end

            if strcmp(app.camera.SerialNumber, 'S1255183') == 0
                delete(app.camera)
                clear app.camera
                app.camera = gigecam('S1255183');
                app.camera.Timeout=app.tout;
                app.camera.ExposureTime = app.exposure3;
            end
            
            app.frame4 = snapshot(app.camera);

            cla(app.UIAxes7);
            cla(app.UIAxes8);
            cla(app.UIAxes14);
            cla(app.UIAxes16_4);
            cla(app.UIAxes16_9);
            
%             app.Columns4DisplayOptionsDropDown.Value = 'Both';
%             app.Rows4DisplayOptionsDropDown.Value = 'Both';
            app.DisplayDropDown_4.Value = 'Full';

            mat2np(app,app.frame4, 'a4.pkl', 'uint8');
            py.arraysmaker.yfitmaker4();
            
            % Create the fit for y
            app.paramsy4 = readtable('variables.txt', 'ReadVariableNames', false);
            app.paramsy4 = table2array(app.paramsy4);
            
            app.yarray4 = readtable('yarrayfile.txt','ReadVariableNames',false);
            app.yarray4 = table2array(app.yarray4);
            
            app.yaxis4 = readtable('yaxisfile.txt','ReadVariableNames',false);
            app.yaxis4 = table2array(app.yaxis4);
                    
            app.y4_0 = app.paramsy4(1);
            app.yc4 = app.paramsy4(1);                    
            app.sigmay4 = app.paramsy4(2);
            app.sigmay4start = app.paramsy4(2);                    
            app.Amplitudey4 = app.paramsy4(3);
            app.Amplitudey4start = app.paramsy4(3);  

            % Create the fit for x
            py.xarraymaker.xfitmaker4();
            app.paramsx4 = readtable('xvariables.txt', 'ReadVariableNames', false);
            app.paramsx4 = table2array(app.paramsx4);
            
            app.xarray4 = readtable('xarrayfile.txt','ReadVariableNames',false);
            app.xarray4 = table2array(app.xarray4);
            
            app.xaxis4 = readtable('xaxisfile.txt','ReadVariableNames',false);
            app.xaxis4 = table2array(app.xaxis4);
            
            app.x4_0 = app.paramsx4(1);
            app.xc4 = app.paramsx4(1);                    
            app.D_x4 = app.paramsx4(2);
            app.D_x4start = app.paramsx4(2);                    
            app.Amplitudex4 = app.paramsx4(3);
            app.Amplitudex4start = app.paramsx4(3); 
            inten = app.paramsx4(3);
            
            % plot the data  
            hold(app.UIAxes14,"on");
            imagesc(app.UIAxes14,app.frame4); 
            sz = size(app.frame4);
            app.UIAxes14.XLim = [0, sz(2)];
            app.UIAxes14.YLim = [0, sz(1)];
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            scatter(app.UIAxes14, round(app.x4_0), round(app.y4_0), 'x','red');
            colorbar(app.UIAxes14);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            hold(app.UIAxes7, 'on');
            xcurve = gaussfcn(app,app.xaxis4,app.x4_0,app.D_x4,app.Amplitudex4);
            plot(app.UIAxes7,app.xaxis4,xcurve);
            plot(app.UIAxes7,app.xaxis4,app.xarray4);
            xline(app.UIAxes7,app.xc4,'--r');            
            
            ycurve = gaussfcn(app,app.yaxis4,app.y4_0,app.sigmay4,app.Amplitudey4);
            hold(app.UIAxes8,'on');
            plot(app.UIAxes8,app.yaxis4,ycurve);
            plot(app.UIAxes8,app.yaxis4,app.yarray4);  
            xline(app.UIAxes8,app.yc4,'--r');
            
            % append intensity to array
            app.intensity3 = [app.intensity3, inten];
            
            % append x/y centroids to arrays for plotting
            app.x3arr = [app.x3arr, app.xc4];
            app.y3arr = [app.y3arr, app.yc4];
            
            % plot intensity
            hold(app.UIAxes16_4, "on")
            plot(app.UIAxes16_4, app.intensity3, '--o', 'Color', 'g')
            
            % plot centroid
            hold(app.UIAxes16_6, "on")
            plot(app.UIAxes16_9, app.x3arr, '--o', 'Color', 'b')
          
            % Display the Parameters
%             app.Amplitudex4EditField.Value = sprintf('%.2f',app.Amplitudex4);
%             app.x4_0EditField.Value = sprintf('%.2f',app.x4_0);
%             app.D_x4EditField.Value = sprintf('%.2f',app.D_x4);     
%             
%             app.Amplitudey4EditField.Value = sprintf('%.2f',app.Amplitudey4);
%             app.y4_0EditField.Value = sprintf('%.2f',app.y4_0);
%             app.Sigmay4EditField.Value = sprintf('%.2f',app.sigmay4);
            app.Label11_7.Text = sprintf('%.2f', app.x4_0);
            app.Label12_7.Text = sprintf('%.2f', app.D_x4);
            app.Label11_8.Text = sprintf('%.2f', app.y4_0);
            app.Label12_8.Text = sprintf('%.2f', app.sigmay4);
            
            app.Label7.Text = sprintf('%.2f',app.x4_0);
            app.Label8.Text = sprintf('%.2f',app.y4_0);
            app.Label19.Text = sprintf('%.3E', app.Amplitudex4);
            app.Label20.Text = sprintf('%.3E', app.Amplitudey4);
            
        end

        % Button pushed function: ExposeCamera4Button
        function ExposeCamera4ButtonPushed(app, event)

            if app.camera == -1
                app.camera = gigecam('S1255193');
                app.camera.Timeout=app.tout;
                app.camera.ExposureTime = app.exposure4;
            end
            if strcmp(app.camera.SerialNumber, 'S1255193') == 0
                delete(app.camera)
                clear app.camera
                app.camera = gigecam('S1255193');
                app.camera.Timeout=app.tout;
                app.camera.ExposureTime = app.exposure4;
            end
            
            app.frame5 = snapshot(app.camera);

            cla(app.UIAxes9);
            cla(app.UIAxes10);
            cla(app.UIAxes15);
            cla(app.UIAxes16_5);
            cla(app.UIAxes16_10);
            
%             app.Columns5DisplayOptionsDropDown.Value = 'Both';
%             app.Rows5DisplayOptionsDropDown.Value = 'Both';
            app.DisplayDropDown_5.Value = 'Full';

            mat2np(app,app.frame5, 'a5.pkl', 'uint8');
            py.arraysmaker.yfitmaker5();
            
            % Create the fit for y
            app.paramsy5 = readtable('variables.txt', 'ReadVariableNames', false);
            app.paramsy5 = table2array(app.paramsy5);
            
            app.yarray5 = readtable('yarrayfile.txt','ReadVariableNames',false);
            app.yarray5 = table2array(app.yarray5);
            
            app.yaxis5 = readtable('yaxisfile.txt','ReadVariableNames',false);
            app.yaxis5 = table2array(app.yaxis5);
            
            app.y5_0 = app.paramsy5(1);
            app.yc5 = app.paramsy5(1);                    
            app.sigmay5 = app.paramsy5(2);
            app.sigmay5start = app.paramsy5(2);                    
            app.Amplitudey5 = app.paramsy5(3);
            app.Amplitudey5start = app.paramsy5(3);  

            % Create the fit for x
            py.xarraymaker.xfitmaker5();
            app.paramsx5 = readtable('xvariables.txt', 'ReadVariableNames', false);
            app.paramsx5 = table2array(app.paramsx5);
            
            app.xarray5 = readtable('xarrayfile.txt','ReadVariableNames',false);
            app.xarray5 = table2array(app.xarray5);
            
            app.xaxis5 = readtable('xaxisfile.txt','ReadVariableNames',false);
            app.xaxis5 = table2array(app.xaxis5);
            
            app.x5_0 = app.paramsx5(1);
            app.xc5 = app.paramsx5(1);                    
            app.D_x5 = app.paramsx5(2);
            app.D_x5start = app.paramsx5(2);                    
            app.Amplitudex5 = app.paramsx5(3);
            app.Amplitudex5start = app.paramsx5(3);
            inten = app.paramsy5(3);
            
            % plot the data
            hold(app.UIAxes15,"on");
            imagesc(app.UIAxes15,app.frame5); 
            sz = size(app.frame5);
            app.UIAxes15.XLim = [0, sz(2)];
            app.UIAxes15.YLim = [0, sz(1)];
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            scatter(app.UIAxes15, round(app.x5_0), round(app.y5_0), 'x','red');
            colorbar(app.UIAxes15);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    
            hold(app.UIAxes9, 'on');
            xcurve = gaussfcn(app,app.xaxis5,app.x5_0,app.D_x5,app.Amplitudex5);
            plot(app.UIAxes9,app.xaxis5,xcurve);
            plot(app.UIAxes9,app.xaxis5,app.xarray5);
            xline(app.UIAxes9,app.xc5,'--r');            
            
            ycurve = gaussfcn(app,app.yaxis5,app.y5_0,app.sigmay5,app.Amplitudey5);
            hold(app.UIAxes10,'on');
            plot(app.UIAxes10,app.yaxis5,ycurve);
            plot(app.UIAxes10,app.yaxis5,app.yarray5);
            xline(app.UIAxes10,app.yc5,'--r');
            
            % append intensity to array
            app.intensity4 = [app.intensity4, inten];
            
            % append x/y centroids to arrays for plotting
            app.x4arr = [app.x4arr, app.xc5];
            app.y4arr = [app.y4arr, app.yc5];
            
            % plot intensity
            hold(app.UIAxes16_5, "on")
            plot(app.UIAxes16_5, app.intensity4, '--o', 'Color', 'm')
            
            % plot centroid
            hold(app.UIAxes16_10, "on")
            plot(app.UIAxes16_10, app.y4arr, '--o', 'Color', 'r')
            
            % Display the Parameters
%             app.Amplitudex5EditField.Value = sprintf('%.2f',app.Amplitudex5);
%             app.x5_0EditField.Value = sprintf('%.2f',app.x5_0);
%             app.D_x5EditField.Value = sprintf('%.2f',app.D_x5);     
%             
%             app.Amplitudey5EditField.Value = sprintf('%.2f',app.Amplitudey5);
%             app.y5_0EditField.Value = sprintf('%.2f',app.y5_0);
%             app.Sigmay5EditField.Value = sprintf('%.2f',app.sigmay5);
            app.Label11_9.Text = sprintf('%.2f', app.x5_0);
            app.Label12_9.Text = sprintf('%.2f', app.D_x5);
            app.Label11_10.Text = sprintf('%.2f', app.y5_0);
            app.Label12_10.Text = sprintf('%.2f', app.sigmay5);
            
            app.Label9.Text = sprintf('%.2f',app.x5_0);
            app.Label10.Text = sprintf('%.2f',app.y5_0);
            app.Label21.Text = sprintf('%.3E', app.Amplitudex5);
            app.Label22.Text = sprintf('%.3E', app.Amplitudey5);
            
        end

        % Button pushed function: ExposeCamerasButton
        function ExposeCamerasButtonPushed(app, event)
%             app.Columns1DisplayOptionsDropDown.Value = 'Both';
%             app.Rows1DisplayOptionsDropDown.Value = 'Both';
            app.DisplayDropDown_3.Value = 'Full';
            
%             app.Columns2DisplayOptionsDropDown.Value = 'Both';
%             app.Rows2DisplayOptionsDropDown.Value = 'Both';
            app.DisplayDropDown_2.Value = 'Full';            

%             app.Columns3DisplayOptionsDropDown.Value = 'Both';
%             app.Rows3DisplayOptionsDropDown.Value = 'Both';
            app.DisplayDropDown.Value = 'Full';

%             app.Columns4DisplayOptionsDropDown.Value = 'Both';
%             app.Rows4DisplayOptionsDropDown.Value = 'Both';
            app.DisplayDropDown_4.Value = 'Full';   
            
%             app.Columns5DisplayOptionsDropDown.Value = 'Both';
%             app.Rows5DisplayOptionsDropDown.Value = 'Both';
            app.DisplayDropDown_5.Value = 'Full';
            
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            cla(app.UIAxes5);
            cla(app.UIAxes6);
            cla(app.UIAxes7);
            cla(app.UIAxes8);
            cla(app.UIAxes9);
            cla(app.UIAxes10);
            cla(app.UIAxes11);
            cla(app.UIAxes12);
            cla(app.UIAxes13);
            cla(app.UIAxes14);
            cla(app.UIAxes15);
            cla(app.UIAxes16);
            cla(app.UIAxes16_2);
            cla(app.UIAxes16_3);
            cla(app.UIAxes16_4);
            cla(app.UIAxes16_5);
            cla(app.UIAxes16_6);
            cla(app.UIAxes16_7);
            cla(app.UIAxes16_8);
            cla(app.UIAxes16_9);
            cla(app.UIAxes16_10);
            
            app.camera = -1;
            s = 5; % or whatever the number of cameras is
            for count = 1:s
                if count == 1
                    clear app.camera
                    pause(0.25)
                    app.camera = gigecam('S1098217');
                    app.camera.Timeout=app.tout;
                    app.camera.ExposureTime = app.exposure0;
                    app.frame1 = snapshot(app.camera);
                    mat2np(app,app.frame1, 'a.pkl', 'uint8');
                    py.arraysmaker.yfitmaker();
                    
                    % Create the fit for y
                    app.paramsy1 = readtable('variables.txt', 'ReadVariableNames', false);
                    app.paramsy1 = table2array(app.paramsy1);
                    
                    app.yarray1 = readtable('yarrayfile.txt','ReadVariableNames',false);
                    app.yarray1 = table2array(app.yarray1);
                    
                    app.yaxis1 = readtable('yaxisfile.txt','ReadVariableNames',false);
                    app.yaxis1 = table2array(app.yaxis1);
                    
                    app.y1_0 = app.paramsy1(1);
                    app.yc1 = app.paramsy1(1);                    
                    app.sigmay1 = app.paramsy1(2);
                    app.sigmay1start = app.paramsy1(2);                    
                    app.Amplitudey1 = app.paramsy1(3);
                    app.Amplitudey1start = app.paramsy1(3);
                    
                    % Create the fit for x
                    py.xarraymaker.xfitmaker();
                    app.paramsx1 = readtable('xvariables.txt', 'ReadVariableNames', false);
                    app.paramsx1 = table2array(app.paramsx1);
                    
                    app.xarray1 = readtable('xarrayfile.txt','ReadVariableNames',false);
                    app.xarray1 = table2array(app.xarray1);
                    
                    app.xaxis1 = readtable('xaxisfile.txt','ReadVariableNames',false);
                    app.xaxis1 = table2array(app.xaxis1);
                    
                    app.x1_0 = app.paramsx1(1);
                    app.xc1 = app.paramsx1(1);
                    app.D_x1 = app.paramsx1(2);
                    app.D_x1start = app.paramsx1(2);                    
                    app.Amplitudex1 = app.paramsx1(3);
                    app.Amplitudex1start = app.paramsx1(3);
                    inten = app.paramsx1(3);
                    
                    % plot the data
                    hold(app.UIAxes11,"on");
                    imagesc(app.UIAxes11,app.frame1); 
                    sz = size(app.frame1);
                    app.UIAxes11.XLim = [0, sz(2)];
                    app.UIAxes11.YLim = [0, sz(1)];
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    scatter(app.UIAxes11, round(app.x1_0), round(app.y1_0), 'x','red');
                    colorbar(app.UIAxes11);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    
                    hold(app.UIAxes, 'on');
                    xcurve = gaussfcn(app,app.xaxis1,app.x1_0,app.D_x1,app.Amplitudex1);
                    plot(app.UIAxes,app.xaxis1,xcurve);
                    plot(app.UIAxes,app.xaxis1,app.xarray1);
                    plot(app.UIAxes,app.xaxis1,app.xc1);
                    
                    ycurve = gaussfcn(app,app.yaxis1,app.y1_0,app.sigmay1,app.Amplitudey1);
                    hold(app.UIAxes2,'on');
                    plot(app.UIAxes2,app.yaxis1,ycurve);
                    plot(app.UIAxes2,app.yaxis1,app.yarray1);
                    plot(app.UIAxes2,app.yaxis1,app.yc1);
                    
                    % append intensity to array
                    app.intensity0 = [app.intensity0, inten];
            
                    % append x/y centroids to arrays for plotting
                    app.x0arr = [app.x0arr, app.xc1];
                    app.y0arr = [app.y0arr, app.yc1];
            
                    % plot intensity
                    hold(app.UIAxes16, "on")
                    plot(app.UIAxes16, app.intensity0, '--o', 'Color', 'k')
            
                    % plot centroid
                    hold(app.UIAxes16_6, "on")
                    plot(app.UIAxes16_6, app.x0arr, '--o', 'Color', 'b')
                    
                    % Display the Parameters
%                     app.Amplitudex1EditField.Value = sprintf('%s',app.Amplitudex1);
%                     app.x1_0EditField_2.Value = sprintf('%s',app.x1_0);
%                     app.D_x1EditField.Value = sprintf('%s',app.D_x1);     
%                     
%                     app.Amplitudey1EditField.Value = sprintf('%s',app.Amplitudey1);
%                     app.y1_0EditField.Value = sprintf('%s',app.y1_0);
%                     app.Sigmay1EditField.Value = sprintf('%s',app.sigmay1);
                    app.Label11.Text = sprintf('%.2f', app.x1_0);
                    app.Label12.Text = sprintf('%.2f', app.D_x1);
                    app.Label11_2.Text = sprintf('%.2f', app.y1_0);
                    app.Label12_2.Text = sprintf('%.2f', app.sigmay1);
                    app.Label15.Text = sprintf('%.3E', app.Amplitudex1);
                    app.Label16.Text = sprintf('%.3E', app.Amplitudey1);
                    
                    app.Label.Text = sprintf('%.2f',app.x1_0);
                    app.Label2.Text = sprintf('%.2f',app.y1_0);
                    delete(app.camera)
                    clear app.camera
                    pause(0.25)
                end
                if count == 2
                    app.camera = gigecam('S1100936');
                    app.camera.Timeout=app.tout;
                    app.camera.ExposureTime = app.exposure1;
                    app.frame2 = snapshot(app.camera);
                    mat2np(app,app.frame2, 'a2.pkl', 'uint8');
                    py.arraysmaker.yfitmaker2();
                    
                    % Create the fit for y
                    app.paramsy2 = readtable('variables.txt', 'ReadVariableNames', false);
                    app.paramsy2 = table2array(app.paramsy2);
                    
                    app.yarray2 = readtable('yarrayfile.txt','ReadVariableNames',false);
                    app.yarray2 = table2array(app.yarray2);
                    
                    app.yaxis2 = readtable('yaxisfile.txt','ReadVariableNames',false);
                    app.yaxis2 = table2array(app.yaxis2);
                    
                    app.y2_0 = app.paramsy2(1);
                    app.yc2 = app.paramsy2(1);                    
                    app.sigmay2 = app.paramsy2(2);
                    app.sigmay2start = app.paramsy2(2);                    
                    app.Amplitudey2 = app.paramsy2(3);
                    app.Amplitudey2start = app.paramsy2(3);                    
                    
                    % Create the fit for x
                    py.xarraymaker.xfitmaker2();
                    app.paramsx2 = readtable('xvariables.txt', 'ReadVariableNames', false);
                    app.paramsx2 = table2array(app.paramsx2);
                    
                    app.xarray2 = readtable('xarrayfile.txt','ReadVariableNames',false);
                    app.xarray2 = table2array(app.xarray2);
                    
                    app.xaxis2 = readtable('xaxisfile.txt','ReadVariableNames',false);
                    app.xaxis2 = table2array(app.xaxis2);
                    
                    app.x2_0 = app.paramsx2(1);
                    app.xc2 = app.paramsx2(1);                    
                    app.D_x2 = app.paramsx2(2);
                    app.D_x2start = app.paramsx2(2);                    
                    app.Amplitudex2 = app.paramsx2(3);
                    app.Amplitudex2start = app.paramsx2(3); 
                    inten = app.paramsx2(3);
                    
                    % plot the data                    
                    hold(app.UIAxes12,"on");
                    imagesc(app.UIAxes12,app.frame2); 
                    sz = size(app.frame2);
                    app.UIAxes12.XLim = [0, sz(2)];
                    app.UIAxes12.YLim = [0, sz(1)];
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    scatter(app.UIAxes12, round(app.x2_0), round(app.y2_0), 'x','red');
                    colorbar(app.UIAxes12);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    
                    xcurve = gaussfcn(app,app.xaxis2,app.x2_0,app.D_x2,app.Amplitudex2);
                    hold(app.UIAxes3, 'on');
                    plot(app.UIAxes3,app.xaxis2,xcurve);
                    plot(app.UIAxes3,app.xaxis2,app.xarray2);
                    plot(app.UIAxes3,app.xaxis2,app.xc2);                    
                    
                    ycurve = gaussfcn(app,app.yaxis2,app.y2_0,app.sigmay2,app.Amplitudey2);
                    hold(app.UIAxes4,'on');
                    plot(app.UIAxes4,app.yaxis2,ycurve);
                    plot(app.UIAxes4,app.yaxis2,app.yarray2);
                    plot(app.UIAxes4,app.yaxis2,app.yc2);   
                    
                    % append intensity to array
                    app.intensity1 = [app.intensity1, inten];
            
                    % append x/y centroids to arrays for plotting
                    app.x1arr = [app.x1arr, app.xc2];
                    app.y1arr = [app.y1arr, app.yc2];
            
                    % plot intensity
                    hold(app.UIAxes16_2, "on")
                    plot(app.UIAxes16_2, app.intensity1, '--o', 'Color', 'b')
            
                    % plot centroid
                    hold(app.UIAxes16_7, "on")
                    plot(app.UIAxes16_7, app.x1arr, '--o', 'Color', 'b')
                    
                    % Display the parameters

%                     app.Amplitudex2EditField.Value = sprintf('%s',app.Amplitudex2);
%                     app.x2_0EditField_2.Value = sprintf('%s',app.x2_0);
%                     app.D_x2EditField.Value = sprintf('%s',app.D_x2);     
%                     
%                     app.Amplitudey2EditField.Value = sprintf('%s',app.Amplitudey2);
%                     app.y2_0EditField.Value = sprintf('%s',app.y2_0);
%                     app.Sigmay2EditField.Value = sprintf('%s',app.sigmay2);
                    app.Label11_3.Text = sprintf('%.2f', app.x2_0);
                    app.Label12_3.Text = sprintf('%.2f', app.D_x2);
                    app.Label11_4.Text = sprintf('%.2f', app.y2_0);
                    app.Label12_4.Text = sprintf('%.2f', app.sigmay2);
                    app.Label13.Text = sprintf('%.3E', app.Amplitudex2);
                    app.Label14.Text = sprintf('%.3E', app.Amplitudey2);
                    
                    app.Label3.Text = sprintf('%.2f',app.x2_0);
                    app.Label4.Text = sprintf('%.2f',app.y2_0);   
                    delete(app.camera)
                    clear app.camera
                    pause(0.25)
                end
                if count == 3
                    app.camera = gigecam('S1254378');
                    app.camera.Timeout=app.tout;
                    app.camera.ExposureTime = app.exposure2;
                    app.frame3 = snapshot(app.camera);
                    mat2np(app,app.frame3, 'a3.pkl', 'uint8');
                    py.arraysmaker.yfitmaker3();
                    
                    % Create the fit for y
                    % Create the fit for y
                    app.paramsy3 = readtable('variables.txt', 'ReadVariableNames', false);
                    app.paramsy3 = table2array(app.paramsy3);
                    
                    app.yarray3 = readtable('yarrayfile.txt','ReadVariableNames',false);
                    app.yarray3 = table2array(app.yarray3);
                    
                    app.yaxis3 = readtable('yaxisfile.txt','ReadVariableNames',false);
                    app.yaxis3 = table2array(app.yaxis3);
                    
                    app.y3_0 = app.paramsy3(1);
                    app.yc3 = app.paramsy3(1);                    
                    app.sigmay3 = app.paramsy3(2);
                    app.sigmay3start = app.paramsy3(2);                    
                    app.Amplitudey3 = app.paramsy3(3);
                    app.Amplitudey3start = app.paramsy3(3);                    
                    
                    % Create the fit for x
                    py.xarraymaker.xfitmaker3();
                    app.paramsx3 = readtable('xvariables.txt', 'ReadVariableNames', false);
                    app.paramsx3 = table2array(app.paramsx3);
                    
                    app.xarray3 = readtable('xarrayfile.txt','ReadVariableNames',false);
                    app.xarray3 = table2array(app.xarray3);
                    
                    app.xaxis3 = readtable('xaxisfile.txt','ReadVariableNames',false);
                    app.xaxis3 = table2array(app.xaxis3);
                    
                    app.x3_0 = app.paramsx3(1);
                    app.xc3 = app.paramsx3(1);                    
                    app.D_x3 = app.paramsx3(2);
                    app.D_x3start = app.paramsx3(2);                    
                    app.Amplitudex3 = app.paramsx3(3);
                    app.Amplitudex3start = app.paramsx3(3); 
                    inten = app.paramsy3(3);
                    
                    % plot the data  
                    hold(app.UIAxes13,"on");
                    imagesc(app.UIAxes13,app.frame3); 
                    sz = size(app.frame3);
                    app.UIAxes13.XLim = [0, sz(2)];
                    app.UIAxes13.YLim = [0, sz(1)];
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    scatter(app.UIAxes13, round(app.x3_0), round(app.y3_0), 'x','red');
                    colorbar(app.UIAxes13);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    
                    hold(app.UIAxes5, 'on');
                    xcurve = gaussfcn(app,app.xaxis3,app.x3_0,app.D_x3,app.Amplitudex3);
                    plot(app.UIAxes5,app.xaxis3,xcurve);
                    plot(app.UIAxes5,app.xaxis3,app.xarray3);
                    plot(app.UIAxes5,app.xaxis3,app.xc3);                    
                    
                    ycurve = gaussfcn(app,app.yaxis3,app.y3_0,app.sigmay3,app.Amplitudey3);
                    hold(app.UIAxes6,'on');
                    plot(app.UIAxes6,app.yaxis3,ycurve);
                    plot(app.UIAxes6,app.yaxis3,app.yarray3);                    
                    plot(app.UIAxes6,app.yaxis3,app.yc3);
                    
                    % append intensity to array
                    app.intensity2 = [app.intensity2, inten];
            
                    % append x/y centroids to arrays for plotting
                    app.x2arr = [app.x2arr, app.xc3];
                    app.y2arr = [app.y2arr, app.yc3];
            
                    % plot intensity
                    hold(app.UIAxes16_3, "on")
                    plot(app.UIAxes16_3, app.intensity2, '--o', 'Color', 'r')
            
                    % plot centroid
                    hold(app.UIAxes16_8, "on")
                    plot(app.UIAxes16_8, app.y2arr, '--o', 'Color', 'r')
                    
                    % Display the Parameters
%                     app.Amplitudex3EditField.Value = sprintf('%s',app.Amplitudex3);
%                     app.x3_0EditField_2.Value = sprintf('%s',app.x3_0);
%                     app.D_x3EditField.Value = sprintf('%s',app.D_x3);     
%                     
%                     app.Amplitudey3EditField.Value = sprintf('%s',app.Amplitudey3);
%                     app.y3_0EditField.Value = sprintf('%s',app.y3_0);
%                     app.Sigmay3EditField.Value = sprintf('%s',app.sigmay3);
                    app.Label11_5.Text = sprintf('%.2f', app.x3_0);
                    app.Label12_5.Text = sprintf('%.2f', app.D_x3);
                    app.Label11_6.Text = sprintf('%.2f', app.y3_0);
                    app.Label12_6.Text = sprintf('%.2f', app.sigmay3);
                    app.Label17.Text = sprintf('%.3E', app.Amplitudex3);
                    app.Label18.Text = sprintf('%.3E', app.Amplitudey3);
                    
                    app.Label5_2.Text = sprintf('%.2f',app.x3_0);
                    app.Label6_2.Text = sprintf('%.2f',app.y3_0); 
                    delete(app.camera)
                    clear app.camera
                    pause(0.25)
                end
           
                if count == 4

                    app.camera = gigecam('S1255183');
                    app.camera.Timeout=app.tout;
                    app.camera.ExposureTime = app.exposure3;
                    app.frame4 = snapshot(app.camera);
                    mat2np(app,app.frame4, 'a4.pkl', 'uint8');
                    py.arraysmaker.yfitmaker4();
                    
                    % Create the fit for y
                    app.paramsy4 = readtable('variables.txt', 'ReadVariableNames', false);
                    app.paramsy4 = table2array(app.paramsy4);
                    
                    app.yarray4 = readtable('yarrayfile.txt','ReadVariableNames',false);
                    app.yarray4 = table2array(app.yarray4);
                    
                    app.yaxis4 = readtable('yaxisfile.txt','ReadVariableNames',false);
                    app.yaxis4 = table2array(app.yaxis4);
                    
                    app.y4_0 = app.paramsy4(1);
                    app.yc4 = app.paramsy1(1);                    
                    app.sigmay4 = app.paramsy4(2);
                    app.sigmay4start = app.paramsy4(2);                    
                    app.Amplitudey4 = app.paramsy4(3);
                    app.Amplitudey4start = app.paramsy4(3);   
                    
                    % Create the fit for x
                    py.xarraymaker.xfitmaker4();
                    app.paramsx4 = readtable('xvariables.txt', 'ReadVariableNames', false);
                    app.paramsx4 = table2array(app.paramsx4);
                    
                    app.xarray4 = readtable('xarrayfile.txt','ReadVariableNames',false);
                    app.xarray4 = table2array(app.xarray4);
                    
                    app.xaxis4 = readtable('xaxisfile.txt','ReadVariableNames',false);
                    app.xaxis4 = table2array(app.xaxis4);
                    
                    app.x4_0 = app.paramsx4(1);
                    app.xc4 = app.paramsx4(1);                    
                    app.D_x4 = app.paramsx4(2);
                    app.D_x4start = app.paramsx4(2);                    
                    app.Amplitudex4 = app.paramsx4(3);
                    app.Amplitudex4start = app.paramsx4(3); 
                    inten = app.paramsx4(3);
                    
                    % plot the data  
                    hold(app.UIAxes14,"on");
                    imagesc(app.UIAxes14,app.frame4); 
                    sz = size(app.frame4);
                    app.UIAxes14.XLim = [0, sz(2)];
                    app.UIAxes14.YLim = [0, sz(1)];
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    scatter(app.UIAxes14, round(app.x4_0), round(app.y4_0), 'x','red');
                    colorbar(app.UIAxes14);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                    hold(app.UIAxes7, 'on');
                    xcurve = gaussfcn(app,app.xaxis4,app.x4_0,app.D_x4,app.Amplitudex4);
                    plot(app.UIAxes7,app.xaxis4,xcurve);
                    plot(app.UIAxes7,app.xaxis4,app.xarray4);
                    plot(app.UIAxes,app.xaxis4,app.xc4);                    
                    
                    ycurve = gaussfcn(app,app.yaxis4,app.y4_0,app.sigmay4,app.Amplitudey4);
                    hold(app.UIAxes8,'on');
                    plot(app.UIAxes8,app.yaxis4,ycurve);
                    plot(app.UIAxes8,app.yaxis4,app.yarray4); 
                    plot(app.UIAxes8,app.yaxis4,app.yc4);   
                    
                    % append intensity to array
                    app.intensity3 = [app.intensity3, inten];
            
                    % append x/y centroids to arrays for plotting
                    app.x3arr = [app.x3arr, app.xc4];
                    app.y3arr = [app.y3arr, app.yc4];
            
                    % plot intensity
                    hold(app.UIAxes16_4, "on")
                    plot(app.UIAxes16_4, app.intensity3, '--o', 'Color', 'g')
            
                    % plot centroid
                    hold(app.UIAxes16_9, "on")
                    plot(app.UIAxes16_9, app.x3arr, '--o', 'Color', 'b')
                  
                    % Display the Parameters
%                     app.Amplitudex4EditField.Value = sprintf('%s',app.Amplitudex4);
%                     app.x4_0EditField.Value = sprintf('%s',app.x4_0);
%                     app.D_x4EditField.Value = sprintf('%s',app.D_x4);     
%                     
%                     app.Amplitudey4EditField.Value = sprintf('%s',app.Amplitudey4);
%                     app.y4_0EditField.Value = sprintf('%s',app.y4_0);
%                     app.Sigmay4EditField.Value = sprintf('%s',app.sigmay4);
                    app.Label11_7.Text = sprintf('%.2f', app.x4_0);
                    app.Label12_7.Text = sprintf('%.2f', app.D_x4);
                    app.Label11_8.Text = sprintf('%.2f', app.y4_0);
                    app.Label12_8.Text = sprintf('%.2f', app.sigmay4);
                    app.Label19.Text = sprintf('%.3E', app.Amplitudex4);
                    app.Label20.Text = sprintf('%.3E', app.Amplitudey4);
                    
                    app.Label7.Text = sprintf('%.2f',app.x4_0);
                    app.Label8.Text = sprintf('%.2f',app.y4_0);
                    delete(app.camera)
                    clear app.camera
                    pause(0.25)
                end
%                 if count == 5
%                     app.camera = gigecam('S1255193');
%                     app.camera.Timeout=app.tout;
%                     app.camera.ExposureTime = app.exposure4;
%                     app.frame5 = snapshot(app.camera);
%                     mat2np(app,app.frame5, 'a5.pkl', 'uint8');
%                     py.arraysmaker.yfitmaker5();
%                     
%                     % Create the fit for y
%                     app.paramsy5 = readtable('variables.txt', 'ReadVariableNames', false);
%                     app.paramsy5 = table2array(app.paramsy5);
%                     
%                     app.yarray5 = readtable('yarrayfile.txt','ReadVariableNames',false);
%                     app.yarray5 = table2array(app.yarray5);
%                     
%                     app.yaxis5 = readtable('yaxisfile.txt','ReadVariableNames',false);
%                     app.yaxis5 = table2array(app.yaxis5);
%                     
%                     app.y5_0 = app.paramsy5(1);
%                     app.yc5 = app.paramsy5(1);                    
%                     app.sigmay5 = app.paramsy5(2);
%                     app.sigmay5start = app.paramsy5(2);                    
%                     app.Amplitudey5 = app.paramsy5(3);
%                     app.Amplitudey5start = app.paramsy5(3);  
%                     
%                     % Create the fit for x
%                     py.xarraymaker.xfitmaker5();
%                     app.paramsx5 = readtable('xvariables.txt', 'ReadVariableNames', false);
%                     app.paramsx5 = table2array(app.paramsx5);
%                     
%                     app.xarray5 = readtable('xarrayfile.txt','ReadVariableNames',false);
%                     app.xarray5 = table2array(app.xarray5);
%                     
%                     app.xaxis5 = readtable('xaxisfile.txt','ReadVariableNames',false);
%                     app.xaxis5 = table2array(app.xaxis5);
%                     
%                     app.x5_0 = app.paramsx5(1);
%                     app.xc5 = app.paramsx5(1);                    
%                     app.D_x5 = app.paramsx5(2);
%                     app.D_x5start = app.paramsx5(2);                    
%                     app.Amplitudex5 = app.paramsx5(3);
%                     app.Amplitudex5start = app.paramsx5(3);   
%                     inten = app.paramsy5(3);
%                     
%                     % plot the data  
%                     hold(app.UIAxes15,"on");
%                     imagesc(app.UIAxes15,app.frame5); 
%                     sz = size(app.frame5);
%                     app.UIAxes15.XLim = [0, sz(2)];
%                     app.UIAxes15.YLim = [0, sz(1)];
%                     %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                     scatter(app.UIAxes15, round(app.x5_0), round(app.y5_0), 'x','red');
%                     colorbar(app.UIAxes15);
%                     %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                     
%                     hold(app.UIAxes9, 'on');
%                     xcurve = gaussfcn(app,app.xaxis5,app.x5_0,app.D_x5,app.Amplitudex5);
%                     plot(app.UIAxes9,app.xaxis5,xcurve);
%                     plot(app.UIAxes9,app.xaxis5,app.xarray5);
%                     plot(app.UIAxes9,app.xaxis5,app.xc5);                    
%                     
%                     ycurve = gaussfcn(app,app.yaxis5,app.y5_0,app.sigmay5,app.Amplitudey5);
%                     hold(app.UIAxes10,'on');
%                     plot(app.UIAxes10,app.yaxis5,ycurve);
%                     plot(app.UIAxes10,app.yaxis5,app.yarray5);
%                     plot(app.UIAxes10,app.yaxis5,app.yc5);   
%                     
%                     % append intensity to array
%                     app.intensity4 = [app.intensity4, inten];
%             
%                     % append x/y centroids to arrays for plotting
%                     app.x4arr = [app.x4arr, app.xc5];
%                     app.y4arr = [app.y4arr, app.yc5];
%             
%                     % plot intensity
%                     hold(app.UIAxes16_5, "on")
%                     plot(app.UIAxes16_5, app.intensity4, '--o', 'Color', 'm')
%             
%                     % plot centroid
%                     hold(app.UIAxes16_10, "on")
%                     plot(app.UIAxes16_10, app.y4arr, '--o', 'Color', 'r')
%                     
%                     % Display the Parameters
% %                     app.Amplitudex5EditField.Value = sprintf('%s',app.Amplitudex5);
% %                     app.x5_0EditField.Value = sprintf('%s',app.x5_0);
% %                     app.D_x5EditField.Value = sprintf('%s',app.D_x5);     
% %                     
% %                     app.Amplitudey5EditField.Value = sprintf('%s',app.Amplitudey5);
% %                     app.y5_0EditField.Value = sprintf('%s',app.y5_0);
% %                     app.Sigmay5EditField.Value = sprintf('%s',app.sigmay5);
%                     app.Label11_9.Text = sprintf('%.2f', app.x5_0);
%                     app.Label12_9.Text = sprintf('%.2f', app.D_x5);
%                     app.Label11_10.Text = sprintf('%.2f', app.y5_0);
%                     app.Label12_10.Text = sprintf('%.2f', app.sigmay5);
%                     app.Label21.Text = sprintf('%.3E', app.Amplitudex5);
%                     app.Label22.Text = sprintf('%.3E', app.Amplitudey5);
%                     
%                     app.Label9.Text = sprintf('%.2f',app.x5_0);
%                     app.Label10.Text = sprintf('%.2f',app.y5_0);
%                     
%                     delete(app.camera)
%                     clear app.camera
%                     app.camera = -1; 
%                 end
            end
        end

        % Callback function
        function Rows1DisplayOptionsDropDownValueChanged(app, event)
            value = app.Rows1DisplayOptionsDropDown.Value;
            if strcmp(value,'Both')
                cla(app.UIAxes2);
                ycurve = gaussfcn(app,app.yaxis1,app.y1_0,app.sigmay1,app.Amplitudey1);
                hold(app.UIAxes2,'on');
                plot(app.UIAxes2,app.yaxis1,ycurve);
                plot(app.UIAxes2,app.yaxis1,app.yarray1);
                xline(app.UIAxes2,app.y1_0,'--r');
            end
            if strcmp(value,'Data')
                cla(app.UIAxes2);            
                hold(app.UIAxes2,'on');
                plot(app.UIAxes2,app.yaxis1,app.yarray1); 
                xline(app.UIAxes2,app.y1_0,'--r');             
            end
        end

        % Callback function
        function Rows2DisplayOptionsDropDownValueChanged(app, event)
            value = app.Rows2DisplayOptionsDropDown.Value;

            if strcmp(value,'Both')
                cla(app.UIAxes4);
                ycurve = gaussfcn(app,app.yaxis2,app.y2_0,app.sigmay2,app.Amplitudey2);
                hold(app.UIAxes4,'on');
                plot(app.UIAxes4,app.yaxis2,ycurve);
                plot(app.UIAxes4,app.yaxis2,app.yarray2);               
                xline(app.UIAxes4,app.y2_0,'--r');
            end
            if strcmp(value,'Data')
                cla(app.UIAxes4);      
                hold(app.UIAxes4,'on');
                plot(app.UIAxes4,app.yaxis2,app.yarray2);            
                xline(app.UIAxes4,app.y2_0,'--r');
            end             
        end

        % Callback function
        function Rows3DisplayOptionsDropDownValueChanged(app, event)
            value = app.Rows3DisplayOptionsDropDown.Value;

            if strcmp(value,'Both')
                cla(app.UIAxes6);
                ycurve = gaussfcn(app,app.yaxis3,app.y3_0,app.sigmay3,app.Amplitudey3);
                hold(app.UIAxes6,'on');
                plot(app.UIAxes6,app.yaxis3,ycurve);
                plot(app.UIAxes6,app.yaxis3,app.yarray3);    
                xline(app.UIAxes6,app.y3_0,'--r');
            end
            if strcmp(value,'Data')
                cla(app.UIAxes6); 
                hold(app.UIAxes6,'on');
                plot(app.UIAxes6,app.yaxis3,app.yarray3);     
                xline(app.UIAxes6,app.y3_0,'--r');
            end            
        end

        % Callback function
        function Rows4DisplayOptionsDropDownValueChanged(app, event)
            value = app.Rows4DisplayOptionsDropDown.Value;

            if strcmp(value,'Both')
                cla(app.UIAxes8);
                ycurve = gaussfcn(app,app.yaxis4,app.y4_0,app.sigmay4,app.Amplitudey4);
                hold(app.UIAxes8,'on');
                plot(app.UIAxes8,app.yaxis4,ycurve);
                plot(app.UIAxes8,app.yaxis4,app.yarray4);        
                xline(app.UIAxes8,app.y4_0,'--r');
            end
            if strcmp(value,'Data')
                cla(app.UIAxes8);      
                hold(app.UIAxes8,'on');
                plot(app.UIAxes8,app.yaxis4,app.yarray4);        
                xline(app.UIAxes8,app.y4_0,'--r');
            end                 
        end

        % Callback function
        function Rows5DisplayOptionsDropDownValueChanged(app, event)
            value = app.Rows5DisplayOptionsDropDown.Value;

            if strcmp(value,'Both')
                cla(app.UIAxes10);
                ycurve = gaussfcn(app,app.yaxis5,app.y5_0,app.sigmay5,app.Amplitudey5);
                hold(app.UIAxes10,'on');
                plot(app.UIAxes10,app.yaxis5,ycurve);
                plot(app.UIAxes10,app.yaxis5,app.yarray5);  
                xline(app.UIAxes10,app.y5_0,'--r');
            end
            if strcmp(value, 'Data')
                cla(app.UIAxes10);        
                hold(app.UIAxes10,'on');
                plot(app.UIAxes10,app.yaxis5,app.yarray5);  
                xline(app.UIAxes10,app.y5_0,'--r');
            end                
        end

        % Callback function
        function Columns1DisplayOptionsDropDownValueChanged(app, event)
            value = app.Columns1DisplayOptionsDropDown.Value;

            if  strcmp(value,'Both')
                cla(app.UIAxes);
                hold(app.UIAxes, 'on');
                xcurve = gaussfcn(app,app.xaxis1,app.x1_0,app.sigmay1,app.Amplitudex1);
                plot(app.UIAxes,app.xaxis1,xcurve);
                plot(app.UIAxes,app.xaxis1,app.xarray1);
                xline(app.UIAxes,app.x1_0,'--r');                
            end
            if strcmp(value,'Data')
                cla(app.UIAxes);           
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,app.xaxis1,app.xarray1);
                xline(app.UIAxes,app.x1_0,'--r');                
            end            
        end

        % Callback function
        function Columns2DisplayOptionsDropDownValueChanged(app, event)
            value = app.Columns2DisplayOptionsDropDown.Value;
            if strcmp(value,'Both')
                cla(app.UIAxes3);
                xcurve = gaussfcn(app,app.xaxis2,app.x2_0,app.sigmay2,app.Amplitudex2);
                hold(app.UIAxes3, 'on');
                plot(app.UIAxes3,app.xaxis2,xcurve);
                plot(app.UIAxes3,app.xaxis2,app.xarray2);
                xline(app.UIAxes3,app.x2_0,'--r');                
            end
            if strcmp(value,'Data')
                cla(app.UIAxes3);     
                hold(app.UIAxes3, 'on');
                plot(app.UIAxes3,app.xaxis2,app.xarray2);                 
                xline(app.UIAxes3,app.x2_0,'--r');
            end         
        end

        % Callback function
        function Columns3DisplayOptionsDropDownValueChanged(app, event)
            value = app.Columns3DisplayOptionsDropDown.Value;
            if strcmp(value,'Both')
                cla(app.UIAxes5);
                hold(app.UIAxes5, 'on');
                xcurve = gaussfcn(app,app.xaxis3,app.x3_0,app.sigmay3,app.Amplitudex3);
                plot(app.UIAxes5,app.xaxis3,xcurve);
                plot(app.UIAxes5,app.xaxis3,app.xarray3);               
                xline(app.UIAxes5,app.x3_0,'--r');
            end
            if strcmp(value,'Data')
                cla(app.UIAxes5);     
                hold(app.UIAxes5, 'on');
                plot(app.UIAxes5,app.xaxis3,app.xarray3);            
                xline(app.UIAxes5,app.x3_0,'--r');
            end             
        end

        % Callback function
        function Columns4DisplayOptionsDropDownValueChanged(app, event)
            value = app.Columns4DisplayOptionsDropDown.Value;
            if strcmp(value,'Both')
                cla(app.UIAxes7);
                hold(app.UIAxes7, 'on');
                xcurve = gaussfcn(app,app.xaxis4,app.x4_0,app.sigmay4,app.Amplitudex4);
                plot(app.UIAxes7,app.xaxis4,xcurve);
                plot(app.UIAxes7,app.xaxis4,app.xarray4);     
                xline(app.UIAxes7,app.x4_0,'--r');
            end
            if strcmp(value,'Data')
                cla(app.UIAxes7); 
                hold(app.UIAxes7, 'on');
                plot(app.UIAxes7,app.xaxis4,app.xarray4);         
                xline(app.UIAxes7,app.x4_0,'--r');
            end             
        end

        % Callback function
        function Columns5DisplayOptionsDropDownValueChanged(app, event)
            value = app.Columns5DisplayOptionsDropDown.Value;
            if strcmp(value,'Both')
                hold(app.UIAxes9, 'on');
                cla(app.UIAxes9);
                xcurve = gaussfcn(app,app.xaxis5,app.x5_0,app.sigmay5,app.Amplitudex5);
                plot(app.UIAxes9,app.xaxis5,xcurve);
                plot(app.UIAxes9,app.xaxis5,app.xarray5);         
                xline(app.UIAxes9,app.x5_0,'--r');
            end
            if strcmp(value, 'Data')
                cla(app.UIAxes9);        
                hold(app.UIAxes9, 'on');
                plot(app.UIAxes9,app.xaxis5,app.xarray5);     
                xline(app.UIAxes9,app.x5_0,'--r');
            end               
        end

        % Button pushed function: SaveFiguresButton
        function SaveFiguresButtonPushed(app, event)
            copyUIAxes(app.UIAxes);
            copyUIAxes(app.UIAxes2);
            copyUIAxes(app.UIAxes11);
            
            copyUIAxes(app.UIAxes3);
            copyUIAxes(app.UIAxes4);
            copyUIAxes(app.UIAxes12);
            
            copyUIAxes(app.UIAxes5);
            copyUIAxes(app.UIAxes6);
            copyUIAxes(app.UIAxes13);
            
            copyUIAxes(app.UIAxes7);
            copyUIAxes(app.UIAxes8);
            copyUIAxes(app.UIAxes14);
            
            copyUIAxes(app.UIAxes9);
            copyUIAxes(app.UIAxes10);
            copyUIAxes(app.UIAxes15);            
            
            print('-f1','-dpsc', 'test');
            print('-f2', '-dpsc', '-append', 'test');
            print('-f3','-dpsc', '-append', 'test');
            
            print('-f4','-dpsc', '-append','test');
            print('-f5', '-dpsc', '-append', 'test');
            print('-f6','-dpsc', '-append', 'test');
            
            print('-f7','-dpsc', '-append','test');
            print('-f8', '-dpsc', '-append', 'test');
            print('-f9','-dpsc', '-append', 'test');   
            
            print('-f10','-dpsc', '-append','test');
            print('-f11', '-dpsc', '-append', 'test');
            print('-f12','-dpsc', '-append', 'test');    
            
            print('-f13','-dpsc', '-append','test');
            print('-f14', '-dpsc', '-append', 'test');
            print('-f15','-dpsc', '-append', 'test');
            close all;            
            if isempty(app.PDFNameEditField_2.Value)
                app.PDFNameEditField_2.Value = 'allfigures';
            end
            
            filepath = join([app.PDFNameEditField_2.Value, '.pdf']);            
            
            ps2pdf('psfile', 'C:\Users\James_admin\Desktop\scripts for focus cam\test.ps', 'deletepsfile', 1, ...
                'pdffile', filepath, ...
                'gscommand', 'C:\Program Files\gs\gs9.54.0\bin\gswin64.exe');
        end

        % Button pushed function: SaveCamera0FiguresButton
        function SaveCamera0FiguresButtonPushed(app, event)
            copyUIAxes(app.UIAxes);
            copyUIAxes(app.UIAxes2);
            copyUIAxes(app.UIAxes11);
            print('-f1','-dpsc', 'test1');
            print('-f2', '-dpsc', '-append', 'test1');
            print('-f3','-dpsc', '-append', 'test1');
            close all;
            if isempty(app.PDFNameEditField.Value)
                app.PDFNameEditField.Value = 'camera1figs';
            end 
            filepath = join([app.PDFNameEditField.Value, '.pdf']);
            ps2pdf('psfile', 'C:\Users\James_admin\Desktop\scripts for focus cam\test1.ps', ...
                'pdffile', filepath,'deletepsfile', 1, 'gscommand', 'C:\Program Files\gs\gs9.54.0\bin\gswin64.exe');
        end

        % Button pushed function: SaveCamera1FiguresButton_2
        function SaveCamera1FiguresButton_2Pushed(app, event)
            copyUIAxes(app.UIAxes3);
            copyUIAxes(app.UIAxes4);
            copyUIAxes(app.UIAxes12);
            print('-f1','-dpsc', 'test2');
            print('-f2', '-dpsc', '-append', 'test2');
            print('-f3','-dpsc', '-append', 'test2'); 
            close all;
            if isempty(app.PDFNameEditField_3.Value)
                app.PDFNameEditField_3.Value = 'camera2figs';
            end  
            filepath = join([app.PDFNameEditField_3.Value, '.pdf']);            
            ps2pdf('psfile', 'C:\Users\James_admin\Desktop\scripts for focus cam\test2.ps', ...
                'pdffile',filepath, ...
                'deletepsfile', 1, 'gscommand', 'C:\Program Files\gs\gs9.54.0\bin\gswin64.exe');
        end

        % Button pushed function: SaveCamera2FiguresButton_2
        function SaveCamera2FiguresButton_2Pushed(app, event)
            copyUIAxes(app.UIAxes5);
            copyUIAxes(app.UIAxes6);
            copyUIAxes(app.UIAxes13);
            print('-f1','-dpsc', 'test3');
            print('-f2', '-dpsc', '-append', 'test3');
            print('-f3','-dpsc', '-append', 'test3'); 
            close all;
            if isempty(app.PDFNameEditField_4.Value)
                app.PDFNameEditField_4.Value = 'camera3figs';
            end    
            filepath = join([app.PDFNameEditField_4.Value, '.pdf']);            
            ps2pdf('psfile', 'C:\Users\James_admin\Desktop\scripts for focus cam\test3.ps', ...
                'pdffile',filepath, ...
                'deletepsfile', 1, 'gscommand', 'C:\Program Files\gs\gs9.54.0\bin\gswin64.exe');
        end

        % Button pushed function: SaveCamera3FiguresButton_2
        function SaveCamera3FiguresButton_2Pushed(app, event)
            copyUIAxes(app.UIAxes7);
            copyUIAxes(app.UIAxes8);
            copyUIAxes(app.UIAxes14);
            print('-f1','-dpsc', 'test4');
            print('-f2', '-dpsc', '-append', 'test4');
            print('-f3','-dpsc', '-append', 'test4'); 
            close all;
            if isempty(app.PDFNameEditField_5.Value)
                app.PDFNameEditField_5.Value = 'camera4figs';
            end 
            filepath = join([app.PDFNameEditField_5.Value, '.pdf']);            
            ps2pdf('psfile', 'C:\Users\James_admin\Desktop\scripts for focus cam\test4.ps', ...
                'pdffile',filepath, ...
                'deletepsfile', 1, 'gscommand', 'C:\Program Files\gs\gs9.54.0\bin\gswin64.exe');
        end

        % Button pushed function: SaveCamera4FiguresButton_2
        function SaveCamera4FiguresButton_2Pushed(app, event)
            copyUIAxes(app.UIAxes9);
            copyUIAxes(app.UIAxes10);
            copyUIAxes(app.UIAxes15);
            print('-f1','-dpsc', 'test5');
            print('-f2', '-dpsc', '-append', 'test5');
            print('-f3','-dpsc', '-append', 'test5'); 
            close all;
            if isempty(app.PDFNameEditField_6.Value)
                app.PDFNameEditField_6.Value = 'camera5figs';
            end  
            filepath = join([app.PDFNameEditField_6.Value, '.pdf']);            
            ps2pdf('psfile', 'C:\Users\James_admin\Desktop\scripts for focus cam\\test5.ps', ...
                'pdffile',filepath, ...
                'deletepsfile', 1, 'gscommand', 'C:\Program Files\gs\gs9.54.0\bin\gswin64.exe');
        end

        % Callback function
        function SaveParametersButtonPushed(app, event)
%             if isempty(app.Amplitudex1EditField.Value)
%                 app.Amplitudex1EditField.Value = sprintf('%s',app.Amplitudex1start);
%                 app.Amplitudex1 = app.Amplitudex1start;
%             else 
%                 app.Amplitudex1 = str2num(app.Amplitudex1EditField.Value);
%             end
%             if isempty(app.x1_0EditField_2.Value)
%                 app.x1_0EditField_2.Value = sprintf('%s',app.xc1);
%                 app.x1_0 = app.xc1;
%                 app.Label.Text = sprintf('%s',app.xc1);
%             else 
%                 app.x1_0 = str2num(app.x1_0EditField_2.Value);
%                 app.Label.Text = sprintf('%s',app.x1_0);
%             end
%             if isempty(app.D_x1EditField.Value)
%                 app.D_x1EditField.Value = sprintf('%s',app.D_x1start);
%                 app.D_x1 = app.D_x1start;
%             else 
%                 app.D_x1 = str2num(app.D_x1EditField.Value);
%             end 
%             
%             cla(app.UIAxes);
%             hold(app.UIAxes, 'on');
%             xcurve = sincfcn(app,app.xaxis1,app.x1_0,app.D_x1,app.Amplitudex1);
%             plot(app.UIAxes,app.xaxis1,xcurve);
%             plot(app.UIAxes,app.xaxis1,app.xarray1);
%             
%             app.Columns1DisplayOptionsDropDown.Value = 'Both';         
        end

        % Callback function
        function SaveParametersButton_2Pushed(app, event)
            if isempty(app.Amplitudey1EditField.Value)
                app.Amplitudey1EditField.Value = sprintf('%s',app.Amplitudey1start);
                app.Amplitudey1 = app.Amplitudey1start;
            else 
                app.Amplitudey1 = str2num(app.Amplitudey1EditField.Value);                
            end
            if isempty(app.y1_0EditField.Value)
                app.y1_0EditField.Value = sprintf('%s',app.yc1);
                app.y1_0 = app.yc1;
                app.Label2.Text = sprintf('%s',app.yc1);
            else 
                app.y1_0 = str2num(app.y1_0EditField.Value);
                app.Label2.Text = sprintf('%s',app.y1_0);
            end
            if isempty(app.Sigmay1EditField.Value)
                app.Sigmay1EditField.Value = sprintf('%s',app.sigmay1start);
                app.sigmay1 = app.sigmay1start;
            else 
                app.sigmay1 = str2num(app.Sigmay1EditField.Value);
            end 
            
%             cla(app.UIAxes2);
%             ycurve = gaussfcn(app,app.yaxis1,app.y1_0,app.sigmay1,app.Amplitudey1);
%             hold(app.UIAxes2,'on');
%             plot(app.UIAxes2,app.yaxis1,ycurve);
%             plot(app.UIAxes2,app.yaxis1,app.yarray1);
%             app.Rows1DisplayOptionsDropDown.Value = 'Both';               
        end

        % Callback function
        function SaveParametersButton_3Pushed(app, event)
            if isempty(app.Amplitudex2EditField.Value)
                app.Amplitudex2EditField.Value = sprintf('%s',app.Amplitudex2start);
                app.Amplitudex2 = app.Amplitudex2start;
            else 
                app.Amplitudex2 = str2num(app.Amplitudex2EditField.Value);
            end
            if isempty(app.x2_0EditField_2.Value)
                app.x2_0EditField_2.Value = sprintf('%s',app.xc2);
                app.x2_0 = app.xc2;
                app.Label3.Text = sprintf('%s',app.xc2);
            else 
                app.x2_0 = str2num(app.x2_0EditField_2.Value);
                app.Label3.Text = sprintf('%s',app.x2_0);                
            end
            if isempty(app.D_x2EditField.Value)
                app.D_x2EditField.Value = sprintf('%s',app.D_x2start);
                app.D_x2 = app.D_x2start;
            else 
                app.D_x2 = str2num(app.D_x2EditField.Value);
            end 
            cla(app.UIAxes3);
            xcurve = gaussfcn(app,app.xaxis2,app.x2_0,app.sigmay2,app.Amplitudex2);
            hold(app.UIAxes3, 'on');
            plot(app.UIAxes3,app.xaxis2,xcurve);
            plot(app.UIAxes3,app.xaxis2,app.xarray2);            

%             app.Columns2DisplayOptionsDropDown.Value = 'Both';
        end

        % Callback function
        function SaveParametersButton_4Pushed(app, event)
            if isempty(app.Amplitudey2EditField.Value)
                app.Amplitudey2EditField.Value = sprintf('%s',app.Amplitudey2start);
                app.Amplitudey2 = app.Amplitudey2start;
            else 
                app.Amplitudey2 = str2num(app.Amplitudey2EditField.Value);                
            end
            if isempty(app.y2_0EditField.Value)
                app.y2_0EditField.Value = sprintf('%s',app.yc2);
                app.y2_0 = app.yc2;
                app.Label4.Text = sprintf('%s',app.yc2);
            else 
                app.y2_0 = str2num(app.y2_0EditField.Value);
                app.Label4.Text = sprintf('%s',app.y2_0);
            end
            if isempty(app.Sigmay2EditField.Value)
                app.Sigmay2EditField.Value = sprintf('%s',app.sigmay2start);
                app.sigmay2 = app.sigmay2start;
            else 
                app.sigmay2 = str2num(app.Sigmay2EditField.Value);
            end 
            
            cla(app.UIAxes4);
            ycurve = gaussfcn(app,app.yaxis2,app.y2_0,app.sigmay2,app.Amplitudey2);
            hold(app.UIAxes4,'on');
            plot(app.UIAxes4,app.yaxis2,ycurve);
            plot(app.UIAxes4,app.yaxis2,app.yarray2);


%             app.Rows2DisplayOptionsDropDown.Value = 'Both';  
        end

        % Callback function
        function SaveParametersButton_5Pushed(app, event)
            if isempty(app.Amplitudex3EditField.Value)
                app.Amplitudex3EditField.Value = sprintf('%s',app.Amplitudex3start);
                app.Amplitudex3 = app.Amplitudex3start;
            else 
                app.Amplitudex3 = str2num(app.Amplitudex3EditField.Value);
            end
            if isempty(app.x3_0EditField_2.Value)
                app.x3_0EditField_2.Value = sprintf('%s',app.xc3);
                app.x3_0 = app.xc3;
                app.Label6_2.Text = sprintf('%s',app.xc3);
            else 
                app.x3_0 = str2num(app.x1_0EditField_3.Value);
                app.Label6_2.Text = sprintf('%s',app.x3_0);
            end
            if isempty(app.D_x3EditField.Value)
                app.D_x3EditField.Value = sprintf('%s',app.D_x3start);
                app.D_x3 = app.D_x3start;
            else 
                app.D_x3 = str2num(app.D_x3EditField.Value);
            end 
            
            cla(app.UIAxes5);
            hold(app.UIAxes5, 'on');
            xcurve = gaussfcn(app,app.xaxis3,app.x3_0,app.sigmay3,app.Amplitudex3);
            plot(app.UIAxes5,app.xaxis3,xcurve);
            plot(app.UIAxes5,app.xaxis3,app.xarray3);
            
%             app.Columns3DisplayOptionsDropDown.Value = 'Both';              
        end

        % Callback function
        function SaveParametersButton_6Pushed(app, event)
            if isempty(app.Amplitudey3EditField.Value)
                app.Amplitudey3EditField.Value = sprintf('%s',app.Amplitudey3start);
                app.Amplitudey3 = app.Amplitudey3start;
            else 
                app.Amplitudey3 = str2num(app.Amplitudey3EditField.Value);                
            end
            if isempty(app.y3_0EditField.Value)
                app.y3_0EditField.Value = sprintf('%s',app.yc3);
                app.y3_0 = app.yc3;
                app.Label6_2.Text = sprintf('%s',app.yc3);
            else 
                app.y3_0 = str2num(app.y3_0EditField.Value);
                app.Label6_2.Text = sprintf('%s',app.y3_0);
            end
            if isempty(app.Sigmay3EditField.Value)
                app.Sigmay3EditField.Value = sprintf('%s',app.sigmay3start);
                app.sigmay3 = app.sigmay3start;
            else 
                app.sigmay3 = str2num(app.Sigmay3EditField.Value);
            end 
            
            cla(app.UIAxes6);
            ycurve = gaussfcn(app,app.yaxis3,app.y3_0,app.sigmay3,app.Amplitudey3);
            hold(app.UIAxes6,'on');
            plot(app.UIAxes6,app.yaxis3,ycurve);
            plot(app.UIAxes6,app.yaxis3,app.yarray3);


            app.Rows3DisplayOptionsDropDown.Value = 'Both';           
        end

        % Callback function
        function SaveParametersButton_7Pushed(app, event)
            if isempty(app.Amplitudex4EditField.Value)
                app.Amplitudex4EditField.Value = sprintf('%s',app.Amplitudex4start);
                app.Amplitudex4 = app.Amplitudex4start;
            else 
                app.Amplitudex4 = str2num(app.Amplitudex4EditField.Value);
            end
            if isempty(app.x4_0EditField.Value)
                app.x4_0EditField.Value = sprintf('%s',app.xc4);
                app.x4_0 = app.xc4;
                app.Label.Text = sprintf('%s',app.xc4);
            else 
                app.x4_0 = str2num(app.x4_0EditField.Value);
                app.Label7.Text = sprintf('%s',app.x4_0);
            end
            if isempty(app.D_x4EditField.Value)
                app.D_x4EditField.Value = sprintf('%s',app.D_x4start);
                app.D_x4 = app.D_x4start;
            else 
                app.D_x4 = str2num(app.D_x4EditField.Value);
            end 
            
            cla(app.UIAxes7);
            hold(app.UIAxes7, 'on');
            xcurve = gaussfcn(app,app.xaxis4,app.x4_0,app.sigmay4,app.Amplitudex4);
            plot(app.UIAxes7,app.xaxis4,xcurve);
            plot(app.UIAxes7,app.xaxis4,app.xarray4);
            
%             app.Columns4DisplayOptionsDropDown.Value = 'Both';             
        end

        % Callback function
        function SaveParametersButton_8Pushed(app, event)
            if isempty(app.Amplitudey4EditField.Value)
                app.Amplitudey4EditField.Value = sprintf('%s',app.Amplitudey4start);
                app.Amplitudey4 = app.Amplitudey4start;
            else 
                app.Amplitudey4 = str2num(app.Amplitudey4EditField.Value);                
            end
            if isempty(app.y4_0EditField.Value)
                app.y4_0EditField.Value = sprintf('%s',app.yc4);
                app.y4_0 = app.yc4;
                app.Label2.Text = sprintf('%s',app.yc4);
            else 
                app.y4_0 = str2num(app.y4_0EditField.Value);
                app.Label8.Text = sprintf('%s',app.y4_0);
            end
            if isempty(app.Sigmay4EditField.Value)
                app.Sigmay4EditField.Value = sprintf('%s',app.sigmay4start);
                app.sigmay4 = app.sigmay4start;
            else 
                app.sigmay4 = str2num(app.Sigmay4EditField.Value);
            end 
            
            cla(app.UIAxes8);
            ycurve = gaussfcn(app,app.yaxis4,app.y4_0,app.sigmay4,app.Amplitudey4);
            hold(app.UIAxes8,'on');
            plot(app.UIAxes8,app.yaxis4,ycurve);
            plot(app.UIAxes8,app.yaxis4,app.yarray4);


%             app.Rows4DisplayOptionsDropDown.Value = 'Both';              
        end

        % Callback function
        function SaveParametersButton_9Pushed(app, event)
            if isempty(app.Amplitudex5EditField.Value)
                app.Amplitudex5EditField.Value = sprintf('%s',app.Amplitudex5start);
                app.Amplitudex5 = app.Amplitudex5start;
            else 
                app.Amplitudex5 = str2num(app.Amplitudex5EditField.Value);
            end
            if isempty(app.x5_0EditField.Value)
                app.x5_0EditField.Value = sprintf('%s',app.xc5);
                app.x5_0 = app.xc5;
                app.Label9.Text = sprintf('%s',app.xc5);
            else 
                app.x5_0 = str2num(app.x5_0EditField.Value);
                app.Label9.Text = sprintf('%s',app.x5_0);
            end
            if isempty(app.D_x5EditField.Value)
                app.D_x5EditField.Value = sprintf('%s',app.D_x5start);
                app.D_x5 = app.D_x5start;
            else 
                app.D_x5 = str2num(app.D_x5EditField.Value);
            end 
            
            cla(app.UIAxes9);
            hold(app.UIAxes9, 'on');
            xcurve = gaussfcn(app,app.xaxis5,app.x5_0,app.sigmay5,app.Amplitudex5);
            plot(app.UIAxes9,app.xaxis5,xcurve);
            plot(app.UIAxes9,app.xaxis5,app.xarray5);
            
%             app.Columns5DisplayOptionsDropDown.Value = 'Both';             
        end

        % Callback function
        function SaveParametersButton_10Pushed(app, event)
            if isempty(app.Amplitudey5EditField.Value)
                app.Amplitudey5EditField.Value = sprintf('%s',app.Amplitudey5start);
                app.Amplitudey5 = app.Amplitudey5start;
            else 
                app.Amplitudey5 = str2num(app.Amplitudey5EditField.Value);                
            end
            if isempty(app.y5_0EditField.Value)
                app.y5_0EditField.Value = sprintf('%s',app.yc5);
                app.y5_0 = app.yc5;
                app.Label10.Text = sprintf('%s',app.yc5);
            else 
                app.y5_0 = str2num(app.y5_0EditField.Value);
                app.Label10.Text = sprintf('%s',app.y5_0);
            end
            if isempty(app.Sigmay5EditField.Value)
                app.Sigmay5EditField.Value = sprintf('%s',app.sigmay5start);
                app.sigmay5 = app.sigmay5start;
            else 
                app.sigmay5 = str2num(app.Sigmay5EditField.Value);
            end 
            
            cla(app.UIAxes10);
            ycurve = gaussfcn(app,app.yaxis5,app.y5_0,app.sigmay5,app.Amplitudey5);
            hold(app.UIAxes10,'on');
            plot(app.UIAxes10,app.yaxis5,ycurve);
            plot(app.UIAxes10,app.yaxis5,app.yarray5);


%             app.Rows5DisplayOptionsDropDown.Value = 'Both';                
        end

        % Value changed function: DisplayDropDown_3
        function DisplayDropDown_3ValueChanged(app, event)
            value = app.DisplayDropDown_3.Value;
            if strcmp(value,'Full')
               cla(app.UIAxes11);                
               hold(app.UIAxes11,"on");
               imagesc(app.UIAxes11,app.frame1); 
               sz = size(app.frame1);
               app.UIAxes11.XLim = [0, sz(2)];
               app.UIAxes11.YLim = [0, sz(1)];
               %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
               scatter(app.UIAxes11, round(app.xc1), round(app.yc1), 'x','red');
               colorbar(app.UIAxes11);
               %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  
            end
            if strcmp(value,'AOI')
               cla(app.UIAxes11);                
                xbound = [app.xc1-100,app.xc1+100]; 
                ybound = [app.yc1-100,app.yc1+100];  
                    
                if app.yc1 < length(app.yarray1) || app.xc1 < length(app.xarray1)
                    if app.xc1 > 0 || app.yc1 > 0
                        hold(app.UIAxes11,"on");
                        imagesc(app.UIAxes11,app.frame1); 
                        app.UIAxes11.XLim = xbound;
                        app.UIAxes11.YLim = ybound;
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        scatter(app.UIAxes11, round(app.xc1), round(app.yc1), 'x','red');
                        colorbar(app.UIAxes11);
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
                    end
                    if app.xc1 <= 0 || app.yc1 <= 0
                       xbound = [app.xc1,app.xc1+100]; 
                       ybound = [app.yc1,app.yc1+100];
                       hold(app.UIAxes11,"on");
                       imagesc(app.UIAxes11,app.frame1); 
                       app.UIAxes11.XLim = xbound;
                       app.UIAxes11.YLim = ybound;
                       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                       scatter(app.UIAxes11, round(app.xc1), round(app.yc1), 'x','red');
                       colorbar(app.UIAxes11);
                       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
                    end
                end
                if app.yc1 >= length(app.yarray1) || app.xc1 >= length(app.xarray1)
                    if app.xc1 > 0 || app.yc1 > 0
                        xbound = [app.xc1-100,app.xc1+100]; 
                        ybound = [app.yc1-100,app.yc1+100];
                        hold(app.UIAxes11,"on");
                        imagesc(app.UIAxes11,app.frame1); 
                        app.UIAxes11.XLim = xbound;
                        app.UIAxes11.YLim = ybound;
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        scatter(app.UIAxes11, round(app.xc1), round(app.yc1), 'x','red');
                        colorbar(app.UIAxes11);
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    end
                    if app.yc1 <= 0 || app.xc1 <= 0
                       xbound = [app.xc1,app.xc1]; 
                       ybound = [app.yc1,app.yc1];
                       hold(app.UIAxes11,"on");
                       imagesc(app.UIAxes11,app.frame1); 
                       app.UIAxes11.XLim = xbound;
                       app.UIAxes11.YLim = ybound;
                       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                       scatter(app.UIAxes11, round(app.xc1), round(app.yc1), 'x','red');
                       colorbar(app.UIAxes11);
                       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
                    end                
                end
            end
        end

        % Callback function
        function SaveAreaInputButtonPushed(app, event)
            value = app.XAreaEditField.Value;
            value=int64(value);
            if value == 0
                py.xarraymaker.xfitmaker();
                app.paramsx1 = readtable('xvariables.txt', 'ReadVariableNames', false);
                app.paramsx1 = table2array(app.paramsx1);
                
                app.xarray1 = readtable('xarrayfile.txt','ReadVariableNames',false);
                app.xarray1 = table2array(app.xarray1);
                
                app.xaxis1 = readtable('xaxisfile.txt','ReadVariableNames',false);
                app.xaxis1 = table2array(app.xaxis1);
                
                app.x1_0 = app.paramsx1(1);
                app.D_x1 = app.paramsx1(2);
                app.Amplitudex1 = app.paramsx1(3);
                
                % plot the data
                cla(app.UIAxes);
                hold(app.UIAxes, 'on');
                xcurve = gaussfcn(app,app.xaxis1,app.x1_0,app.sigmay1,app.Amplitudex1);
                plot(app.UIAxes,app.xaxis1,xcurve);
                plot(app.UIAxes,app.xaxis1,app.xarray1);
                xline(app.UIAxes,app.x1_0,'--r');
                
                % Display the Parameters
                app.Amplitudex1EditField.Value = sprintf('%s',app.Amplitudex1);
                app.x1_0EditField_2.Value = sprintf('%s',app.x1_0);
                app.D_x1EditField.Value = sprintf('%s',app.D_x1);     
                
                app.Label.Text = sprintf('%.2f',app.x1_0);
%                 app.Rows1DisplayOptionsDropDown.Value = 'Both';
            else 
                % Create the fit for x
                py.xarraymaker.xfitmaker(value);
                app.paramsx1 = readtable('xvariables.txt', 'ReadVariableNames', false);
                app.paramsx1 = table2array(app.paramsx1);
                
                app.xarray1 = readtable('xarrayfile.txt','ReadVariableNames',false);
                app.xarray1 = table2array(app.xarray1);
                
                app.xaxis1 = readtable('xaxisfile.txt','ReadVariableNames',false);
                app.xaxis1 = table2array(app.xaxis1);
                
                app.x1_0 = app.paramsx1(1);
                app.D_x1 = app.paramsx1(2);
                app.Amplitudex1 = app.paramsx1(3);
                
                % plot the data
                cla(app.UIAxes);
                hold(app.UIAxes, 'on');
                xcurve = gaussfcn(app,app.xaxis1,app.x1_0,app.sigmay1,app.Amplitudex1);
                plot(app.UIAxes,app.xaxis1,xcurve);
                plot(app.UIAxes,app.xaxis1,app.xarray1);
                xline(app.UIAxes,app.x1_0,'--r');                
                
                % Display the Parameters
                app.Amplitudex1EditField.Value = sprintf('%s',app.Amplitudex1);
                app.x1_0EditField_2.Value = sprintf('%s',app.x1_0);
                app.D_x1EditField.Value = sprintf('%s',app.D_x1);     
                
                app.Label.Text = sprintf('%.2f',app.x1_0);
%                 app.Rows1DisplayOptionsDropDown.Value = 'Both';
            end
        end

        % Callback function
        function SaveAreaInputButton_2Pushed(app, event)
            value = app.YAreaEditField.Value;
            value = int64(value);
            if value == 0
                % Create the fit for y
                py.arraysmaker.yfitmaker();
                app.paramsy1 = readtable('variables.txt', 'ReadVariableNames', false);
                app.paramsy1 = table2array(app.paramsy1);
                
                app.yarray1 = readtable('yarrayfile.txt','ReadVariableNames',false);
                app.yarray1 = table2array(app.yarray1);
                
                app.yaxis1 = readtable('yaxisfile.txt','ReadVariableNames',false);
                app.yaxis1 = table2array(app.yaxis1);
                
                app.y1_0 = app.paramsy1(1);
                app.sigmay1 = app.paramsy1(2);
                app.Amplitudey1 = app.paramsy1(3);
                
                % plot the data          
                ycurve = gaussfcn(app,app.yaxis1,app.y1_0,app.sigmay1,app.Amplitudey1);
                cla(app.UIAxes2);
                hold(app.UIAxes2,'on');
                plot(app.UIAxes2,app.yaxis1,ycurve);
                plot(app.UIAxes2,app.yaxis1,app.yarray1);
                xline(app.UIAxes2,app.y1_0,'--r');
                
                % Display the Parameters         
                app.Amplitudey1EditField.Value = sprintf('%s',app.Amplitudey1);
                app.y1_0EditField.Value = sprintf('%s',app.y1_0);
                app.Sigmay1EditField.Value = sprintf('%s',app.sigmay1);
                
                app.Label2.Text = sprintf('%.2f',app.y1_0);
%                 app.Columns1DisplayOptionsDropDown.Value = 'Both';
            else 
                % Create the fit for y
                py.arraysmaker.yfitmaker(value);
                app.paramsy1 = readtable('variables.txt', 'ReadVariableNames', false);
                app.paramsy1 = table2array(app.paramsy1);
                
                app.yarray1 = readtable('yarrayfile.txt','ReadVariableNames',false);
                app.yarray1 = table2array(app.yarray1);
                
                app.yaxis1 = readtable('yaxisfile.txt','ReadVariableNames',false);
                app.yaxis1 = table2array(app.yaxis1);
                
                app.y1_0 = app.paramsy1(1);
                app.sigmay1 = app.paramsy1(2);
                app.Amplitudey1 = app.paramsy1(3);
                
                % plot the data          
                ycurve = gaussfcn(app,app.yaxis1,app.y1_0,app.sigmay1,app.Amplitudey1);
                cla(app.UIAxes2);
                hold(app.UIAxes2,'on');
                plot(app.UIAxes2,app.yaxis1,ycurve);
                plot(app.UIAxes2,app.yaxis1,app.yarray1);
                xline(app.UIAxes2,app.y1_0,'--r');
                
                % Display the Parameters         
                app.Amplitudey1EditField.Value = sprintf('%s',app.Amplitudey1);
                app.y1_0EditField.Value = sprintf('%s',app.y1_0);
                app.Sigmay1EditField.Value = sprintf('%s',app.sigmay1);
                
                app.Label2.Text = sprintf('%.2f',app.y1_0);
            end
        end

        % Callback function
        function SaveAreaInputButton_3Pushed(app, event)
            value = app.XAreaEditField_6.Value;
            value=int64(value);
            if value == 0
                % Create the fit for x
                py.xarraymaker.xfitmaker2();
                app.paramsx2 = readtable('xvariables.txt', 'ReadVariableNames', false);
                app.paramsx2 = table2array(app.paramsx2);
                
                app.xarray2 = readtable('xarrayfile.txt','ReadVariableNames',false);
                app.xarray2 = table2array(app.xarray2);
                
                app.xaxis2 = readtable('xaxisfile.txt','ReadVariableNames',false);
                app.xaxis2 = table2array(app.xaxis2);
                
                app.x2_0 = app.paramsx2(1);
                app.D_x2 = app.paramsx2(2);
                app.Amplitudex2 = app.paramsx2(3);
                
                % plot the data
                cla(app.UIAxes3);
                hold(app.UIAxes3, 'on');
                xcurve = gaussfcn(app,app.xaxis2,app.x2_0,app.sigmay2,app.Amplitudex2);
                plot(app.UIAxes3,app.xaxis2,xcurve);
                plot(app.UIAxes3,app.xaxis2,app.xarray2);
                xline(app.UIAxes3,app.x2_0,'--r');
                
                % Display the Parameters
                app.Amplitudex2EditField.Value = sprintf('%s',app.Amplitudex2);
                app.x2_0EditField_2.Value = sprintf('%s',app.x2_0);
                app.D_x2EditField.Value = sprintf('%s',app.D_x2);     
                
                app.Label3.Text = sprintf('%.2f',app.x2_0);
            else 
                % Create the fit for x
                py.xarraymaker.xfitmaker2(value);
                app.paramsx2 = readtable('xvariables.txt', 'ReadVariableNames', false);
                app.paramsx2 = table2array(app.paramsx2);
                
                app.xarray2 = readtable('xarrayfile.txt','ReadVariableNames',false);
                app.xarray2 = table2array(app.xarray2);
                
                app.xaxis2 = readtable('xaxisfile.txt','ReadVariableNames',false);
                app.xaxis2 = table2array(app.xaxis2);
                
                app.x2_0 = app.paramsx2(1);
                app.D_x2 = app.paramsx2(2);
                app.Amplitudex2 = app.paramsx2(3);
                
                % plot the data
                cla(app.UIAxes3);
                hold(app.UIAxes3, 'on');
                xcurve = gaussfcn(app,app.xaxis2,app.x2_0,app.sigmay2,app.Amplitudex2);
                plot(app.UIAxes3,app.xaxis2,xcurve);
                plot(app.UIAxes3,app.xaxis2,app.xarray2);
                xline(app.UIAxes3,app.x2_0,'--r');
                
                % Display the Parameters
                app.Amplitudex2EditField.Value = sprintf('%s',app.Amplitudex2);
                app.x2_0EditField_2.Value = sprintf('%s',app.x2_0);
                app.D_x2EditField.Value = sprintf('%s',app.D_x2);     
                
                app.Label3.Text = sprintf('%.2f',app.x2_0);
            end
        end

        % Callback function
        function SaveAreaInputButton_4Pushed(app, event)
            value = app.YAreaEditField_6.Value;
            value = int64(value);            
            if value == 0    
                py.arraysmaker.yfitmaker2();
                
                % Create the fit for y
                app.paramsy2 = readtable('variables.txt', 'ReadVariableNames', false);
                app.paramsy2 = table2array(app.paramsy2);
                
                app.yarray2 = readtable('yarrayfile.txt','ReadVariableNames',false);
                app.yarray2 = table2array(app.yarray2);
                
                app.yaxis2 = readtable('yaxisfile.txt','ReadVariableNames',false);
                app.yaxis2 = table2array(app.yaxis2);
                
                app.y2_0 = app.paramsy2(1);
                app.sigmay2 = app.paramsy2(2);
                app.Amplitudey2 = app.paramsy2(3);
                
                % plot the data
                cla(app.UIAxes4);
                ycurve = gaussfcn(app,app.yaxis2,app.y2_0,app.sigmay2,app.Amplitudey2);
                hold(app.UIAxes4,'on');
                plot(app.UIAxes4,app.yaxis2,ycurve);
                plot(app.UIAxes4,app.yaxis2,app.yarray2);
                xline(app.UIAxes4,app.y2_0,'--r');
                
                % Display the parameters
                app.Amplitudey2EditField.Value = sprintf('%s',app.Amplitudey2);
                app.y2_0EditField.Value = sprintf('%s',app.y2_0);
                app.Sigmay2EditField.Value = sprintf('%s',app.sigmay2);
                
                app.Label4.Text = sprintf('%.2f',app.y2_0);
                
%                 app.Rows2DisplayOptionsDropDown.Value = 'Both';
            else 
                py.arraysmaker.yfitmaker2(value);
                
                % Create the fit for y
                app.paramsy2 = readtable('variables.txt', 'ReadVariableNames', false);
                app.paramsy2 = table2array(app.paramsy2);
                
                app.yarray2 = readtable('yarrayfile.txt','ReadVariableNames',false);
                app.yarray2 = table2array(app.yarray2);
                
                app.yaxis2 = readtable('yaxisfile.txt','ReadVariableNames',false);
                app.yaxis2 = table2array(app.yaxis2);
                
                app.y2_0 = app.paramsy2(1);
                app.sigmay2 = app.paramsy2(2);
                app.Amplitudey2 = app.paramsy2(3);
                
                % plot the data
                cla(app.UIAxes4);
                ycurve = gaussfcn(app,app.yaxis2,app.y2_0,app.sigmay2,app.Amplitudey2);
                hold(app.UIAxes4,'on');
                plot(app.UIAxes4,app.yaxis2,ycurve);
                plot(app.UIAxes4,app.yaxis2,app.yarray2);
                xline(app.UIAxes4,app.y2_0,'--r');
                
                % Display the parameters
                app.Amplitudey2EditField.Value = sprintf('%s',app.Amplitudey2);
                app.y2_0EditField.Value = sprintf('%s',app.y2_0);
                app.Sigmay2EditField.Value = sprintf('%s',app.sigmay2);
                
                app.Label4.Text = sprintf('%.2f',app.y2_0);
                
%                 app.Rows2DisplayOptionsDropDown.Value = 'Both';
            end
        end

        % Callback function
        function SaveAreaInputButton_5Pushed(app, event)
            value = app.XAreaEditField_7.Value;
            value = int64(value);
            if value == 0
                py.xarraymaker.xfitmaker3();
                app.paramsx3 = readtable('xvariables.txt', 'ReadVariableNames', false);
                app.paramsx3 = table2array(app.paramsx3);
                
                app.xarray3 = readtable('xarrayfile.txt','ReadVariableNames',false);
                app.xarray3 = table2array(app.xarray3);
                
                app.xaxis3 = readtable('xaxisfile.txt','ReadVariableNames',false);
                app.xaxis3 = table2array(app.xaxis3);
                
                app.x3_0 = app.paramsx3(1);
                app.D_x3 = app.paramsx3(2);
                app.Amplitudex3 = app.paramsx3(3);
                
                % plot the data
                cla(app.UIAxes5);
                hold(app.UIAxes5, 'on');
                xcurve = gaussfcn(app,app.xaxis3,app.x3_0,app.sigmay3,app.Amplitudex3);
                plot(app.UIAxes5,app.xaxis3,xcurve);
                plot(app.UIAxes5,app.xaxis3,app.xarray3);
                xline(app.UIAxes5,app.x3_0,'--r');
                
                % Display the Parameters
                app.Amplitudex3EditField.Value = sprintf('%s',app.Amplitudex3);
                app.x3_0EditField_2.Value = sprintf('%s',app.x3_0);
                app.D_x3EditField.Value = sprintf('%s',app.D_x3);     
                
                app.Amplitudey3EditField.Value = sprintf('%s',app.Amplitudey3);
                app.y3_0EditField.Value = sprintf('%s',app.y3_0);
                app.Sigmay3EditField.Value = sprintf('%s',app.sigmay3);
                
                app.Label5_2.Text = sprintf('%.2f',app.x3_0);
%                 app.Columns3DisplayOptionsDropDown.Value = 'Both';
            else 
                py.xarraymaker.xfitmaker3(value);
                app.paramsx3 = readtable('xvariables.txt', 'ReadVariableNames', false);
                app.paramsx3 = table2array(app.paramsx3);
                
                app.xarray3 = readtable('xarrayfile.txt','ReadVariableNames',false);
                app.xarray3 = table2array(app.xarray3);
                
                app.xaxis3 = readtable('xaxisfile.txt','ReadVariableNames',false);
                app.xaxis3 = table2array(app.xaxis3);
                
                app.x3_0 = app.paramsx3(1);
                app.D_x3 = app.paramsx3(2);
                app.Amplitudex3 = app.paramsx3(3);
                
                % plot the data
                cla(app.UIAxes5);
                hold(app.UIAxes5, 'on');
                xcurve = gaussfcn(app,app.xaxis3,app.x3_0,app.sigmay3,app.Amplitudex3);
                plot(app.UIAxes5,app.xaxis3,xcurve);
                plot(app.UIAxes5,app.xaxis3,app.xarray3);                    
                xline(app.UIAxes5,app.x3_0,'--r');
                
                % Display the Parameters
                app.Amplitudex3EditField.Value = sprintf('%s',app.Amplitudex3);
                app.x3_0EditField_2.Value = sprintf('%s',app.x3_0);
                app.D_x3EditField.Value = sprintf('%s',app.D_x3);     
                
                app.Amplitudey3EditField.Value = sprintf('%s',app.Amplitudey3);
                app.y3_0EditField.Value = sprintf('%s',app.y3_0);
                app.Sigmay3EditField.Value = sprintf('%s',app.sigmay3);
                
                app.Label5_2.Text = sprintf('%.2f',app.x3_0);
%                 app.Columns3DisplayOptionsDropDown.Value = 'Both';  
            end 
        end

        % Callback function
        function SaveAreaInputButton_6Pushed(app, event)
            value = app.YAreaEditField_7.Value;
            value = int64(value);
            if value == 0
                py.arraysmaker.yfitmaker3();
                
                % Create the fit for y
                app.paramsy3 = readtable('variables.txt', 'ReadVariableNames', false);
                app.paramsy3 = table2array(app.paramsy3);
                
                app.yarray3 = readtable('yarrayfile.txt','ReadVariableNames',false);
                app.yarray3 = table2array(app.yarray3);
                
                app.yaxis3 = readtable('yaxisfile.txt','ReadVariableNames',false);
                app.yaxis3 = table2array(app.yaxis3);
                
                app.y3_0 = app.paramsy3(1);
                app.sigmay3 = app.paramsy3(2);
                app.Amplitudey3 = app.paramsy3(3);
                
                % plot the data
                cla(app.UIAxes6);
                ycurve = gaussfcn(app,app.yaxis3,app.y3_0,app.sigmay3,app.Amplitudey3);
                hold(app.UIAxes6,'on');
                plot(app.UIAxes6,app.yaxis3,ycurve);
                plot(app.UIAxes6,app.yaxis3,app.yarray3);
                xline(app.UIAxes6,app.y3_0,'--r');
                
                % Display the Parameters                
                app.Amplitudey3EditField.Value = sprintf('%s',app.Amplitudey3);
                app.y3_0EditField.Value = sprintf('%s',app.y3_0);
                app.Sigmay3EditField.Value = sprintf('%s',app.sigmay3);
                
                app.Label6_2.Text = sprintf('%.2f',app.y3_0);  
%                 app.Rows3DisplayOptionsDropDown.Value = 'Both';  
            else 
                py.arraysmaker.yfitmaker3(value);
                
                % Create the fit for y
                app.paramsy3 = readtable('variables.txt', 'ReadVariableNames', false);
                app.paramsy3 = table2array(app.paramsy3);
                
                app.yarray3 = readtable('yarrayfile.txt','ReadVariableNames',false);
                app.yarray3 = table2array(app.yarray3);
                
                app.yaxis3 = readtable('yaxisfile.txt','ReadVariableNames',false);
                app.yaxis3 = table2array(app.yaxis3);
                
                app.y3_0 = app.paramsy3(1);
                app.sigmay3 = app.paramsy3(2);
                app.Amplitudey3 = app.paramsy3(3);
                
                % plot the data
                cla(app.UIAxes6);
                ycurve = gaussfcn(app,app.yaxis3,app.y3_0,app.sigmay3,app.Amplitudey3);
                hold(app.UIAxes6,'on');
                plot(app.UIAxes6,app.yaxis3,ycurve);
                plot(app.UIAxes6,app.yaxis3,app.yarray3);                    
                xline(app.UIAxes6,app.y3_0,'--r');
                
                % Display the Parameters                
                app.Amplitudey3EditField.Value = sprintf('%s',app.Amplitudey3);
                app.y3_0EditField.Value = sprintf('%s',app.y3_0);
                app.Sigmay3EditField.Value = sprintf('%s',app.sigmay3);
                
                app.Label6_2.Text = sprintf('%.2f',app.y3_0);  
%                 app.Rows3DisplayOptionsDropDown.Value = 'Both'; 
            end 
        end

        % Callback function
        function SaveAreaInputButton_7Pushed(app, event)
            value = app.XAreaEditField_8.Value;
            value = int64(value);
            if value == 0
                % Create the fit for x
                py.xarraymaker.xfitmaker4();
                app.paramsx4 = readtable('xvariables.txt', 'ReadVariableNames', false);
                app.paramsx4 = table2array(app.paramsx4);
                
                app.xarray4 = readtable('xarrayfile.txt','ReadVariableNames',false);
                app.xarray4 = table2array(app.xarray4);
                
                app.xaxis4 = readtable('xaxisfile.txt','ReadVariableNames',false);
                app.xaxis4 = table2array(app.xaxis4);
                
                app.x4_0 = app.paramsx4(1);
                app.D_x4 = app.paramsx4(2);
                app.Amplitudex4 = app.paramsx4(3);
                
                % plot the data
                cla(app.UIAxes7);
                hold(app.UIAxes7, 'on');
                xcurve = gaussfcn(app,app.xaxis4,app.x4_0,app.sigmay4,app.Amplitudex4);
                plot(app.UIAxes7,app.xaxis4,xcurve);
                plot(app.UIAxes7,app.xaxis4,app.xarray4);
                xline(app.UIAxes7,app.x4_0,'--r');
              
                % Display the Parameters
                app.Amplitudex4EditField.Value = sprintf('%s',app.Amplitudex4);
                app.x4_0EditField.Value = sprintf('%s',app.x4_0);
                app.D_x4EditField.Value = sprintf('%s',app.D_x4);     
                
                app.Label7.Text = sprintf('%.2f',app.x4_0);
%                 app.Columns4DisplayOptionsDropDown.Value = 'Both';  
            else 
                % Create the fit for x
                py.xarraymaker.xfitmaker4(value);
                app.paramsx4 = readtable('xvariables.txt', 'ReadVariableNames', false);
                app.paramsx4 = table2array(app.paramsx4);
                
                app.xarray4 = readtable('xarrayfile.txt','ReadVariableNames',false);
                app.xarray4 = table2array(app.xarray4);
                
                app.xaxis4 = readtable('xaxisfile.txt','ReadVariableNames',false);
                app.xaxis4 = table2array(app.xaxis4);
                
                app.x4_0 = app.paramsx4(1);
                app.D_x4 = app.paramsx4(2);
                app.Amplitudex4 = app.paramsx4(3);
                
                % plot the data
                cla(app.UIAxes7);
                hold(app.UIAxes7, 'on');
                xcurve = gaussfcn(app,app.xaxis4,app.x4_0,app.sigmay4,app.Amplitudex4);
                plot(app.UIAxes7,app.xaxis4,xcurve);
                plot(app.UIAxes7,app.xaxis4,app.xarray4);              
                xline(app.UIAxes7,app.x4_0,'--r');
                
                % Display the Parameters
                app.Amplitudex4EditField.Value = sprintf('%s',app.Amplitudex4);
                app.x4_0EditField.Value = sprintf('%s',app.x4_0);
                app.D_x4EditField.Value = sprintf('%s',app.D_x4);     
                
                app.Label7.Text = sprintf('%.2f',app.x4_0);
%                 app.Columns4DisplayOptionsDropDown.Value = 'Both';
            end     
        end

        % Callback function
        function SaveAreaInputButton_8Pushed(app, event)
            value = app.YAreaEditField_8.Value;
            value = int64(value);
            if value == 0
                py.arraysmaker.yfitmaker4();
                
                % Create the fit for y
                app.paramsy4 = readtable('variables.txt', 'ReadVariableNames', false);
                app.paramsy4 = table2array(app.paramsy4);
                
                app.yarray4 = readtable('yarrayfile.txt','ReadVariableNames',false);
                app.yarray4 = table2array(app.yarray4);
                
                app.yaxis4 = readtable('yaxisfile.txt','ReadVariableNames',false);
                app.yaxis4 = table2array(app.yaxis4);
                
                app.y4_0 = app.paramsy4(1);
                app.sigmay4 = app.paramsy4(2);
                app.Amplitudey4 = app.paramsy4(3);
                
                % plot the data
                cla(app.UIAxes8);         
                ycurve = gaussfcn(app,app.yaxis4,app.y4_0,app.sigmay4,app.Amplitudey4);
                hold(app.UIAxes8,'on');
                plot(app.UIAxes8,app.yaxis4,ycurve);
                plot(app.UIAxes8,app.yaxis4,app.yarray4); 
                xline(app.UIAxes8,app.y4_0,'--r');
              
                % Display the Parameters
                app.Amplitudey4EditField.Value = sprintf('%s',app.Amplitudey4);
                app.y4_0EditField.Value = sprintf('%s',app.y4_0);
                app.Sigmay4EditField.Value = sprintf('%s',app.sigmay4);
    
                app.Label8.Text = sprintf('%.2f',app.y4_0);
%                 app.Rows4DisplayOptionsDropDown.Value = 'Both';
            else 
                py.arraysmaker.yfitmaker4(value);
                
                % Create the fit for y
                app.paramsy4 = readtable('variables.txt', 'ReadVariableNames', false);
                app.paramsy4 = table2array(app.paramsy4);
                
                app.yarray4 = readtable('yarrayfile.txt','ReadVariableNames',false);
                app.yarray4 = table2array(app.yarray4);
                
                app.yaxis4 = readtable('yaxisfile.txt','ReadVariableNames',false);
                app.yaxis4 = table2array(app.yaxis4);
                
                app.y4_0 = app.paramsy4(1);
                app.sigmay4 = app.paramsy4(2);
                app.Amplitudey4 = app.paramsy4(3);
                
                % plot the data              
                ycurve = gaussfcn(app,app.yaxis4,app.y4_0,app.sigmay4,app.Amplitudey4);
                hold(app.UIAxes8,'on');
                plot(app.UIAxes8,app.yaxis4,ycurve);
                plot(app.UIAxes8,app.yaxis4,app.yarray4);                    
                xline(app.UIAxes8,app.y4_0,'--r');
                
                % Display the Parameters
                app.Amplitudey4EditField.Value = sprintf('%s',app.Amplitudey4);
                app.y4_0EditField.Value = sprintf('%s',app.y4_0);
                app.Sigmay4EditField.Value = sprintf('%s',app.sigmay4);
    
                app.Label8.Text = sprintf('%.2f',app.y4_0);
%                 app.Rows4DisplayOptionsDropDown.Value = 'Both'; 
            end 
        end

        % Callback function
        function SaveAreaInputButton_9Pushed(app, event)
            value = app.XAreaEditField_9.value;
            value = int64(value);
            if value == 0
                % Create the fit for x
                py.xarraymaker.xfitmaker5();
                app.paramsx5 = readtable('xvariables.txt', 'ReadVariableNames', false);
                app.paramsx5 = table2array(app.paramsx5);
                
                app.xarray5 = readtable('xarrayfile.txt','ReadVariableNames',false);
                app.xarray5 = table2array(app.xarray5);
                
                app.xaxis5 = readtable('xaxisfile.txt','ReadVariableNames',false);
                app.xaxis5 = table2array(app.xaxis5);
                
                app.x5_0 = app.paramsx5(1);
                app.D_x5 = app.paramsx5(2);
                app.Amplitudex5 = app.paramsx5(3);
                
                % plot the data
                imagesc(app.UIAxes15, app.frame5); 
                hold(app.UIAxes9, 'on');
                xcurve = gaussfcn(app,app.xaxis5,app.x5_0,app.sigmay5,app.Amplitudex5);
                plot(app.UIAxes9,app.xaxis5,xcurve);
                plot(app.UIAxes9,app.xaxis5,app.xarray5);
                xline(app.UIAxes9,app.x5_0,'--r');
                
                % Display the Parameters
                app.Amplitudex5EditField.Value = sprintf('%s',app.Amplitudex5);
                app.x5_0EditField.Value = sprintf('%s',app.x5_0);
                app.D_x5EditField.Value = sprintf('%s',app.D_x5);     
                
                app.Label9.Text = sprintf('%.2f',app.x5_0); 
%                 app.Columns5DisplayOptionsDropDown.Value ='Both';    
            else 
                % Create the fit for x
                py.xarraymaker.xfitmaker5(value);
                app.paramsx5 = readtable('xvariables.txt', 'ReadVariableNames', false);
                app.paramsx5 = table2array(app.paramsx5);
                
                app.xarray5 = readtable('xarrayfile.txt','ReadVariableNames',false);
                app.xarray5 = table2array(app.xarray5);
                
                app.xaxis5 = readtable('xaxisfile.txt','ReadVariableNames',false);
                app.xaxis5 = table2array(app.xaxis5);
                
                app.x5_0 = app.paramsx5(1);
                app.D_x5 = app.paramsx5(2);
                app.Amplitudex5 = app.paramsx5(3);
                
                % plot the data
                imagesc(app.UIAxes15, app.frame5); 
                hold(app.UIAxes9, 'on');
                xcurve = gaussfcn(app,app.xaxis5,app.x5_0,app.sigmay5,app.Amplitudex5);
                plot(app.UIAxes9,app.xaxis5,xcurve);
                plot(app.UIAxes9,app.xaxis5,app.xarray5);
                xline(app.UIAxes9,app.x5_0,'--r');               
                
                % Display the Parameters
                app.Amplitudex5EditField.Value = sprintf('%s',app.Amplitudex5);
                app.x5_0EditField.Value = sprintf('%s',app.x5_0);
                app.D_x5EditField.Value = sprintf('%s',app.D_x5);     
                
                app.Label9.Text = sprintf('%.2f',app.x5_0); 
%                 app.Columns5DisplayOptionsDropDown.Value ='Both';
            end 
        end

        % Callback function
        function SaveAreaInputButton_10Pushed(app, event)
            % Create the fit for x
            value = app.YAreaEditField_9.Value;
            value = int64(value);
            if value == 0
                py.arraysmaker.yfitmaker5();
                
                % Create the fit for y
                app.paramsy5 = readtable('variables.txt', 'ReadVariableNames', false);
                app.paramsy5 = table2array(app.paramsy5);
                
                app.yarray5 = readtable('yarrayfile.txt','ReadVariableNames',false);
                app.yarray5 = table2array(app.yarray5);
                
                app.yaxis5 = readtable('yaxisfile.txt','ReadVariableNames',false);
                app.yaxis5 = table2array(app.yaxis5);
                
                app.y5_0 = app.paramsy5(1);
                app.sigmay5 = app.paramsy5(2);
                app.Amplitudey5 = app.paramsy5(3);
                
                % plot the data
                cla(app.UIAxes10);
                hold(app.UIAxes10, 'on');                
                ycurve = gaussfcn(app,app.yaxis5,app.y5_0,app.sigmay5,app.Amplitudey5);
                plot(app.UIAxes10,app.yaxis5,ycurve);
                plot(app.UIAxes10,app.yaxis5,app.yarray5);
                xline(app.UIAxes10,app.y5_0,'--r');
              
                % Display the Parameters
                app.Amplitudey5EditField.Value = sprintf('%s',app.Amplitudey5);
                app.y5_0EditField.Value = sprintf('%s',app.y5_0);
                app.Sigmay5EditField.Value = sprintf('%s',app.sigmay5);
    
                app.Label10.Text = sprintf('%.2f',app.y5_0);
%                 app.Rows5DisplayOptionsDropDown.Value = 'Both';
            else 
                py.arraysmaker.yfitmaker5(value);
                
                % Create the fit for y
                app.paramsy5 = readtable('variables.txt', 'ReadVariableNames', false);
                app.paramsy5 = table2array(app.paramsy5);
                
                app.yarray5 = readtable('yarrayfile.txt','ReadVariableNames',false);
                app.yarray5 = table2array(app.yarray5);
                
                app.yaxis5 = readtable('yaxisfile.txt','ReadVariableNames',false);
                app.yaxis5 = table2array(app.yaxis5);
                
                app.y5_0 = app.paramsy5(1);
                app.sigmay5 = app.paramsy5(2);
                app.Amplitudey5 = app.paramsy5(3);
                
                % plot the data
                cla(app.UIAxes10);
                hold(app.UIAxes10, 'on');                
                ycurve = gaussfcn(app,app.yaxis5,app.y5_0,app.sigmay5,app.Amplitudey5);
                plot(app.UIAxes10,app.yaxis5,ycurve);
                plot(app.UIAxes10,app.yaxis5,app.yarray5);                    
                xline(app.UIAxes10,app.y5_0,'--r');
                
                % Display the Parameters
                app.Amplitudey5EditField.Value = sprintf('%s',app.Amplitudey5);
                app.y5_0EditField.Value = sprintf('%s',app.y5_0);
                app.Sigmay5EditField.Value = sprintf('%s',app.sigmay5);
    
                app.Label10.Text = sprintf('%.2f',app.y5_0);
%                 app.Rows5DisplayOptionsDropDown.Value = 'Both';
            end    
        end

        % Button pushed function: SaveExposureTimeButton
        function SaveExposureTimeButtonPushed(app, event)
            exposure = app.ExposureTimeusEditField_5.Value;
            app.exposure0 = exposure;
        end

        % Button pushed function: SaveExposureTimeButton_2
        function SaveExposureTimeButton_2Pushed(app, event)
            if strcmp(app.camera.SerialNumber, 'S1100936') == 1
                app.exposure1 = app.ExposureTimeusEditField_4.Value;
            end
        end

        % Button pushed function: SaveExposureTimeButton_3
        function SaveExposureTimeButton_3Pushed(app, event)
            if strcmp(app.camera.SerialNumber, 'S1254378') == 1
                app.exposure2 = app.ExposureTimeusEditField_3.Value;
            end
        end

        % Button pushed function: SaveExposureTimeButton_4
        function SaveExposureTimeButton_4Pushed(app, event)
            if strcmp(app.camera.SerialNumber, 'S1255183') == 1
                app.exposure3 = app.ExposureTimeusEditField_2.Value;
            end
        end

        % Button pushed function: SaveExposureTimeButton_5
        function SaveExposureTimeButton_5Pushed(app, event)
            if strcmp(app.camera.SerialNumber, 'S1255193') == 1
                app.exposure4 = app.ExposureTimeusEditField.Value;
            end
        end

        % Value changed function: DisplayDropDown_2
        function DisplayDropDown_2ValueChanged(app, event)
            value = app.DisplayDropDown_2.Value;
            if strcmp(value,'Full')
               cla(app.UIAxes12);                
               hold(app.UIAxes12,"on");
               imagesc(app.UIAxes12,app.frame2); 
               sz = size(app.frame2);
               app.UIAxes12.XLim = [0, sz(2)];
               app.UIAxes12.YLim = [0, sz(1)];
               %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
               scatter(app.UIAxes12, round(app.xc2), round(app.yc2), 'x','red');
               colorbar(app.UIAxes12);
               %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  
            end
            if strcmp(value,'AOI')
               cla(app.UIAxes12);                
                xbound = [app.xc2-150,app.xc2+150]; 
                ybound = [app.yc2-150,app.yc2+150];  
                    
                if app.yc2 < length(app.yarray2) || app.xc2 < length(app.xarray2)
                    if app.xc2 > 0 || app.yc2 > 0
                        hold(app.UIAxes12,"on");
                        imagesc(app.UIAxes12,app.frame2); 
                        app.UIAxes12.XLim = xbound;
                        app.UIAxes12.YLim = ybound;
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        scatter(app.UIAxes12, round(app.xc2), round(app.yc2), 'x','red');
                        colorbar(app.UIAxes12);
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
                    end
                    if app.xc2 <= 0 || app.yc2 <= 0
                       xbound = [app.xc2,app.xc2+150]; 
                       ybound = [app.yc2,app.yc2+150];
                       hold(app.UIAxes12,"on");
                       imagesc(app.UIAxes12,app.frame2); 
                       app.UIAxes12.XLim = xbound;
                       app.UIAxes12.YLim = ybound;
                       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                       scatter(app.UIAxes12, round(app.xc2), round(app.yc2), 'x','red');
                       colorbar(app.UIAxes12);
                       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
                    end
                end
                if app.yc2 >= length(app.yarray2) || app.xc2 >= length(app.xarray2)
                    if app.xc2 > 0 || app.yc2 > 0
                        xbound = [app.xc2-150,app.xc2+150]; 
                        ybound = [app.yc2-150,app.yc2+150];
                        hold(app.UIAxes12,"on");
                        imagesc(app.UIAxes12,app.frame2); 
                        app.UIAxes12.XLim = xbound;
                        app.UIAxes12.YLim = ybound;
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        scatter(app.UIAxes12, round(app.xc2), round(app.yc2), 'x','red');
                        colorbar(app.UIAxes12);
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    end
                    if app.yc2 <= 0 || app.xc2 <= 0
                       xbound = [app.xc2,app.xc2]; 
                       ybound = [app.yc2,app.yc2];
                       hold(app.UIAxes12,"on");
                       imagesc(app.UIAxes12,app.frame2); 
                       app.UIAxes12.XLim = xbound;
                       app.UIAxes12.YLim = ybound;
                       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                       scatter(app.UIAxes12, round(app.xc2), round(app.yc2), 'x','red');
                       colorbar(app.UIAxes12);
                       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
                    end                
                end
            end
        end

        % Value changed function: DisplayDropDown
        function DisplayDropDownValueChanged(app, event)
            value = app.DisplayDropDown.Value;
            if strcmp(value,'Full')
               cla(app.UIAxes13);                
               hold(app.UIAxes13,"on");
               imagesc(app.UIAxes13,app.frame3); 
               sz = size(app.frame3);
               app.UIAxes13.XLim = [0, sz(2)];
               app.UIAxes13.YLim = [0, sz(1)];
               %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
               scatter(app.UIAxes13, round(app.xc3), round(app.yc3), 'x','red');
               colorbar(app.UIAxes13);
               %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  
            end
            if strcmp(value,'AOI')
               cla(app.UIAxes13);                
                xbound = [app.xc3-100,app.xc3+100]; 
                ybound = [app.yc3-100,app.yc3+100];  
                    
                if app.yc3 < length(app.yarray3) || app.xc3 < length(app.xarray3)
                    if app.yc3 > 0 || app.xc3 > 0
                        hold(app.UIAxes13,"on");
                        imagesc(app.UIAxes13,app.frame3); 
                        app.UIAxes13.XLim = xbound;
                        app.UIAxes13.YLim = ybound;
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        scatter(app.UIAxes13, round(app.xc3), round(app.yc3), 'x','red');
                        colorbar(app.UIAxes13);
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
                    end
                    if app.yc3 <= 0 || app.xc3 <= 0
                       xbound = [app.xc3,app.xc3+100]; 
                       ybound = [app.yc3,app.yc3+100];
                       hold(app.UIAxes13,"on");
                       imagesc(app.UIAxes13,app.frame3); 
                       app.UIAxes13.XLim = xbound;
                       app.UIAxes13.YLim = ybound;
                       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                       scatter(app.UIAxes13, round(app.xc3), round(app.yc3), 'x','red');
                       colorbar(app.UIAxes13);
                       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
                    end
                end
                if app.yc3 >= length(app.yarray3) || app.xc3 >= length(app.xarray3)
                    if app.xc3 > 0 || app.yc3 > 0
                        xbound = [app.xc3-100,app.xc3+100]; 
                        ybound = [app.yc3-100,app.yc3+100];
                        hold(app.UIAxes13,"on");
                        imagesc(app.UIAxes13,app.frame3); 
                        app.UIAxes13.XLim = xbound;
                        app.UIAxes13.YLim = ybound;
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        scatter(app.UIAxes13, round(app.xc3), round(app.yc3), 'x','red');
                        colorbar(app.UIAxes13);
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    end
                    if app.yc3 <= 0 || app.xc3 <= 0
                       xbound = [app.xc3,app.xc3]; 
                       ybound = [app.yc3,app.yc3];
                       hold(app.UIAxes13,"on");
                       imagesc(app.UIAxes13,app.frame3); 
                       app.UIAxes13.XLim = xbound;
                       app.UIAxes13.YLim = ybound;
                       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                       scatter(app.UIAxes13, round(app.xc3), round(app.yc3), 'x','red');
                       colorbar(app.UIAxes13);
                       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
                    end                
                end
            end            
        end

        % Value changed function: DisplayDropDown_4
        function DisplayDropDown_4ValueChanged(app, event)
            value = app.DisplayDropDown_4.Value;
            if strcmp(value,'Full')
               cla(app.UIAxes14);                
               hold(app.UIAxes14,"on");
               imagesc(app.UIAxes14,app.frame4); 
               sz = size(app.frame4);
               app.UIAxes14.XLim = [0, sz(2)];
               app.UIAxes14.YLim = [0, sz(1)];
               %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
               scatter(app.UIAxes14, round(app.xc4), round(app.yc4), 'x','red');
               colorbar(app.UIAxes14);
               %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  
            end
            if strcmp(value,'AOI')
               cla(app.UIAxes14);                
                xbound = [app.xc4-100,app.xc4+100]; 
                ybound = [app.yc4-100,app.yc4+100];  
                    
                if app.yc4 < length(app.yarray4) || app.xc4 < length(app.xarray4)
                    if app.xc4 > 0 || app.yc4 > 0
                        hold(app.UIAxes14,"on");
                        imagesc(app.UIAxes14,app.frame4); 
                        app.UIAxes14.XLim = xbound;
                        app.UIAxes14.YLim = ybound;
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        scatter(app.UIAxes14, round(app.xc4), round(app.yc4), 'x','red');
                        colorbar(app.UIAxes14);
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
                    end
                    if app.yc4 <= 0 || app.xc4 <= 0
                       xbound = [app.xc4,app.xc4+100]; 
                       ybound = [app.yc4,app.yc4+100];
                       hold(app.UIAxes14,"on");
                       imagesc(app.UIAxes14,app.frame4); 
                       app.UIAxes14.XLim = xbound;
                       app.UIAxes14.YLim = ybound;
                       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                       scatter(app.UIAxes14, round(app.xc4), round(app.yc4), 'x','red');
                       colorbar(app.UIAxes14);
                       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
                    end
                end
                if app.yc4 >= length(app.yarray4) || app.xc4 >= length(app.xarray4)
                    if app.xc4 > 0 || app.yc4 > 0
                        xbound = [app.xc4-100,app.xc4+100]; 
                        ybound = [app.yc4-100,app.yc4+100];
                        hold(app.UIAxes14,"on");
                        imagesc(app.UIAxes14,app.frame4); 
                        app.UIAxes14.XLim = xbound;
                        app.UIAxes14.YLim = ybound;
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        scatter(app.UIAxes14, round(app.xc4), round(app.yc4), 'x','red');
                        colorbar(app.UIAxes14);
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    end
                    if app.yc4 <= 0 || app.xc4 <= 0
                       xbound = [app.xc4,app.xc4]; 
                       ybound = [app.yc4,app.yc4];
                       hold(app.UIAxes14,"on");
                       imagesc(app.UIAxes14,app.frame4); 
                       app.UIAxes14.XLim = xbound;
                       app.UIAxes14.YLim = ybound;
                       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                       scatter(app.UIAxes14, round(app.xc4), round(app.yc4), 'x','red');
                       colorbar(app.UIAxes14);
                       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
                    end                
                end
            end
        end

        % Value changed function: DisplayDropDown_5
        function DisplayDropDown_5ValueChanged(app, event)
            value = app.DisplayDropDown_5.Value;
            if strcmp(value,'Full')
               cla(app.UIAxes15);                
               hold(app.UIAxes15,"on");
               imagesc(app.UIAxes15,app.frame5); 
               sz = size(app.frame5);
               app.UIAxes15.XLim = [0, sz(2)];
               app.UIAxes15.YLim = [0, sz(1)];
               %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
               scatter(app.UIAxes15, round(app.xc5), round(app.yc5), 'x','red');
               colorbar(app.UIAxes15);
               %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  
            end
            if strcmp(value,'AOI')
               cla(app.UIAxes15);                
                xbound = [app.xc5-100,app.xc5+100]; 
                ybound = [app.yc5-100,app.yc5+100];  
                    
                if app.yc5 < length(app.yarray5) || app.xc5 < length(app.xarray5)
                    if app.yc5 > 0 || app.xc5 > 0
                        hold(app.UIAxes15,"on");
                        imagesc(app.UIAxes15,app.frame5); 
                        app.UIAxes15.XLim = xbound;
                        app.UIAxes15.YLim = ybound;
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        scatter(app.UIAxes15, round(app.xc5), round(app.yc5), 'x','red');
                        colorbar(app.UIAxes15);
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
                    end
                    if app.yc5 <= 0 || app.xc5 <= 0
                       xbound = [app.xc5,app.xc5+100]; 
                       ybound = [app.yc5,app.yc5+100];
                       hold(app.UIAxes15,"on");
                       imagesc(app.UIAxes15,app.frame5); 
                       app.UIAxes15.XLim = xbound;
                       app.UIAxes15.YLim = ybound;
                       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                       scatter(app.UIAxes15, round(app.xc5), round(app.yc5), 'x','red');
                       colorbar(app.UIAxes15);
                       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
                    end
                end
                if app.xc5 >= length(app.yarray5) || app.yc5 >= length(app.xarray5)
                    if app.xc5 > 0 || app.yc5 > 0
                        xbound = [app.xc5-100,app.xc5+100]; 
                        ybound = [app.yc5-100,app.yc5+100];
                        hold(app.UIAxes15,"on");
                        imagesc(app.UIAxes15,app.frame5); 
                        app.UIAxes15.XLim = xbound;
                        app.UIAxes15.YLim = ybound;
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        scatter(app.UIAxes15, round(app.xc5), round(app.yc5), 'x','red');
                        colorbar(app.UIAxes15);
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    end
                    if app.yc5 <= 0 || app.xc5 <= 0
                       xbound = [app.xc5,app.xc5]; 
                       ybound = [app.yc5,app.yc5];
                       hold(app.UIAxes15,"on");
                       imagesc(app.UIAxes15,app.frame5); 
                       app.UIAxes15.XLim = xbound;
                       app.UIAxes15.YLim = ybound;
                       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                       scatter(app.UIAxes15, round(app.xc5), round(app.yc5), 'x','red');
                       colorbar(app.UIAxes15);
                       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
                    end                
                end
            end
        end

        % Button pushed function: ClearAllCamerasButton
        function ClearAllCamerasButtonPushed(app, event)
            app.intensity0 = [];
            app.intensity1 = [];
            app.intensity2 = [];
            app.intensity3 = [];
            app.intensity4 = [];
            cla(app.UIAxes16)
            cla(app.UIAxes16_2)
            cla(app.UIAxes16_3)
            cla(app.UIAxes16_4)
            cla(app.UIAxes16_5)
        end

        % Button pushed function: ClearAllMostRecentButton
        function ClearAllMostRecentButtonPushed(app, event)
            if length(app.intensity0) > 0
                app.intensity0(end) = [];
            else
                app.intensity0 = [];
            end
            if length(app.intensity1) > 0
                app.intensity1(end) = [];
            else
                app.intensity1 = [];
            end
            if length(app.intensity2) > 0
                app.intensity2(end) = [];
            else
                app.intensity2 = [];
            end
            if length(app.intensity3) > 0
                app.intensity3(end) = [];
            else
                app.intensity3 = [];
            end
            if length(app.intensity4) > 0
                app.intensity4(end) = [];
            else
                app.intensity4 = [];
            end
            cla(app.UIAxes16)
            cla(app.UIAxes16_2)
            cla(app.UIAxes16_3)
            cla(app.UIAxes16_4)
            cla(app.UIAxes16_5)
            % plot intensity
            hold(app.UIAxes16, "on")
            plot(app.UIAxes16, app.intensity0, '--o', 'Color', 'k')
            hold(app.UIAxes16_2, "on")
            plot(app.UIAxes16_2, app.intensity1, '--o', 'Color', 'b')
            hold(app.UIAxes16_3, "on")
            plot(app.UIAxes16_3, app.intensity2, '--o', 'Color', 'r')
            hold(app.UIAxes16_3, "on")
            plot(app.UIAxes16_4, app.intensity3, '--o', 'Color', 'g')
            hold(app.UIAxes16_5, "on")
            plot(app.UIAxes16_5, app.intensity4, '--o', 'Color', 'm')
        end

        % Button pushed function: ClearAllCamerasButton_2
        function ClearAllCamerasButton_2Pushed(app, event)
            app.x0arr = [];
            app.x1arr = [];
            app.x2arr = [];
            app.x3arr = [];
            app.x4arr = [];
            app.y0arr = [];
            app.y1arr = [];
            app.y2arr = [];
            app.y3arr = [];
            app.y4arr = [];
            cla(app.UIAxes16_6)
            cla(app.UIAxes16_7)
            cla(app.UIAxes16_8)
            cla(app.UIAxes16_9)
            cla(app.UIAxes16_10)
        end

        % Button pushed function: ClearAllMostRecentButton_2
        function ClearAllMostRecentButton_2Pushed(app, event)
            if length(app.x0arr) > 0
                app.x0arr(end) = [];
            else
                app.x0arr = [];
            end
            if length(app.x1arr) > 0
                app.x1arr(end) = [];
            else
                app.x1arr = [];
            end
            if length(app.x2arr) > 0
                app.x2arr(end) = [];
            else
                app.x2arr = [];
            end
            if length(app.x3arr) > 0
                app.x3arr(end) = [];
            else
                app.x3arr = [];
            end
            if length(app.x4arr) > 0
                app.x4arr(end) = [];
            else
                app.x4arr = [];
            end
            if length(app.y0arr) > 0
                app.y0arr(end) = [];
            else
                app.y0arr = [];
            end
            
            if length(app.y1arr) > 0
                app.y1arr(end) = [];
            else
                app.y1arr = [];
            end
            if length(app.y2arr) > 0
                app.y2arr(end) = [];
            else
                app.y2arr = [];
            end
            if length(app.y3arr) > 0
                app.y3arr(end) = [];
            else
                app.y3arr = [];
            end
            if length(app.y4arr) > 0
                app.y4arr(end) = [];
            else
                app.y4arr = [];
            end
            
            % checking radio buttons for plotting
            selectedButton0 = app.Camera0ButtonGroup.SelectedObject;
            selectedButton1 = app.Camera1ButtonGroup.SelectedObject;
            selectedButton2 = app.Camera2ButtonGroup.SelectedObject;
            selectedButton3 = app.Camera3ButtonGroup.SelectedObject;
            selectedButton4 = app.Camera4ButtonGroup.SelectedObject;
   
            cla(app.UIAxes16_6)
            cla(app.UIAxes16_7)
            cla(app.UIAxes16_8)
            cla(app.UIAxes16_9)
            cla(app.UIAxes16_10)
            
            % plot centroids
            if selectedButton0 == app.XButton_5
                cla(app.UIAxes16_6)
                hold(app.UIAxes16_6, "on")
                plot(app.UIAxes16_6, app.x0arr, '--o', 'Color', 'b')
            else
                cla(app.UIAxes16_6)
                hold(app.UIAxes16_6, "on")
                plot(app.UIAxes16_6, app.y0arr, '--o', 'Color', 'r')
            end
            if selectedButton1 == app.XButton_2
                cla(app.UIAxes16_7)
                hold(app.UIAxes16_7, "on")
                plot(app.UIAxes16_7, app.x1arr, '--o', 'Color', 'b')
            else
                cla(app.UIAxes16_7)
                hold(app.UIAxes16_7, "on")
                plot(app.UIAxes16_7, app.y1arr, '--o', 'Color', 'r')
            end
            if selectedButton2 == app.XButton_4
                cla(app.UIAxes16_8)
                hold(app.UIAxes16_8, "on")
                plot(app.UIAxes16_8, app.x2arr, '--o', 'Color', 'b')
            else
                cla(app.UIAxes16_8)
                hold(app.UIAxes16_8, "on")
                plot(app.UIAxes16_8, app.y2arr, '--o', 'Color', 'r')
            end
            if selectedButton3 == app.XButton_5
                cla(app.UIAxes16_9)
                hold(app.UIAxes16_9, "on")
                plot(app.UIAxes16_9, app.x3arr, '--o', 'Color', 'b')
            else
                cla(app.UIAxes16_9)
                hold(app.UIAxes16_9, "on")
                plot(app.UIAxes16_9, app.y3arr, '--o', 'Color', 'r')
            end
            if selectedButton4 == app.XButton
                cla(app.UIAxes16_10)
                hold(app.UIAxes16_10, "on")
                plot(app.UIAxes16_10, app.x4arr, '--o', 'Color', 'b')
            else
                cla(app.UIAxes16_10)
                hold(app.UIAxes16_10, "on")
                plot(app.UIAxes16_10, app.y4arr, '--o', 'Color', 'r')
            end
        end

        % Button pushed function: ClearAllButton_6
        function ClearAllButton_6Pushed(app, event)
            app.x0arr = [];
            app.y0arr = [];
            cla(app.UIAxes16_6)
        end

        % Button pushed function: ClearAllButton_7
        function ClearAllButton_7Pushed(app, event)
            app.x1arr = [];
            app.y1arr = [];
            cla(app.UIAxes16_7)
        end

        % Button pushed function: ClearAllButton_8
        function ClearAllButton_8Pushed(app, event)
            app.x2arr = [];
            app.y2arr = [];
            cla(app.UIAxes16_8)
        end

        % Button pushed function: ClearAllButton_10
        function ClearAllButton_10Pushed(app, event)
            app.x3arr = [];
            app.y3arr = [];
            cla(app.UIAxes16_9)
        end

        % Button pushed function: ClearAllButton_9
        function ClearAllButton_9Pushed(app, event)
            app.x4arr = [];
            app.y4arr = [];
            cla(app.UIAxes16_10)
        end

        % Button pushed function: ClearMostRecentButton_6
        function ClearMostRecentButton_6Pushed(app, event)
            selectedButton = app.Camera0ButtonGroup.SelectedObject;
            if length(app.x0arr) > 0
                app.x0arr(end) = [];
            else
                app.x0arr = [];
            end
            if length(app.y0arr) > 0
                app.y0arr(end) = [];
            else
                app.y0arr = [];
            end
            if selectedButton == app.XButton_5
                cla(app.UIAxes16_6)
                hold(app.UIAxes16_6, "on")
                plot(app.UIAxes16_6, app.x0arr, '--o', 'Color', 'b')
            else
                cla(app.UIAxes16_6)
                hold(app.UIAxes16_6, "on")
                plot(app.UIAxes16_6, app.y0arr, '--o', 'Color', 'r')
            end
        end

        % Button pushed function: ClearMostRecentButton_7
        function ClearMostRecentButton_7Pushed(app, event)
            selectedButton = app.Camera1ButtonGroup.SelectedObject;
            if length(app.x1arr) > 0
                app.x1arr(end) = [];
            else
                app.x1arr = [];
            end
            if length(app.y1arr) > 0
                app.y1arr(end) = [];
            else
                app.y1arr = [];
            end
            if selectedButton == app.XButton_2
                cla(app.UIAxes16_7)
                hold(app.UIAxes16_7, "on")
                plot(app.UIAxes16_7, app.x1arr, '--o', 'Color', 'b')
            else
                cla(app.UIAxes16_7)
                hold(app.UIAxes16_7, "on")
                plot(app.UIAxes16_7, app.y1arr, '--o', 'Color', 'r')
            end
        end

        % Button pushed function: ClearMostRecentButton_8
        function ClearMostRecentButton_8Pushed(app, event)
            selectedButton = app.Camera2ButtonGroup.SelectedObject;
            if length(app.x2arr) > 0
                app.x2arr(end) = [];
            else
                app.x2arr = [];
            end
            if length(app.y2arr) > 0
                app.y2arr(end) = [];
            else
                app.y2arr = [];
            end
            if selectedButton == app.XButton_3
                cla(app.UIAxes16_8)
                hold(app.UIAxes16_8, "on")
                plot(app.UIAxes16_8, app.x2arr, '--o', 'Color', 'b')
            else
                cla(app.UIAxes16_8)
                hold(app.UIAxes16_8, "on")
                plot(app.UIAxes16_8, app.y2arr, '--o', 'Color', 'r')
            end
        end

        % Button pushed function: ClearMostRecentButton_10
        function ClearMostRecentButton_10Pushed(app, event)
            selectedButton = app.Camera3ButtonGroup.SelectedObject;
            if length(app.x3arr) > 0
                app.x3arr(end) = [];
            else
                app.x3arr = [];
            end
            if length(app.y3arr) > 0
                app.y3arr(end) = [];
            else
                app.y3arr = [];
            end
            if selectedButton == app.XButton_4
                cla(app.UIAxes16_9)
                hold(app.UIAxes16_9, "on")
                plot(app.UIAxes16_9, app.x3arr, '--o', 'Color', 'b')
            else
                cla(app.UIAxes16_9)
                hold(app.UIAxes16_9, "on")
                plot(app.UIAxes16_9, app.y3arr, '--o', 'Color', 'r')
            end
        end

        % Button pushed function: ClearMostRecentButton_9
        function ClearMostRecentButton_9Pushed(app, event)
            selectedButton = app.Camera4ButtonGroup.SelectedObject;
            if length(app.x4arr) > 0
                app.x4arr(end) = [];
            else
                app.x4arr = [];
            end
            if length(app.y4arr) > 0
                app.y4arr(end) = [];
            else
                app.y4arr = [];
            end
            if selectedButton == app.XButton
                cla(app.UIAxes16_10)
                hold(app.UIAxes16_10, "on")
                plot(app.UIAxes16_10, app.x4arr, '--o', 'Color', 'b')
            else
                cla(app.UIAxes16_10)
                hold(app.UIAxes16_10, "on")
                plot(app.UIAxes16_10, app.y4arr, '--o', 'Color', 'r')
            end
        end

        % Button pushed function: ClearAllButton_3
        function ClearAllButton_3Pushed(app, event)
            app.intensity0 = [];
            cla(app.UIAxes16)
        end

        % Button pushed function: ClearAllButton_2
        function ClearAllButton_2Pushed(app, event)
            app.intensity1 = [];
            cla(app.UIAxes16_2)
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.intensity2 = [];
            cla(app.UIAxes16_3)
        end

        % Button pushed function: ClearAllButton_5
        function ClearAllButton_5Pushed(app, event)
            app.intensity3 = [];
            cla(app.UIAxes16_4)
        end

        % Button pushed function: ClearAllButton_4
        function ClearAllButton_4Pushed(app, event)
            app.intensity4 = [];
            cla(app.UIAxes16_5)
        end

        % Button pushed function: ClearMostRecentButton
        function ClearMostRecentButtonPushed(app, event)
            if length(app.intensity0) > 0
                app.intensity0(end) = [];
            else
                app.intensity0 = [];
            end
            cla(app.UIAxes16)
            hold(app.UIAxes16, "on")
            plot(app.UIAxes16, app.intensity0, '--o', 'Color', 'k')
        end

        % Button pushed function: ClearMostRecentButton_2
        function ClearMostRecentButton_2Pushed(app, event)
            if length(app.intensity1) > 0
                app.intensity1(end) = [];
            else
                app.intensity1 = [];
            end
            cla(app.UIAxes16_2)
            hold(app.UIAxes16_2, "on")
            plot(app.UIAxes16_2, app.intensity1, '--o', 'Color', 'b')
        end

        % Button pushed function: ClearMostRecentButton_3
        function ClearMostRecentButton_3Pushed(app, event)
            if length(app.intensity2) > 0
                app.intensity2(end) = [];
            else
                app.intensity2 = [];
            end
            cla(app.UIAxes16_3)
            hold(app.UIAxes16_3, "on")
            plot(app.UIAxes16_3, app.intensity2, '--o', 'Color', 'r')
        end

        % Button pushed function: ClearMostRecentButton_5
        function ClearMostRecentButton_5Pushed(app, event)
            if length(app.intensity3) > 0
                app.intensity3(end) = [];
            else
                app.intensity3 = [];
            end
            cla(app.UIAxes16_4)
            hold(app.UIAxes16_4, "on")
            plot(app.UIAxes16_4, app.intensity3, '--o', 'Color', 'g')
        end

        % Button pushed function: ClearMostRecentButton_4
        function ClearMostRecentButton_4Pushed(app, event)
            if length(app.intensity4) > 0
                app.intensity4(end) = [];
            else
                app.intensity4 = [];
            end
            cla(app.UIAxes16_5)
            hold(app.UIAxes16_5, "on")
            plot(app.UIAxes16_5, app.intensity4, '--o', 'Color', 'm')
        end

        % Selection changed function: Camera1ButtonGroup
        function Camera1ButtonGroupSelectionChanged(app, event)
            selectedButton = app.Camera1ButtonGroup.SelectedObject;
            if selectedButton == app.XButton_2
                cla(app.UIAxes16_7)
                hold(app.UIAxes16_7, "on")
                plot(app.UIAxes16_7, app.x1arr, '--o', 'Color', 'b')
            else
                cla(app.UIAxes16_7)
                hold(app.UIAxes16_7, "on")
                plot(app.UIAxes16_7, app.y1arr, '--o', 'Color', 'r')
            end
        end

        % Selection changed function: Camera0ButtonGroup
        function Camera0ButtonGroupSelectionChanged(app, event)
            selectedButton = app.Camera0ButtonGroup.SelectedObject;
            if selectedButton == app.XButton_5
                cla(app.UIAxes16_6)
                hold(app.UIAxes16_6, "on")
                plot(app.UIAxes16_6, app.x0arr, '--o', 'Color', 'b')
            else
                cla(app.UIAxes16_6)
                hold(app.UIAxes16_6, "on")
                plot(app.UIAxes16_6, app.y0arr, '--o', 'Color', 'r')
            end
        end

        % Selection changed function: Camera2ButtonGroup
        function Camera2ButtonGroupSelectionChanged(app, event)
            selectedButton = app.Camera2ButtonGroup.SelectedObject;
            if selectedButton == app.XButton_3
                cla(app.UIAxes16_8)
                hold(app.UIAxes16_8, "on")
                plot(app.UIAxes16_8, app.x2arr, '--o', 'Color', 'b')
            else
                cla(app.UIAxes16_8)
                hold(app.UIAxes16_8, "on")
                plot(app.UIAxes16_8, app.y2arr, '--o', 'Color', 'r')
            end
        end

        % Selection changed function: Camera3ButtonGroup
        function Camera3ButtonGroupSelectionChanged(app, event)
            selectedButton = app.Camera3ButtonGroup.SelectedObject;
            if selectedButton == app.XButton_4
                cla(app.UIAxes16_9)
                hold(app.UIAxes16_9, "on")
                plot(app.UIAxes16_9, app.x3arr, '--o', 'Color', 'b')
            else
                cla(app.UIAxes16_9)
                hold(app.UIAxes16_9, "on")
                plot(app.UIAxes16_9, app.y3arr, '--o', 'Color', 'r')
            end
        end

        % Selection changed function: Camera4ButtonGroup
        function Camera4ButtonGroupSelectionChanged(app, event)
            selectedButton = app.Camera4ButtonGroup.SelectedObject;
            if selectedButton == app.XButton
                cla(app.UIAxes16_10)
                hold(app.UIAxes16_10, "on")
                plot(app.UIAxes16_10, app.x4arr, '--o', 'Color', 'b')
            else
                cla(app.UIAxes16_10)
                hold(app.UIAxes16_10, "on")
                plot(app.UIAxes16_10, app.y4arr, '--o', 'Color', 'r')
            end
        end

        % Button pushed function: ClearAllPlotsButton
        function ClearAllPlotsButtonPushed(app, event)
            app.x0arr = [];
            app.x1arr = [];
            app.x2arr = [];
            app.x3arr = [];
            app.x4arr = [];
            app.y0arr = [];
            app.y1arr = [];
            app.y2arr = [];
            app.y3arr = [];
            app.y4arr = [];
            app.intensity0 = [];
            app.intensity1 = [];
            app.intensity2 = [];
            app.intensity3 = [];
            app.intensity4 = [];
            cla(app.UIAxes16)
            cla(app.UIAxes16_2)
            cla(app.UIAxes16_3)
            cla(app.UIAxes16_4)
            cla(app.UIAxes16_5)
            cla(app.UIAxes16_6)
            cla(app.UIAxes16_7)
            cla(app.UIAxes16_8)
            cla(app.UIAxes16_9)
            cla(app.UIAxes16_10)
        end

        % Button pushed function: ClearAllPlotsButton_2
        function ClearAllPlotsButton_2Pushed(app, event)
            app.x0arr = [];
            app.x1arr = [];
            app.x2arr = [];
            app.x3arr = [];
            app.x4arr = [];
            app.y0arr = [];
            app.y1arr = [];
            app.y2arr = [];
            app.y3arr = [];
            app.y4arr = [];
            app.intensity0 = [];
            app.intensity1 = [];
            app.intensity2 = [];
            app.intensity3 = [];
            app.intensity4 = [];
            cla(app.UIAxes16)
            cla(app.UIAxes16_2)
            cla(app.UIAxes16_3)
            cla(app.UIAxes16_4)
            cla(app.UIAxes16_5)
            cla(app.UIAxes16_6)
            cla(app.UIAxes16_7)
            cla(app.UIAxes16_8)
            cla(app.UIAxes16_9)
            cla(app.UIAxes16_10)
        end

        % Value changed function: ExposureTimeusEditField_5
        function ExposureTimeusEditField_5ValueChanged(app, event)
            value = app.ExposureTimeusEditField_5.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FitTab = uitab(app.TabGroup);
            app.FitTab.Title = 'Fit';
            app.Camera4Label = uilabel(app.FitTab);
            app.Camera4Label.Text = 'Camera 4';
            app.Camera1Label = uilabel(app.FitTab);
            app.Camera1Label.Text = 'Camera 1';
            app.Camera3Label = uilabel(app.FitTab);
            app.Camera3Label.Text = 'Camera 3';
            app.Camera2Label = uilabel(app.FitTab);
            app.Camera2Label.Text = 'Camera 2';
            app.Camera0Label = uilabel(app.FitTab);
            app.Camera0Label.Text = 'Camera 0';
            app.XLabel = uilabel(app.FitTab);
            app.XLabel.Text = 'X';
            app.XLabel_2 = uilabel(app.FitTab);
            app.XLabel_2.Text = 'X';
            app.YLabel = uilabel(app.FitTab);
            app.YLabel.Text = 'Y ';
            app.YLabel_2 = uilabel(app.FitTab);
            app.YLabel_2.Text = 'Y';
            app.Label = uilabel(app.FitTab);
            app.Label2 = uilabel(app.FitTab);
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.FitTab);
            app.Label3.Text = 'Label3';
            app.Label4 = uilabel(app.FitTab);
            app.Label4.Text = 'Label4';
            app.XLabel_3 = uilabel(app.FitTab);
            app.XLabel_3.Text = 'X';
            app.YLabel_3 = uilabel(app.FitTab);
            app.YLabel_3.Text = 'Y';
            app.Label7 = uilabel(app.FitTab);
            app.Label7.Text = 'Label7';
            app.Label8 = uilabel(app.FitTab);
            app.Label8.Text = 'Label8';
            app.Label6_2 = uilabel(app.FitTab);
            app.Label6_2.Text = 'Label6';
            app.YLabel_4 = uilabel(app.FitTab);
            app.YLabel_4.Text = 'Y';
            app.Label5_2 = uilabel(app.FitTab);
            app.Label5_2.Text = 'Label5';
            app.XLabel_4 = uilabel(app.FitTab);
            app.XLabel_4.Text = 'X';
            app.Label10 = uilabel(app.FitTab);
            app.Label10.Text = 'Label10';
            app.YLabel_5 = uilabel(app.FitTab);
            app.YLabel_5.Text = 'Y';
            app.Label9 = uilabel(app.FitTab);
            app.Label9.Text = 'Label9';
            app.XLabel_5 = uilabel(app.FitTab);
            app.XLabel_5.Text = 'X';
            app.IntensityTab = uitab(app.TabGroup);
            app.IntensityTab.Title = 'Intensity';
            app.UIAxes16 = uiaxes(app.IntensityTab);
            title(app.UIAxes16, 'Camera 0')
            xlabel(app.UIAxes16, 'Index')
            ylabel(app.UIAxes16, 'Intensity')
            zlabel(app.UIAxes16, 'Z')
            app.UIAxes16_2 = uiaxes(app.IntensityTab);
            title(app.UIAxes16_2, 'Camera 1')
            xlabel(app.UIAxes16_2, 'Index')
            ylabel(app.UIAxes16_2, 'Intensity')
            zlabel(app.UIAxes16_2, 'Z')
            app.UIAxes16_3 = uiaxes(app.IntensityTab);
            title(app.UIAxes16_3, 'Camera 2')
            xlabel(app.UIAxes16_3, 'Index')
            ylabel(app.UIAxes16_3, 'Intensity')
            zlabel(app.UIAxes16_3, 'Z')
            app.UIAxes16_4 = uiaxes(app.IntensityTab);
            title(app.UIAxes16_4, 'Camera 3')
            xlabel(app.UIAxes16_4, 'Index')
            ylabel(app.UIAxes16_4, 'Intensity')
            zlabel(app.UIAxes16_4, 'Z')
            app.UIAxes16_5 = uiaxes(app.IntensityTab);
            title(app.UIAxes16_5, 'Camera 4')
            xlabel(app.UIAxes16_5, 'Index')
            ylabel(app.UIAxes16_5, 'Intensity')
            zlabel(app.UIAxes16_5, 'Z')
            app.ClearAllCamerasButton = uibutton(app.IntensityTab, 'push');
            app.ClearAllCamerasButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllCamerasButtonPushed, true);
            app.ClearAllCamerasButton.Text = 'Clear All Cameras';
            app.ClearMostRecentButton = uibutton(app.IntensityTab, 'push');
            app.ClearMostRecentButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMostRecentButtonPushed, true);
            app.ClearMostRecentButton.Text = 'Clear Most Recent';
            app.ClearAllButton_3 = uibutton(app.IntensityTab, 'push');
            app.ClearAllButton_3.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButton_3Pushed, true);
            app.ClearAllButton_3.Text = 'Clear All';
            app.ClearMostRecentButton_2 = uibutton(app.IntensityTab, 'push');
            app.ClearMostRecentButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearMostRecentButton_2Pushed, true);
            app.ClearMostRecentButton_2.Text = 'Clear Most Recent';
            app.ClearAllButton_2 = uibutton(app.IntensityTab, 'push');
            app.ClearAllButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButton_2Pushed, true);
            app.ClearAllButton_2.Text = 'Clear All';
            app.ClearMostRecentButton_3 = uibutton(app.IntensityTab, 'push');
            app.ClearMostRecentButton_3.ButtonPushedFcn = createCallbackFcn(app, @ClearMostRecentButton_3Pushed, true);
            app.ClearMostRecentButton_3.Text = 'Clear Most Recent';
            app.ClearAllButton = uibutton(app.IntensityTab, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.ClearMostRecentButton_4 = uibutton(app.IntensityTab, 'push');
            app.ClearMostRecentButton_4.ButtonPushedFcn = createCallbackFcn(app, @ClearMostRecentButton_4Pushed, true);
            app.ClearMostRecentButton_4.Text = 'Clear Most Recent';
            app.ClearAllButton_4 = uibutton(app.IntensityTab, 'push');
            app.ClearAllButton_4.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButton_4Pushed, true);
            app.ClearAllButton_4.Text = 'Clear All';
            app.ClearMostRecentButton_5 = uibutton(app.IntensityTab, 'push');
            app.ClearMostRecentButton_5.ButtonPushedFcn = createCallbackFcn(app, @ClearMostRecentButton_5Pushed, true);
            app.ClearMostRecentButton_5.Text = 'Clear Most Recent';
            app.ClearAllButton_5 = uibutton(app.IntensityTab, 'push');
            app.ClearAllButton_5.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButton_5Pushed, true);
            app.ClearAllButton_5.Text = 'Clear All';
            app.ClearAllMostRecentButton = uibutton(app.IntensityTab, 'push');
            app.ClearAllMostRecentButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllMostRecentButtonPushed, true);
            app.ClearAllMostRecentButton.Text = 'Clear All Most Recent';
            app.ClearAllPlotsButton_2 = uibutton(app.IntensityTab, 'push');
            app.ClearAllPlotsButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearAllPlotsButton_2Pushed, true);
            app.ClearAllPlotsButton_2.Text = 'Clear All Plots';
            app.CentroidTab = uitab(app.TabGroup);
            app.CentroidTab.Title = 'Centroid';
            app.UIAxes16_6 = uiaxes(app.CentroidTab);
            title(app.UIAxes16_6, 'Camera 0')
            xlabel(app.UIAxes16_6, 'Index')
            ylabel(app.UIAxes16_6, 'Centroid')
            zlabel(app.UIAxes16_6, 'Z')
            app.UIAxes16_7 = uiaxes(app.CentroidTab);
            title(app.UIAxes16_7, 'Camera 1')
            xlabel(app.UIAxes16_7, 'Index')
            ylabel(app.UIAxes16_7, 'Centroid')
            zlabel(app.UIAxes16_7, 'Z')
            app.UIAxes16_8 = uiaxes(app.CentroidTab);
            title(app.UIAxes16_8, 'Camera 2')
            xlabel(app.UIAxes16_8, 'Index')
            ylabel(app.UIAxes16_8, 'Centroid')
            zlabel(app.UIAxes16_8, 'Z')
            app.UIAxes16_9 = uiaxes(app.CentroidTab);
            title(app.UIAxes16_9, 'Camera 3')
            xlabel(app.UIAxes16_9, 'Index')
            ylabel(app.UIAxes16_9, 'Centroid')
            zlabel(app.UIAxes16_9, 'Z')
            app.UIAxes16_10 = uiaxes(app.CentroidTab);
            title(app.UIAxes16_10, 'Camera 4')
            xlabel(app.UIAxes16_10, 'Index')
            ylabel(app.UIAxes16_10, 'Centroid')
            zlabel(app.UIAxes16_10, 'Z')
            app.ClearAllCamerasButton_2 = uibutton(app.CentroidTab, 'push');
            app.ClearAllCamerasButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearAllCamerasButton_2Pushed, true);
            app.ClearAllCamerasButton_2.Text = 'Clear All Cameras';
            app.ClearMostRecentButton_6 = uibutton(app.CentroidTab, 'push');
            app.ClearMostRecentButton_6.ButtonPushedFcn = createCallbackFcn(app, @ClearMostRecentButton_6Pushed, true);
            app.ClearMostRecentButton_6.Text = 'Clear Most Recent';
            app.ClearAllButton_6 = uibutton(app.CentroidTab, 'push');
            app.ClearAllButton_6.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButton_6Pushed, true);
            app.ClearAllButton_6.Text = 'Clear All';
            app.ClearMostRecentButton_7 = uibutton(app.CentroidTab, 'push');
            app.ClearMostRecentButton_7.ButtonPushedFcn = createCallbackFcn(app, @ClearMostRecentButton_7Pushed, true);
            app.ClearMostRecentButton_7.Text = 'Clear Most Recent';
            app.ClearAllButton_7 = uibutton(app.CentroidTab, 'push');
            app.ClearAllButton_7.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButton_7Pushed, true);
            app.ClearAllButton_7.Text = 'Clear All';
            app.ClearMostRecentButton_8 = uibutton(app.CentroidTab, 'push');
            app.ClearMostRecentButton_8.ButtonPushedFcn = createCallbackFcn(app, @ClearMostRecentButton_8Pushed, true);
            app.ClearMostRecentButton_8.Text = 'Clear Most Recent';
            app.ClearAllButton_8 = uibutton(app.CentroidTab, 'push');
            app.ClearAllButton_8.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButton_8Pushed, true);
            app.ClearAllButton_8.Text = 'Clear All';
            app.ClearMostRecentButton_9 = uibutton(app.CentroidTab, 'push');
            app.ClearMostRecentButton_9.ButtonPushedFcn = createCallbackFcn(app, @ClearMostRecentButton_9Pushed, true);
            app.ClearMostRecentButton_9.Text = 'Clear Most Recent';
            app.ClearAllButton_9 = uibutton(app.CentroidTab, 'push');
            app.ClearAllButton_9.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButton_9Pushed, true);
            app.ClearAllButton_9.Text = 'Clear All';
            app.ClearMostRecentButton_10 = uibutton(app.CentroidTab, 'push');
            app.ClearMostRecentButton_10.ButtonPushedFcn = createCallbackFcn(app, @ClearMostRecentButton_10Pushed, true);
            app.ClearMostRecentButton_10.Text = 'Clear Most Recent';
            app.ClearAllButton_10 = uibutton(app.CentroidTab, 'push');
            app.ClearAllButton_10.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButton_10Pushed, true);
            app.ClearAllButton_10.Text = 'Clear All';
            app.ClearAllMostRecentButton_2 = uibutton(app.CentroidTab, 'push');
            app.ClearAllMostRecentButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearAllMostRecentButton_2Pushed, true);
            app.ClearAllMostRecentButton_2.Text = 'Clear All Most Recent';
            app.Camera4ButtonGroup = uibuttongroup(app.CentroidTab);
            app.Camera4ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Camera4ButtonGroupSelectionChanged, true);
            app.Camera4ButtonGroup.Title = 'Camera 4';
            app.XButton = uiradiobutton(app.Camera4ButtonGroup);
            app.XButton.Text = 'X';
            app.XButton.Value = true;
            app.YButton = uiradiobutton(app.Camera4ButtonGroup);
            app.YButton.Text = 'Y';
            app.Camera1ButtonGroup = uibuttongroup(app.CentroidTab);
            app.Camera1ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Camera1ButtonGroupSelectionChanged, true);
            app.Camera1ButtonGroup.Title = 'Camera 1';
            app.XButton_2 = uiradiobutton(app.Camera1ButtonGroup);
            app.XButton_2.Text = 'X';
            app.XButton_2.Value = true;
            app.YButton_2 = uiradiobutton(app.Camera1ButtonGroup);
            app.YButton_2.Text = 'Y';
            app.Camera2ButtonGroup = uibuttongroup(app.CentroidTab);
            app.Camera2ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Camera2ButtonGroupSelectionChanged, true);
            app.Camera2ButtonGroup.Title = 'Camera 2';
            app.XButton_3 = uiradiobutton(app.Camera2ButtonGroup);
            app.XButton_3.Text = 'X';
            app.XButton_3.Value = true;
            app.YButton_3 = uiradiobutton(app.Camera2ButtonGroup);
            app.YButton_3.Text = 'Y';
            app.Camera3ButtonGroup = uibuttongroup(app.CentroidTab);
            app.Camera3ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Camera3ButtonGroupSelectionChanged, true);
            app.Camera3ButtonGroup.Title = 'Camera 3';
            app.XButton_4 = uiradiobutton(app.Camera3ButtonGroup);
            app.XButton_4.Text = 'X';
            app.XButton_4.Value = true;
            app.YButton_4 = uiradiobutton(app.Camera3ButtonGroup);
            app.YButton_4.Text = 'Y';
            app.Camera0ButtonGroup = uibuttongroup(app.CentroidTab);
            app.Camera0ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Camera0ButtonGroupSelectionChanged, true);
            app.Camera0ButtonGroup.Title = 'Camera 0';
            app.XButton_5 = uiradiobutton(app.Camera0ButtonGroup);
            app.XButton_5.Text = 'X';
            app.XButton_5.Value = true;
            app.YButton_5 = uiradiobutton(app.Camera0ButtonGroup);
            app.YButton_5.Text = 'Y';
            app.ClearAllPlotsButton = uibutton(app.CentroidTab, 'push');
            app.ClearAllPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllPlotsButtonPushed, true);
            app.ClearAllPlotsButton.Text = 'Clear All Plots';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '0';
            app.UIAxes = uiaxes(app.Tab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Count')
            app.UIAxes2 = uiaxes(app.Tab);
            xlabel(app.UIAxes2, 'Y')
            ylabel(app.UIAxes2, 'Count')
            app.UIAxes11 = uiaxes(app.Tab);
            xlabel(app.UIAxes11, 'X')
            ylabel(app.UIAxes11, 'Y')
            zlabel(app.UIAxes11, 'Z')
            app.ExposeCamera0Button = uibutton(app.Tab, 'push');
            app.ExposeCamera0Button.ButtonPushedFcn = createCallbackFcn(app, @ExposeCamera0ButtonPushed, true);
            app.ExposeCamera0Button.Text = 'Expose Camera 0';
            app.SaveCamera0FiguresButton = uibutton(app.Tab, 'push');
            app.SaveCamera0FiguresButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCamera0FiguresButtonPushed, true);
            app.SaveCamera0FiguresButton.Text = 'Save Camera 0 Figures';
            app.DisplayDropDown_3Label = uilabel(app.Tab);
            app.DisplayDropDown_3Label.Text = 'Display';
            app.DisplayDropDown_3 = uidropdown(app.Tab);
            app.DisplayDropDown_3.Items = {'Full', 'AOI'};
            app.DisplayDropDown_3.ValueChangedFcn = createCallbackFcn(app, @DisplayDropDown_3ValueChanged, true);
            app.DisplayDropDown_3.Value = 'AOI';
            app.ExposureTimeusEditField_5Label = uilabel(app.Tab);
            app.ExposureTimeusEditField_5Label.Text = 'Exposure Time (us)';
            app.ExposureTimeusEditField_5 = uieditfield(app.Tab, 'numeric');
            app.ExposureTimeusEditField_5.ValueChangedFcn = createCallbackFcn(app, @ExposureTimeusEditField_5ValueChanged, true);
            app.SaveExposureTimeButton = uibutton(app.Tab, 'push');
            app.SaveExposureTimeButton.ButtonPushedFcn = createCallbackFcn(app, @SaveExposureTimeButtonPushed, true);
            app.SaveExposureTimeButton.Text = 'Save Exposure Time';
            app.PDFNameEditFieldLabel = uilabel(app.Tab);
            app.PDFNameEditFieldLabel.Text = 'PDF Name';
            app.PDFNameEditField = uieditfield(app.Tab, 'text');
            app.Label11 = uilabel(app.Tab);
            app.Label11.Text = 'Label11';
            app.Label12 = uilabel(app.Tab);
            app.Label12.Text = 'Label12';
            app.CentroidLabel = uilabel(app.Tab);
            app.CentroidLabel.Text = 'Centroid:';
            app.SigmaLabel = uilabel(app.Tab);
            app.SigmaLabel.Text = 'Sigma:';
            app.Label11_2 = uilabel(app.Tab);
            app.Label11_2.Text = 'Label11';
            app.Label12_2 = uilabel(app.Tab);
            app.Label12_2.Text = 'Label12';
            app.CentroidLabel_2 = uilabel(app.Tab);
            app.CentroidLabel_2.Text = 'Centroid:';
            app.SigmaLabel_2 = uilabel(app.Tab);
            app.SigmaLabel_2.Text = 'Sigma:';
            app.AmplitudeLabel_3 = uilabel(app.Tab);
            app.AmplitudeLabel_3.Text = 'Amplitude:';
            app.Label15 = uilabel(app.Tab);
            app.Label15.Text = 'Label15';
            app.AmplitudeLabel_4 = uilabel(app.Tab);
            app.AmplitudeLabel_4.Text = 'Amplitude: ';
            app.Label16 = uilabel(app.Tab);
            app.Label16.Text = 'Label16';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '1';
            app.UIAxes12 = uiaxes(app.Tab_2);
            xlabel(app.UIAxes12, 'X')
            ylabel(app.UIAxes12, 'Y')
            zlabel(app.UIAxes12, 'Z')
            app.UIAxes3 = uiaxes(app.Tab_2);
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Count')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.Tab_2);
            xlabel(app.UIAxes4, 'Y')
            ylabel(app.UIAxes4, 'Count')
            zlabel(app.UIAxes4, 'Z')
            app.SaveCamera1FiguresButton_2 = uibutton(app.Tab_2, 'push');
            app.SaveCamera1FiguresButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveCamera1FiguresButton_2Pushed, true);
            app.SaveCamera1FiguresButton_2.Text = 'Save Camera 1 Figures';
            app.DisplayDropDown_2Label = uilabel(app.Tab_2);
            app.DisplayDropDown_2Label.Text = 'Display';
            app.DisplayDropDown_2 = uidropdown(app.Tab_2);
            app.DisplayDropDown_2.Items = {'Full', 'AOI'};
            app.DisplayDropDown_2.ValueChangedFcn = createCallbackFcn(app, @DisplayDropDown_2ValueChanged, true);
            app.DisplayDropDown_2.Value = 'AOI';
            app.ExposureTimeusEditField_4Label = uilabel(app.Tab_2);
            app.ExposureTimeusEditField_4Label.Text = 'Exposure Time (us)';
            app.ExposureTimeusEditField_4 = uieditfield(app.Tab_2, 'numeric');
            app.SaveExposureTimeButton_2 = uibutton(app.Tab_2, 'push');
            app.SaveExposureTimeButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveExposureTimeButton_2Pushed, true);
            app.SaveExposureTimeButton_2.Text = 'Save Exposure Time';
            app.ExposeCamera1Button = uibutton(app.Tab_2, 'push');
            app.ExposeCamera1Button.ButtonPushedFcn = createCallbackFcn(app, @ExposeCamera1ButtonPushed, true);
            app.ExposeCamera1Button.Text = 'Expose Camera 1';
            app.PDFNameEditField_3Label = uilabel(app.Tab_2);
            app.PDFNameEditField_3Label.Text = 'PDF Name';
            app.PDFNameEditField_3 = uieditfield(app.Tab_2, 'text');
            app.Label11_3 = uilabel(app.Tab_2);
            app.Label11_3.Text = 'Label11';
            app.Label12_3 = uilabel(app.Tab_2);
            app.Label12_3.Text = 'Label12';
            app.CentroidLabel_3 = uilabel(app.Tab_2);
            app.CentroidLabel_3.Text = 'Centroid:';
            app.SigmaLabel_3 = uilabel(app.Tab_2);
            app.SigmaLabel_3.Text = 'Sigma:';
            app.Label11_4 = uilabel(app.Tab_2);
            app.Label11_4.Text = 'Label11';
            app.Label12_4 = uilabel(app.Tab_2);
            app.Label12_4.Text = 'Label12';
            app.CentroidLabel_4 = uilabel(app.Tab_2);
            app.CentroidLabel_4.Text = 'Centroid:';
            app.SigmaLabel_4 = uilabel(app.Tab_2);
            app.SigmaLabel_4.Text = 'Sigma:';
            app.AmplitudeLabel = uilabel(app.Tab_2);
            app.AmplitudeLabel.Text = 'Amplitude: ';
            app.Label13 = uilabel(app.Tab_2);
            app.Label13.Text = 'Label13';
            app.Label14 = uilabel(app.Tab_2);
            app.Label14.Text = 'Label14';
            app.AmplitudeLabel_2 = uilabel(app.Tab_2);
            app.AmplitudeLabel_2.Text = 'Amplitude:';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '2';
            app.UIAxes5 = uiaxes(app.Tab_3);
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Count')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.Tab_3);
            xlabel(app.UIAxes6, 'Y')
            ylabel(app.UIAxes6, 'Count')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes13 = uiaxes(app.Tab_3);
            xlabel(app.UIAxes13, 'X')
            ylabel(app.UIAxes13, 'Y')
            zlabel(app.UIAxes13, 'Z')
            app.ExposeCamera2Button = uibutton(app.Tab_3, 'push');
            app.ExposeCamera2Button.ButtonPushedFcn = createCallbackFcn(app, @ExposeCamera2ButtonPushed, true);
            app.ExposeCamera2Button.Text = 'Expose Camera 2';
            app.SaveCamera2FiguresButton_2 = uibutton(app.Tab_3, 'push');
            app.SaveCamera2FiguresButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveCamera2FiguresButton_2Pushed, true);
            app.SaveCamera2FiguresButton_2.Text = 'Save Camera 2 Figures';
            app.DisplayDropDownLabel = uilabel(app.Tab_3);
            app.DisplayDropDownLabel.Text = 'Display';
            app.DisplayDropDown = uidropdown(app.Tab_3);
            app.DisplayDropDown.Items = {'Full', 'AOI'};
            app.DisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayDropDownValueChanged, true);
            app.DisplayDropDown.Value = 'AOI';
            app.ExposureTimeusEditField_3Label = uilabel(app.Tab_3);
            app.ExposureTimeusEditField_3Label.Text = 'Exposure Time (us)';
            app.ExposureTimeusEditField_3 = uieditfield(app.Tab_3, 'numeric');
            app.SaveExposureTimeButton_3 = uibutton(app.Tab_3, 'push');
            app.SaveExposureTimeButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveExposureTimeButton_3Pushed, true);
            app.SaveExposureTimeButton_3.Text = 'Save Exposure Time';
            app.PDFNameEditField_4Label = uilabel(app.Tab_3);
            app.PDFNameEditField_4Label.Text = 'PDF Name';
            app.PDFNameEditField_4 = uieditfield(app.Tab_3, 'text');
            app.Label11_5 = uilabel(app.Tab_3);
            app.Label11_5.Text = 'Label11';
            app.Label12_5 = uilabel(app.Tab_3);
            app.Label12_5.Text = 'Label12';
            app.CentroidLabel_5 = uilabel(app.Tab_3);
            app.CentroidLabel_5.Text = 'Centroid:';
            app.SigmaLabel_5 = uilabel(app.Tab_3);
            app.SigmaLabel_5.Text = 'Sigma:';
            app.Label11_6 = uilabel(app.Tab_3);
            app.Label11_6.Text = 'Label11';
            app.Label12_6 = uilabel(app.Tab_3);
            app.Label12_6.Text = 'Label12';
            app.CentroidLabel_6 = uilabel(app.Tab_3);
            app.CentroidLabel_6.Text = 'Centroid:';
            app.SigmaLabel_6 = uilabel(app.Tab_3);
            app.SigmaLabel_6.Text = 'Sigma:';
            app.AmplitudeLabel_5 = uilabel(app.Tab_3);
            app.AmplitudeLabel_5.Text = 'Amplitude:';
            app.Label17 = uilabel(app.Tab_3);
            app.Label17.Text = 'Label17';
            app.AmplitudeLabel_6 = uilabel(app.Tab_3);
            app.AmplitudeLabel_6.Text = 'Amplitude:';
            app.Label18 = uilabel(app.Tab_3);
            app.Label18.Text = 'Label18';
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = '3';
            app.UIAxes7 = uiaxes(app.Tab_5);
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Count')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes8 = uiaxes(app.Tab_5);
            xlabel(app.UIAxes8, 'Y')
            ylabel(app.UIAxes8, 'Count')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes14 = uiaxes(app.Tab_5);
            xlabel(app.UIAxes14, 'X')
            ylabel(app.UIAxes14, 'Y')
            zlabel(app.UIAxes14, 'Z')
            app.ExposeCamera3Button = uibutton(app.Tab_5, 'push');
            app.ExposeCamera3Button.ButtonPushedFcn = createCallbackFcn(app, @ExposeCamera3ButtonPushed, true);
            app.ExposeCamera3Button.Text = 'Expose Camera 3';
            app.SaveCamera3FiguresButton_2 = uibutton(app.Tab_5, 'push');
            app.SaveCamera3FiguresButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveCamera3FiguresButton_2Pushed, true);
            app.SaveCamera3FiguresButton_2.Text = 'Save Camera 3 Figures';
            app.DisplayDropDown_4Label = uilabel(app.Tab_5);
            app.DisplayDropDown_4Label.Text = 'Display';
            app.DisplayDropDown_4 = uidropdown(app.Tab_5);
            app.DisplayDropDown_4.Items = {'Full', 'AOI'};
            app.DisplayDropDown_4.ValueChangedFcn = createCallbackFcn(app, @DisplayDropDown_4ValueChanged, true);
            app.DisplayDropDown_4.Value = 'AOI';
            app.ExposureTimeusEditField_2Label = uilabel(app.Tab_5);
            app.ExposureTimeusEditField_2Label.Text = 'Exposure Time (us)';
            app.ExposureTimeusEditField_2 = uieditfield(app.Tab_5, 'numeric');
            app.SaveExposureTimeButton_4 = uibutton(app.Tab_5, 'push');
            app.SaveExposureTimeButton_4.ButtonPushedFcn = createCallbackFcn(app, @SaveExposureTimeButton_4Pushed, true);
            app.SaveExposureTimeButton_4.Text = 'Save Exposure Time';
            app.PDFNameEditField_5Label = uilabel(app.Tab_5);
            app.PDFNameEditField_5Label.Text = 'PDF Name';
            app.PDFNameEditField_5 = uieditfield(app.Tab_5, 'text');
            app.Label11_7 = uilabel(app.Tab_5);
            app.Label11_7.Text = 'Label11';
            app.Label12_7 = uilabel(app.Tab_5);
            app.Label12_7.Text = 'Label12';
            app.CentroidLabel_7 = uilabel(app.Tab_5);
            app.CentroidLabel_7.Text = 'Centroid:';
            app.SigmaLabel_7 = uilabel(app.Tab_5);
            app.SigmaLabel_7.Text = 'Sigma:';
            app.Label11_8 = uilabel(app.Tab_5);
            app.Label11_8.Text = 'Label11';
            app.Label12_8 = uilabel(app.Tab_5);
            app.Label12_8.Text = 'Label12';
            app.CentroidLabel_8 = uilabel(app.Tab_5);
            app.CentroidLabel_8.Text = 'Centroid:';
            app.SigmaLabel_8 = uilabel(app.Tab_5);
            app.SigmaLabel_8.Text = 'Sigma:';
            app.AmplitudeLabel_7 = uilabel(app.Tab_5);
            app.AmplitudeLabel_7.Text = 'Amplitude:';
            app.Label19 = uilabel(app.Tab_5);
            app.Label19.Text = 'Label19';
            app.AmplitudeLabel_8 = uilabel(app.Tab_5);
            app.AmplitudeLabel_8.Text = 'Amplitude:';
            app.Label20 = uilabel(app.Tab_5);
            app.Label20.Text = 'Label20';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '4';
            app.UIAxes9 = uiaxes(app.Tab_4);
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Count')
            zlabel(app.UIAxes9, 'Z')
            app.UIAxes10 = uiaxes(app.Tab_4);
            xlabel(app.UIAxes10, 'Y')
            ylabel(app.UIAxes10, 'Count')
            zlabel(app.UIAxes10, 'Z')
            app.UIAxes15 = uiaxes(app.Tab_4);
            xlabel(app.UIAxes15, 'X')
            ylabel(app.UIAxes15, 'Y')
            zlabel(app.UIAxes15, 'Z')
            app.ExposeCamera4Button = uibutton(app.Tab_4, 'push');
            app.ExposeCamera4Button.ButtonPushedFcn = createCallbackFcn(app, @ExposeCamera4ButtonPushed, true);
            app.ExposeCamera4Button.Text = 'Expose Camera 4';
            app.SaveCamera4FiguresButton_2 = uibutton(app.Tab_4, 'push');
            app.SaveCamera4FiguresButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveCamera4FiguresButton_2Pushed, true);
            app.SaveCamera4FiguresButton_2.Text = 'Save Camera 4 Figures';
            app.DisplayDropDown_5Label = uilabel(app.Tab_4);
            app.DisplayDropDown_5Label.Text = 'Display';
            app.DisplayDropDown_5 = uidropdown(app.Tab_4);
            app.DisplayDropDown_5.Items = {'Full', 'AOI'};
            app.DisplayDropDown_5.ValueChangedFcn = createCallbackFcn(app, @DisplayDropDown_5ValueChanged, true);
            app.DisplayDropDown_5.Value = 'AOI';
            app.ExposureTimeusEditFieldLabel = uilabel(app.Tab_4);
            app.ExposureTimeusEditFieldLabel.Text = 'Exposure Time (us)';
            app.ExposureTimeusEditField = uieditfield(app.Tab_4, 'numeric');
            app.SaveExposureTimeButton_5 = uibutton(app.Tab_4, 'push');
            app.SaveExposureTimeButton_5.ButtonPushedFcn = createCallbackFcn(app, @SaveExposureTimeButton_5Pushed, true);
            app.SaveExposureTimeButton_5.Text = 'Save Exposure Time';
            app.PDFNameEditField_6Label = uilabel(app.Tab_4);
            app.PDFNameEditField_6Label.Text = 'PDF Name';
            app.PDFNameEditField_6 = uieditfield(app.Tab_4, 'text');
            app.Label11_9 = uilabel(app.Tab_4);
            app.Label11_9.Text = 'Label11';
            app.Label12_9 = uilabel(app.Tab_4);
            app.Label12_9.Text = 'Label12';
            app.CentroidLabel_9 = uilabel(app.Tab_4);
            app.CentroidLabel_9.Text = 'Centroid:';
            app.SigmaLabel_9 = uilabel(app.Tab_4);
            app.SigmaLabel_9.Text = 'Sigma:';
            app.Label11_10 = uilabel(app.Tab_4);
            app.Label11_10.Text = 'Label11';
            app.Label12_10 = uilabel(app.Tab_4);
            app.Label12_10.Text = 'Label12';
            app.CentroidLabel_10 = uilabel(app.Tab_4);
            app.CentroidLabel_10.Text = 'Centroid:';
            app.SigmaLabel_10 = uilabel(app.Tab_4);
            app.SigmaLabel_10.Text = 'Sigma:';
            app.AmplitudeLabel_9 = uilabel(app.Tab_4);
            app.AmplitudeLabel_9.Text = 'Amplitude:';
            app.Label21 = uilabel(app.Tab_4);
            app.Label21.Text = 'Label21';
            app.AmplitudeLabel_10 = uilabel(app.Tab_4);
            app.AmplitudeLabel_10.Text = 'Amplitude:';
            app.Label22 = uilabel(app.Tab_4);
            app.Label22.Text = 'Label22';
            app.ExposeCamerasButton = uibutton(app.UIFigure, 'push');
            app.ExposeCamerasButton.ButtonPushedFcn = createCallbackFcn(app, @ExposeCamerasButtonPushed, true);
            app.ExposeCamerasButton.Text = 'Expose Cameras';
            app.SaveFiguresButton = uibutton(app.UIFigure, 'push');
            app.SaveFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFiguresButtonPushed, true);
            app.SaveFiguresButton.Text = 'Save Figures';
            app.PDFNameEditField_2Label = uilabel(app.UIFigure);
            app.PDFNameEditField_2Label.Text = 'PDF Name';
            app.PDFNameEditField_2 = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = focuscamGUIfinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end